classdef GeNEsIS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GeNEsISLabel                   matlab.ui.control.Label
        CreateyourstimuliButton        matlab.ui.control.Button
        SetyourimagesButton            matlab.ui.control.Button
        DisplayyourimagesButton        matlab.ui.control.Button
        iButton                        matlab.ui.control.Button
        iButton_2                      matlab.ui.control.Button
        iButton_3                      matlab.ui.control.Button
        Label                          matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        Label_3                        matlab.ui.control.Label
        ContactmirkozanonunitnitLabel  matlab.ui.control.Label
        GEnerationofNumericalElementSImagesSoftwareLabel  matlab.ui.control.Label
        iButton_4                      matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = 'GeNEsIS - Main menu';
            clc;
        end

        % Button pushed function: CreateyourstimuliButton
        function CreateyourstimuliButtonPushed(app, event)
            GeNEsIS_create()
        end

        % Button pushed function: SetyourimagesButton
        function SetyourimagesButtonPushed(app, event)
            GeNEsIS_save()
        end

        % Button pushed function: DisplayyourimagesButton
        function DisplayyourimagesButtonPushed(app, event)
            GeNEsIS_display()
        end

        % Button pushed function: iButton
        function iButtonPushed(app, event)
            f = msgbox('With GeNEsIS_create tool you can create your dataset of dispositions of figures with different numerosity and controlled parameters, like surface, perimeter, convex hull and inter-distance','GeNEsIS Info');
        end

        % Button pushed function: iButton_2
        function iButton_2Pushed(app, event)
            f = msgbox('With GeNEsIS_save tool you can save your dataset as figures and files ready to be presented on screen, with customized appearance','GeNEsIS Info');
        end

        % Button pushed function: iButton_3
        function iButton_3Pushed(app, event)
            f = msgbox('With GeNEsIS_display tool you have a basic presentation program for classical experiments of habituation and discrimination','GeNEsIS Info');
        end

        % Button pushed function: iButton_4
        function iButton_4Pushed(app, event)
            winopen('./Tutorial/GeNEsIS_tutorial.pdf')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.GeNEsISLabel = uilabel(app.UIFigure);
            app.GeNEsISLabel.Text = 'GeNEsIS';
            app.CreateyourstimuliButton = uibutton(app.UIFigure, 'push');
            app.CreateyourstimuliButton.ButtonPushedFcn = createCallbackFcn(app, @CreateyourstimuliButtonPushed, true);
            app.CreateyourstimuliButton.Text = 'Create your stimuli';
            app.SetyourimagesButton = uibutton(app.UIFigure, 'push');
            app.SetyourimagesButton.ButtonPushedFcn = createCallbackFcn(app, @SetyourimagesButtonPushed, true);
            app.SetyourimagesButton.Text = 'Set your images';
            app.DisplayyourimagesButton = uibutton(app.UIFigure, 'push');
            app.DisplayyourimagesButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayyourimagesButtonPushed, true);
            app.DisplayyourimagesButton.Text = 'Display your images';
            app.iButton = uibutton(app.UIFigure, 'push');
            app.iButton.ButtonPushedFcn = createCallbackFcn(app, @iButtonPushed, true);
            app.iButton.Text = 'i';
            app.iButton_2 = uibutton(app.UIFigure, 'push');
            app.iButton_2.ButtonPushedFcn = createCallbackFcn(app, @iButton_2Pushed, true);
            app.iButton_2.Text = 'i';
            app.iButton_3 = uibutton(app.UIFigure, 'push');
            app.iButton_3.ButtonPushedFcn = createCallbackFcn(app, @iButton_3Pushed, true);
            app.iButton_3.Text = 'i';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '1';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '2';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '3';
            app.ContactmirkozanonunitnitLabel = uilabel(app.UIFigure);
            app.ContactmirkozanonunitnitLabel.Text = 'Contact: mirko.zanon@unitn.it';
            app.GEnerationofNumericalElementSImagesSoftwareLabel = uilabel(app.UIFigure);
            app.GEnerationofNumericalElementSImagesSoftwareLabel.Text = 'GEneration of Numerical ElementS Images Software';
            app.iButton_4 = uibutton(app.UIFigure, 'push');
            app.iButton_4.ButtonPushedFcn = createCallbackFcn(app, @iButton_4Pushed, true);
            app.iButton_4.Text = 'i';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GeNEsIS

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end