classdef GeNEsIS_check < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GeNEsIScheckLabel        matlab.ui.control.Label
        LoadGeNEsISset1Button    matlab.ui.control.Button
        LoadGeNEsISset2Button    matlab.ui.control.Button
        Slider                   matlab.ui.control.Slider
        Slider_2                 matlab.ui.control.Slider
        Button                   matlab.ui.control.Button
        Button_2                 matlab.ui.control.Button
        Button_3                 matlab.ui.control.Button
        Button_4                 matlab.ui.control.Button
        DeleteimageButton        matlab.ui.control.Button
        DeleteimageButton_2      matlab.ui.control.Button
        Fileset1Label            matlab.ui.control.Label
        Fileset2Label            matlab.ui.control.Label
        Sizeset1Label            matlab.ui.control.Label
        Sizeset2Label            matlab.ui.control.Label
        SaveasEditFieldLabel     matlab.ui.control.Label
        SaveasEditField          matlab.ui.control.EditField
        SaveimagesButton         matlab.ui.control.Button
        AveragefrequencyLabel    matlab.ui.control.Label
        AveragefrequencyLabel_2  matlab.ui.control.Label
        SavefrequencydataButton  matlab.ui.control.Button
        LoadPNGset1Button        matlab.ui.control.Button
        LoadPNGset2Button        matlab.ui.control.Button
        RemovelowcomponentsuptoEditFieldLabel  matlab.ui.control.Label
        RemovelowcomponentsuptoEditField  matlab.ui.control.NumericEditField
        UIAxes                   matlab.ui.control.UIAxes
        UIAxes2                  matlab.ui.control.UIAxes
        UIAxesF1                 matlab.ui.control.UIAxes
        UIAxesF2                 matlab.ui.control.UIAxes
        UIAxesFreqDom            matlab.ui.control.UIAxes
        UIAxesFreqDom2           matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Image1
        Image2
        currentIm
    end
    
    methods (Access = private)
        
        function [file, data]=loadData(app)
            app.UIFigure.Visible='off';
            [file,path] = uigetfile('*');
            data=load([path '/' file]);
            app.UIFigure.Visible='on';
        end
        
        function [Matrix, file]=loadData2(app)
            app.UIFigure.Visible='off';
            selpath = uigetdir;
            S = dir(fullfile(selpath, '*.png'));
            if numel(S)==0
                disp('No images found!')
            else
                file=S(1);
                for n = 1:numel(S)
                    F = fullfile(selpath,S(n).name);
                    Matrix{1,n} = imread(F);
                end
            end
            app.UIFigure.Visible='on';
        end
        
        function [avgFreq,totPower, f1, Pf] = radialavg(app, imgf, N, M)
            imgfp = (abs(imgf)/(N*M)).^2;
         
            % Adjust PSD size
            dimDiff = abs(N-M);
            dimMax = max(N,M);
            % Make square
            if N > M                                                                    % More rows than columns
                if ~mod(dimDiff,2)                                                      % Even difference
                    imgfp = [NaN(N,dimDiff/2) imgfp NaN(N,dimDiff/2)];                  % Pad columns to match dimensions
                else                                                                    % Odd difference
                    imgfp = [NaN(N,floor(dimDiff/2)) imgfp NaN(N,floor(dimDiff/2)+1)];  % Pad columns to match dimensions
                end
            elseif N < M                                                                % More columns than rows
                if ~mod(dimDiff,2)                                                      % Even difference
                    imgfp = [NaN(dimDiff/2,M); imgfp; NaN(dimDiff/2,M)];                % Pad rows to match dimensions
                else                                                                    % Odd difference
                    imgfp = [NaN(floor(dimDiff/2),M); imgfp; NaN(floor(dimDiff/2)+1,M)];% Pad rows to match dimensions
                end
            end
            
            % Only consider one half of spectrum (due to symmetry)
            %% Compute radially average power spectrum
            [X,Y] = meshgrid(-dimMax/2:dimMax/2-1, -dimMax/2:dimMax/2-1);               % Make Cartesian grid
            [~,rho] = cart2pol(X,Y);                                                    % Convert to polar coordinate axes
            rho = round(rho);
            i = cell(floor(dimMax/2) + 1, 1);
            for r = 0:floor(dimMax/2)
                i{r + 1} = find(rho == r);
            end
            Pf = zeros(1, floor(dimMax/2)+1);
            for r = 0:floor(dimMax/2)
               Pf(1, r + 1) = nanmean( imgfp( i{r+1} ) );                              %mean over all image fourier points at distance r 
            end
            fs=1/sqrt(2); % 1 point every pixel -converted in radial- (change here 1 to convert in cm, how many points per cm)
            f1 = (0 : 1/dimMax : 0.5)/fs;
            f1 = f1(app.RemovelowcomponentsuptoEditField.Value : end);
            Pf = Pf(app.RemovelowcomponentsuptoEditField.Value : end);
            avgFreq = nansum(f1.*Pf)/nansum(Pf);
            totPower = nansum(Pf);

        end
        
        function plotFreq(app, im, set)
            img=im{1}(:,:,1);
            if set==1
                app.Sizeset1Label.Text = ['Size: ' num2str(size(img,1)) ' px X ' num2str(size(img,2)) ' px X ' num2str(size(app.Image1,2)) ' images'];
                xlim(app.UIAxes, [0 size(img,2)]);
                ylim(app.UIAxes, [0 size(img,1)]);
                imagesc(app.UIAxes,img);
                imgF=fft2(img);
                imgf=fftshift(imgF);
                imshow(imgf, 'Parent', app.UIAxesFreqDom)
                [avgFreq, totPower, f1, Pf] = radialavg(app, imgf, size(img,1), size(img,2));
                loglog(app.UIAxesF1, f1,Pf,'k-','LineWidth',2.0);
                app.UIAxesF1.XGrid = 'on';
                app.AveragefrequencyLabel.Text = ['Average f: ' num2str(round(avgFreq,5)) ' cycles/px -- Total power: ' num2str(sprintf( '%.5d', totPower))];
            else
                app.Sizeset2Label.Text = ['Size: ' num2str(size(img,1)) ' px X ' num2str(size(img,2)) ' px X ' num2str(size(app.Image2,2)) ' images'];
                xlim(app.UIAxes2, [0 size(img,2)]);
                ylim(app.UIAxes2, [0 size(img,1)]);
                imagesc(app.UIAxes2, img);
                imgF=fft2(img);
                imgf=fftshift(imgF);
                imshow(imgf, 'Parent', app.UIAxesFreqDom2)
                [avgFreq, totPower, f1, Pf] = radialavg(app, imgf, size(img,1), size(img,2));
                loglog(app.UIAxesF2, f1,Pf,'k-','LineWidth',2.0);
                app.UIAxesF2.XGrid = 'on';
                app.AveragefrequencyLabel_2.Text = ['Average f: ' num2str(round(avgFreq,5)) ' cycles/px -- Total power: ' num2str(sprintf( '%.5d', totPower))];
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;
            app.currentIm{1}=1;
            app.currentIm{2}=1;
        end

        % Button pushed function: LoadGeNEsISset1Button
        function LoadGeNEsISset1ButtonPushed(app, event)
            app.Slider.Enable = 'on';
            [file, data]=loadData(app);
            app.Image1=data.imgMatrix;
            app.Fileset1Label.Text = ['File: ' file];
            if size(app.Image1,2)>1
                app.Slider.Limits = [1 size(app.Image1,2)];
            else
                app.Slider.Enable='off';
            end
            app.currentIm{1}=1;
            im=app.Image1(1);
            plotFreq(app, im, 1);
        end

        % Button pushed function: LoadGeNEsISset2Button
        function LoadGeNEsISset2ButtonPushed(app, event)
            app.Slider_2.Enable = 'on';
            [file, data]=loadData(app);
            app.Image2=data.imgMatrix;
            app.Fileset2Label.Text = ['File: ' file];
            if size(app.Image2,2)>1
                app.Slider_2.Limits = [1 size(app.Image2,2)];
            else
                app.Slider_2.Enable='off';
            end
            app.currentIm{2}=1;
            im=app.Image2(1);
            plotFreq(app, im, 2);
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            changingValue = round(event.Value);
            app.currentIm{1}=changingValue;
            im=app.Image1(changingValue);
            plotFreq(app, im, 1);
        end

        % Value changing function: Slider_2
        function Slider_2ValueChanging(app, event)
            changingValue = round(event.Value);
            app.currentIm{2}=changingValue;
            im=app.Image2(changingValue);
            plotFreq(app, im, 2);
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = round(app.Slider.Value);
            app.Slider.Value = value;
            app.currentIm{1}=value;
            im=app.Image1(app.currentIm{1});
            plotFreq(app, im, 1);
        end

        % Value changed function: Slider_2
        function Slider_2ValueChanged(app, event)
            value = round(app.Slider_2.Value);
            app.Slider_2.Value = value;
            app.currentIm{2} = value;
            im=app.Image2(app.currentIm{2});
            plotFreq(app, im, 2);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            if app.currentIm{1} ~= 1
                app.currentIm{1}=app.currentIm{1}-1;
                app.Slider.Value = app.currentIm{1};
                im=app.Image1(app.currentIm{1});
                plotFreq(app, im, 1);
            end
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            if app.currentIm{2} ~= 1
                app.currentIm{2}=app.currentIm{2}-1;
                app.Slider_2.Value = app.currentIm{2};
                im=app.Image2(app.currentIm{2});
                plotFreq(app, im, 2);
            end
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            if app.currentIm{1} ~= size(app.Image1,2)
                app.currentIm{1}=app.currentIm{1}+1;
                app.Slider.Value = app.currentIm{1};
                im=app.Image1(app.currentIm{1});
                plotFreq(app, im, 1);
            end
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            if app.currentIm{2} ~= size(app.Image2,2)
                app.currentIm{2}=app.currentIm{2}+1;
                app.Slider_2.Value = app.currentIm{2};
                im=app.Image2(app.currentIm{2});
                plotFreq(app, im, 2);
            end
        end

        % Button pushed function: DeleteimageButton
        function DeleteimageButtonPushed(app, event)
            if app.currentIm{1} == size(app.Image1,2)
                app.Image1(app.currentIm{1}) = [];
                app.currentIm{1} = app.currentIm{1}-1;
            else
                app.Image1(app.currentIm{1}) = [];
            end
            im=app.Image1(app.currentIm{1});
            plotFreq(app, im, 1);
            if size(app.Image1,2) ~= 1
                app.Slider.Limits = [1 size(app.Image1,2)];
            else
                app.Slider.Value = 1;
                app.Slider.Enable = 'off';
            end
        end

        % Button pushed function: DeleteimageButton_2
        function DeleteimageButton_2Pushed(app, event)
            if app.currentIm{2} == size(app.Image2,2)
                app.Image2(app.currentIm{2}) = [];
                app.currentIm{2} = app.currentIm{2}-1;
            else
                app.Image2(app.currentIm{2}) = [];
            end
            im=app.Image2(app.currentIm{2});
            plotFreq(app, im, 2);
            if size(app.Image2,2) ~= 1
                app.Slider_2.Limits = [1 size(app.Image2,2)];
            else
                app.Slider_2.Value = 1;
                app.Slider_2.Enable = 'off';
            end
        end

        % Button pushed function: SaveimagesButton
        function SaveimagesButtonPushed(app, event)
            name = app.SaveasEditField.Value;
            mkdir('Final_images');
            answer = questdlg('What do you want to save?', 'Save options','Set 1','Set 2','Set 1');
            switch answer
                case 'Set 1'
                   imgMatrix = app.Image1;
                case 'Set 2'
                   imgMatrix = app.Image2;
            end
            save(['Final_images\' name], 'imgMatrix', '-v7.3')
            answer2 = questdlg('Do you want to save also images?', 'Save options','Yes','No','Yes');
            switch answer2
                case 'Yes'
                    w = waitbar(0, ['Saved 0 of ' num2str(size(imgMatrix,2)) ' images...']);
                    f = figure(1);
                    set(gca,'xtick',[],'ytick',[])
                    set(gca,'XColor', 'none','YColor','none')
                    axis 'off'
                    for i=1:size(imgMatrix,2)
                        %hold off;
                        imagesc(imgMatrix{i});
                        set(gca,'xtick',[],'ytick',[])
                        set(gca,'XColor', 'none','YColor','none')
                        Image = getframe(f);
                        waitbar(i/size(imgMatrix,2),w,['Saved ' num2str(i) ' of ' num2str(size(imgMatrix,2)) ' images...']);
                        imwrite(Image.cdata, ['Final_images\' name '_' num2str(i) '.png']);
                    end
                    close(w);
                    close(f);
            end
        end

        % Button pushed function: SavefrequencydataButton
        function SavefrequencydataButtonPushed(app, event)
            name = app.SaveasEditField.Value;
            mkdir('Final_images');
            answer = questdlg('What do you want to save?', 'Save options','Set 1','Set 2','Set 1');
            switch answer
                case 'Set 1'
                   imgMatrix = app.Image1;
                case 'Set 2'
                   imgMatrix = app.Image2;
            end
            f = waitbar(0,'Calculating avarage frequencies...');
            n=(1:size(imgMatrix,2))';
            avgFreq_px=zeros(size(imgMatrix,2),1);
            totPower=zeros(size(imgMatrix,2),1);
            for i=1:size(imgMatrix,2)
                imgf=fftshift(fft2(imgMatrix{i}));
                [avgFreq_px(i), totPower(i), ~,~] = radialavg(app,imgf, size(imgMatrix{i},1), size(imgMatrix{i},2));
                waitbar(i/size(imgMatrix,2),f,['Calculating avarage frequencies: ' num2str(i) ' of ' num2str(size(imgMatrix,2)) '...']);
            end
            T = table(n,avgFreq_px,totPower, 'VariableNames', {'Stimulus','Weighted_average_freq','Total_power_Area'});
            writetable(T, [name, '.xlsx'])
            close(f)
        end

        % Button pushed function: LoadPNGset1Button
        function LoadPNGset1ButtonPushed(app, event)
            clc;
            app.Slider.Enable = 'on';
            [app.Image1, file]=loadData2(app);
            app.Fileset1Label.Text = ['File: ' file.name];
            if size(app.Image1,2)>1
                app.Slider.Limits = [1 size(app.Image1,2)];
            else
                app.Slider.Enable='off';
            end
            app.currentIm{1}=1;
            im=app.Image1(1);
            plotFreq(app, im, 1);
        end

        % Button pushed function: LoadPNGset2Button
        function LoadPNGset2ButtonPushed(app, event)
            clc;
            app.Slider_2.Enable = 'on';
            [app.Image2, file]=loadData2(app);
            app.Fileset2Label.Text = ['File: ' file.name];
            if size(app.Image2,2)>1
                app.Slider_2.Limits = [1 size(app.Image2,2)];
            else
                app.Slider_2.Enable='off';
            end
            app.currentIm{2}=1;
            im=app.Image2(1);
            plotFreq(app, im, 2);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.GeNEsIScheckLabel = uilabel(app.UIFigure);
            app.GeNEsIScheckLabel.Text = 'GeNEsIS - check';
            app.LoadGeNEsISset1Button = uibutton(app.UIFigure, 'push');
            app.LoadGeNEsISset1Button.ButtonPushedFcn = createCallbackFcn(app, @LoadGeNEsISset1ButtonPushed, true);
            app.LoadGeNEsISset1Button.Text = 'Load GeNEsIS set 1';
            app.LoadGeNEsISset2Button = uibutton(app.UIFigure, 'push');
            app.LoadGeNEsISset2Button.ButtonPushedFcn = createCallbackFcn(app, @LoadGeNEsISset2ButtonPushed, true);
            app.LoadGeNEsISset2Button.Text = 'Load GeNEsIS set 2';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [1 10];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Slider.Value = 1;
            app.Slider_2 = uislider(app.UIFigure);
            app.Slider_2.Limits = [1 10];
            app.Slider_2.ValueChangedFcn = createCallbackFcn(app, @Slider_2ValueChanged, true);
            app.Slider_2.ValueChangingFcn = createCallbackFcn(app, @Slider_2ValueChanging, true);
            app.Slider_2.Value = 1;
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '<<';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '>>';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '<<';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '>>';
            app.DeleteimageButton = uibutton(app.UIFigure, 'push');
            app.DeleteimageButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteimageButtonPushed, true);
            app.DeleteimageButton.Text = 'Delete image';
            app.DeleteimageButton_2 = uibutton(app.UIFigure, 'push');
            app.DeleteimageButton_2.ButtonPushedFcn = createCallbackFcn(app, @DeleteimageButton_2Pushed, true);
            app.DeleteimageButton_2.Text = 'Delete image';
            app.Fileset1Label = uilabel(app.UIFigure);
            app.Fileset1Label.Text = 'File set 1';
            app.Fileset2Label = uilabel(app.UIFigure);
            app.Fileset2Label.Text = 'File set 2';
            app.Sizeset1Label = uilabel(app.UIFigure);
            app.Sizeset1Label.Text = 'Size set 1';
            app.Sizeset2Label = uilabel(app.UIFigure);
            app.Sizeset2Label.Text = 'Size set 2';
            app.SaveasEditFieldLabel = uilabel(app.UIFigure);
            app.SaveasEditFieldLabel.Text = 'Save as:';
            app.SaveasEditField = uieditfield(app.UIFigure, 'text');
            app.SaveimagesButton = uibutton(app.UIFigure, 'push');
            app.SaveimagesButton.ButtonPushedFcn = createCallbackFcn(app, @SaveimagesButtonPushed, true);
            app.SaveimagesButton.Text = 'Save images';
            app.AveragefrequencyLabel = uilabel(app.UIFigure);
            app.AveragefrequencyLabel.Text = 'Average frequency: ';
            app.AveragefrequencyLabel_2 = uilabel(app.UIFigure);
            app.AveragefrequencyLabel_2.Text = 'Average frequency: ';
            app.SavefrequencydataButton = uibutton(app.UIFigure, 'push');
            app.SavefrequencydataButton.ButtonPushedFcn = createCallbackFcn(app, @SavefrequencydataButtonPushed, true);
            app.SavefrequencydataButton.Text = 'Save frequency data';
            app.LoadPNGset1Button = uibutton(app.UIFigure, 'push');
            app.LoadPNGset1Button.ButtonPushedFcn = createCallbackFcn(app, @LoadPNGset1ButtonPushed, true);
            app.LoadPNGset1Button.Text = 'Load PNG set 1';
            app.LoadPNGset2Button = uibutton(app.UIFigure, 'push');
            app.LoadPNGset2Button.ButtonPushedFcn = createCallbackFcn(app, @LoadPNGset2ButtonPushed, true);
            app.LoadPNGset2Button.Text = 'Load PNG set 2';
            app.RemovelowcomponentsuptoEditFieldLabel = uilabel(app.UIFigure);
            app.RemovelowcomponentsuptoEditFieldLabel.Text = 'Remove low components up to:';
            app.RemovelowcomponentsuptoEditField = uieditfield(app.UIFigure, 'numeric');
            app.RemovelowcomponentsuptoEditField.Value = 1;
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes2 = uiaxes(app.UIFigure);
            app.UIAxesF1 = uiaxes(app.UIFigure);
            title(app.UIAxesF1, 'Spatial frequency')
            xlabel(app.UIAxesF1, 'f [cycles/px]')
            ylabel(app.UIAxesF1, ' Power')
            app.UIAxesF2 = uiaxes(app.UIFigure);
            title(app.UIAxesF2, 'Spatial frequency')
            xlabel(app.UIAxesF2, 'f [cycles/px]')
            ylabel(app.UIAxesF2, 'Power')
            app.UIAxesFreqDom = uiaxes(app.UIFigure);
            zlabel(app.UIAxesFreqDom, 'Z')
            app.UIAxesFreqDom2 = uiaxes(app.UIFigure);
            zlabel(app.UIAxesFreqDom2, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GeNEsIS_check

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end