classdef GeNEsIS_create < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        MaxcomputationtsEditField       matlab.ui.control.NumericEditField
        MaxcomputationtsEditFieldLabel  matlab.ui.control.Label
        FromborderEditField             matlab.ui.control.NumericEditField
        FromborderEditFieldLabel        matlab.ui.control.Label
        ConvexHull_3                    matlab.ui.control.Label
        LoadButton                      matlab.ui.control.Button
        iButton_2                       matlab.ui.control.Button
        iButton                         matlab.ui.control.Button
        GeNEsIScreateLabel              matlab.ui.control.Label
        arenashapeSwitch                matlab.ui.control.Switch
        UnitsestimationPanel            matlab.ui.container.Panel
        AreasconversionLabel            matlab.ui.control.Label
        LinearconversionLabel           matlab.ui.control.Label
        px2Label                        matlab.ui.control.Label
        pxLabel                         matlab.ui.control.Label
        ReferencearenarcmEditField      matlab.ui.control.NumericEditField
        ReferencearenarcmEditFieldLabel  matlab.ui.control.Label
        ConvexHull_2                    matlab.ui.control.Label
        BalanceButtonGroup              matlab.ui.container.ButtonGroup
        MinimumdistanceButton           matlab.ui.control.RadioButton
        AcceptederrorEditField          matlab.ui.control.NumericEditField
        AcceptederrorLabel              matlab.ui.control.Label
        EditField                       matlab.ui.control.NumericEditField
        EditFieldLabel                  matlab.ui.control.Label
        DensityButton                   matlab.ui.control.RadioButton
        ConvexhullButton                matlab.ui.control.RadioButton
        InterdistanceButton             matlab.ui.control.RadioButton
        NothingButton                   matlab.ui.control.RadioButton
        UpdateLabel                     matlab.ui.control.Label
        ArenaradiusEditField            matlab.ui.control.NumericEditField
        ArenaradiusEditFieldLabel       matlab.ui.control.Label
        Generation                      matlab.ui.control.Label
        ProceedButton                   matlab.ui.control.Button
        generationsEditField            matlab.ui.control.NumericEditField
        generationsEditFieldLabel       matlab.ui.control.Label
        visualizeSlider                 matlab.ui.control.Slider
        ComputeButton                   matlab.ui.control.Button
        ConvexHull                      matlab.ui.control.Label
        InterDistance                   matlab.ui.control.Label
        EditFieldNumerosity             matlab.ui.control.NumericEditField
        NumerosityLabel                 matlab.ui.control.Label
        UIAxesPositions                 matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
    % external frame defined as:
        % arena.s (arena shape, 'c'=circle, 's'=square)
        % arena.r (dimention, radius for circle; half-side for square)
        arena;
    % stimulus defined as: 
        % ball.s (stimulus shape, 'c'=circle, 's'=square, 'd'=diamond, 't'=triangle); 
        % ball.r (characteristic dimention, radius for circle, side size for square triangle and diamond);
        % ball.X (x,y coordinates of the baricenter);
        %ball.r and .X are cells of {#generations} for the different set generations
        ball;
    % numerosity (number of stimuli -shapes- for generation)
        nBalls;
    % number of random point set generations
        nGenerations;   
        targetCH;
        suggestedCH;
        suggestedID;
        tolerance;
    end
    
    methods (Access = private)

    % generation of points with fixed convex hull    
        function stop=computeCHPositions(app, generation)
            stop=0; % parameter to check computation status
            CHref=app.EditField.Value; %fixed convex hull to match
            app.targetCH=CHref;
            err = CHref*0.1/100; % accepted error will be considered in next step (shapes)
            CH=0;
            tic;
            while CH < CHref - err || CH > CHref + err
                cont=1; % parameter to continue generations until the desired number is reached
                if generation==1
                    t=toc;
                    if t>app.MaxcomputationtsEditField.Value % check to close computation if it takes too much time to find first combination (> 2min)
                        stop=1;
                        return;
                    end
                end
                while cont~=0
                    if app.nBalls >2
                        X=2*app.arena.r*rand(app.nBalls, 2)-app.arena.r; % (x,y) nBalls-points random generation between -r and r (zero centered)
                    elseif app.nBalls == 2
                        x1=4*app.arena.r*rand(1,1)-4*app.arena.r; % x random between -4r and 0
                        y1=4*2*app.arena.r*rand(1,1)-4*app.arena.r; % y random between -8r and 8r (zero centered)
                        X1 = [x1,y1];
                        X2=-X1;
                        X=[X1;X2];
                    end
                    app.ball.X{generation}=X;
                    for j=1:app.nBalls
                        d=[0,0;app.ball.X{generation}(j,:)]; %distance from center
                        if strcmp(app.arenashapeSwitch.Value, 'Circle') == 1
                            if pdist(d)>=app.arena.r - app.FromborderEditField.Value% remove generation if points outside circle of radius r
                                cont=cont+1;
                                break
                            else
                                if j==app.nBalls
                                    cont=0; % if all points are inside count=0 makes while loop to end and pass to next generation
                                end
                            end
                        elseif strcmp(app.arenashapeSwitch.Value, 'Square') == 1
                            if app.ball.X{generation}(j,1)>=app.arena.r-app.FromborderEditField.Value || app.ball.X{generation}(j,1)<=-app.arena.r+app.FromborderEditField.Value || app.ball.X{generation}(j,2)>=app.arena.r-app.FromborderEditField.Value || app.ball.X{generation}(j,2)<=-app.arena.r+app.FromborderEditField.Value
                                cont=cont+1;
                                break
                            else
                                if j==app.nBalls
                                    cont=0; % if all points are inside count=0 makes while loop to end and pass to next generation
                                end
                            end
                        end
                    end
                end
                if app.nBalls > 2
                    [~,av] = convhull(app.ball.X{generation});
                    CH = round(av,2);
                elseif app.nBalls == 2
                    CH = round(mean(pdist(app.ball.X{generation})),2);    
                end
            end
            return;
        end
        
   % generation of points with fixed density (=n/convex hull)      
        function stop=computeDPositions(app, generation)
            stop=0;% parameter to check computation status
            Dref=app.EditField.Value; % fixed density to match
            app.targetCH = app.nBalls/Dref;
            err = Dref*0.1/100; % accepted error will be considered in next step (shapes)
            D=0;
            tic;
            while D < Dref-err || D > Dref+err
                cont=1; % parameter to continue generations until the desired number is reached
                if generation==1
                    t=toc;
                    if t>app.MaxcomputationtsEditField.Value % check to close computation if it takes too much time to find first combination (> 2min)
                        stop=1;
                        return;
                    end
                end
                while cont~=0   
                        X=2*app.arena.r*rand(app.nBalls, 2)-app.arena.r; % (x,y) nBalls-points random generation between -r and r (zero centered)
                        app.ball.X{generation}=X;
                        for j=1:app.nBalls
                            d=[0,0;app.ball.X{generation}(j,:)];
                            if strcmp(app.arenashapeSwitch.Value, 'Circle') == 1
                                if pdist(d)>=app.arena.r - app.FromborderEditField.Value% remove generation if points outside circle of radius r
                                    cont=cont+1;
                                    break
                                else
                                    if j==app.nBalls
                                        cont=0; % if all points are inside count=0 makes while loop to end and pass to next generation
                                    end
                                end
                            elseif strcmp(app.arenashapeSwitch.Value, 'Square') == 1
                                if app.ball.X{generation}(j,1)>=app.arena.r-app.FromborderEditField.Value || app.ball.X{generation}(j,1)<=-app.arena.r+app.FromborderEditField.Value || app.ball.X{generation}(j,2)>=app.arena.r-app.FromborderEditField.Value || app.ball.X{generation}(j,2)<=-app.arena.r+app.FromborderEditField.Value
                                    cont=cont+1;
                                    break
                                else
                                    if j==app.nBalls
                                        cont=0; % if all points are inside count=0 makes while loop to end and pass to next generation
                                    end
                                end
                            end
                        end
                end
                if app.nBalls > 2
                    [~,av] = convhull(app.ball.X{generation});
                    D = round(app.nBalls/av,2);
                elseif app.nBalls == 2
                    D = round(2/mean(pdist(app.ball.X{generation})),2);    
                end
            end
            return;
        end
        
    % generation of points with fixed mean inter distance  
        function stop=computeIDPositions(app, generation)
            app.targetCH = 0;
            stop=0;% parameter to check computation status
            IDref=app.EditField.Value; % fixed mean inter distance to match
            err = IDref*app.AcceptederrorEditField.Value/100; % accepted error
            ID=0;
            tic;
            while ID < IDref - err || ID > IDref + err
                cont=1; % parameter to continue generations until the desired number is reached
                if generation==1
                    t=toc;
                    if t>app.MaxcomputationtsEditField.Value % check to close computation if it takes too much time to find first combination (> 2min)
                        stop=1;
                        return;
                    end
                end
                while cont~=0   
                        X=2*app.arena.r*rand(app.nBalls, 2)-app.arena.r; % (x,y) nBalls-points random generation between -r and r (zero centered)
                        app.ball.X{generation}=X;
                        for j=1:app.nBalls
                            d=[0,0;app.ball.X{generation}(j,:)];
                            if strcmp(app.arenashapeSwitch.Value, 'Circle') == 1
                                if pdist(d)>=app.arena.r - app.FromborderEditField.Value% remove generation if points outside circle of radius r
                                    cont=cont+1;
                                    break
                                else
                                    if j==app.nBalls
                                        cont=0; % if all points are inside count=0 makes while loop to end and pass to next generation
                                    end
                                end
                            elseif strcmp(app.arenashapeSwitch.Value, 'Square') == 1
                                if app.ball.X{generation}(j,1)>=app.arena.r-app.FromborderEditField.Value || app.ball.X{generation}(j,1)<=-app.arena.r+app.FromborderEditField.Value || app.ball.X{generation}(j,2)>=app.arena.r-app.FromborderEditField.Value || app.ball.X{generation}(j,2)<=-app.arena.r+app.FromborderEditField.Value
                                    cont=cont+1;
                                    break
                                else
                                    if j==app.nBalls
                                        cont=0; % if all points are inside count=0 makes while loop to end and pass to next generation
                                    end
                                end
                            end
                        end
                end
                ID = round(mean(pdist(app.ball.X{generation})),2);
            end
            return;
        end

      % generation of points with minimum distance  
      function stop=computeMinimumPositions(app, generation)
            app.targetCH = 0;
            stop=0;% parameter to check computation status
            dref=app.EditField.Value; % fixed mean inter distance to match
            D = zeros(1,app.nBalls);
            tic;
            while min(D) < dref
                cont=1; % parameter to continue generations until the desired number is reached
                if generation==1
                    t=toc;
                    if t>app.MaxcomputationtsEditField.Value % check to close computation if it takes too much time to find first combination (> 2min)
                        stop=1;
                        return;
                    end
                end
                while cont~=0   
                        X=2*app.arena.r*rand(app.nBalls, 2)-app.arena.r; % (x,y) nBalls-points random generation between -r and r (zero centered)
                        app.ball.X{generation}=X;
                        for j=1:app.nBalls
                            d=[0,0;app.ball.X{generation}(j,:)];
                            if strcmp(app.arenashapeSwitch.Value, 'Circle') == 1
                                if pdist(d)>=app.arena.r - app.FromborderEditField.Value% remove generation if points outside circle of radius r
                                    cont=cont+1;
                                    break
                                else
                                    if j==app.nBalls
                                        cont=0; % if all points are inside count=0 makes while loop to end and pass to next generation
                                    end
                                end
                            elseif strcmp(app.arenashapeSwitch.Value, 'Square') == 1
                                if app.ball.X{generation}(j,1)>=app.arena.r-app.FromborderEditField.Value || app.ball.X{generation}(j,1)<=-app.arena.r+app.FromborderEditField.Value || app.ball.X{generation}(j,2)>=app.arena.r-app.FromborderEditField.Value || app.ball.X{generation}(j,2)<=-app.arena.r+app.FromborderEditField.Value
                                    cont=cont+1;
                                    break
                                else
                                    if j==app.nBalls
                                        cont=0; % if all points are inside count=0 makes while loop to end and pass to next generation
                                    end
                                end
                            end
                        end
                end
                D = pdist(app.ball.X{generation});
            end
            return;
        end
        
     % random generation of points
        function computeRndPositions(app, generation)
            app.targetCH = 0;
            cont=1; % parameter to continue generations until the desired number is reached
            while cont~=0   
                    X=2*app.arena.r*rand(app.nBalls, 2)-app.arena.r; % (x,y) nBalls-points random generation between -r and r (zero centered)
                    app.ball.X{generation}=X;
                    for j=1:app.nBalls
                        d=[0,0;app.ball.X{generation}(j,:)];
                        if strcmp(app.arenashapeSwitch.Value, 'Circle') == 1
                            if pdist(d)>=app.arena.r - app.FromborderEditField.Value% remove generation if points outside circle of radius r
                                cont=cont+1;
                                break
                            elseif strcmp(app.arenashapeSwitch.Value, 'Square') == 1
                                if j==app.nBalls
                                    cont=0; % if all points are inside count=0 makes while loop to end and pass to next generation
                                end
                            end
                        else
                            if app.ball.X{generation}(j,1)>=app.arena.r-app.FromborderEditField.Value || app.ball.X{generation}(j,1)<=-app.arena.r+app.FromborderEditField.Value || app.ball.X{generation}(j,2)>=app.arena.r-app.FromborderEditField.Value || app.ball.X{generation}(j,2)<=-app.arena.r+app.FromborderEditField.Value
                                cont=cont+1;
                                break
                            else
                                if j==app.nBalls
                                    cont=0; % if all points are inside count=0 makes while loop to end and pass to next generation
                                end
                            end
                        end
                    end
            end
        end
        
    % plot arena and shapes generated, reporting relative geometric parameters
        function plotPositions(app, generation)
            cla(app.UIAxesPositions)
            hold(app.UIAxesPositions, 'on')
            if strcmp(app.arenashapeSwitch.Value, 'Circle') == 1
                % arena circle
                th = 0:pi/50:2*pi;
                x_circle = app.arena.r* cos(th);
                y_circle = app.arena.r * sin(th);
                plot(app.UIAxesPositions, x_circle, y_circle, 'Color', 'k');
            end
            % dots inside arena
            if app.nBalls>1
                D = pdist(app.ball.X{generation});
                app.InterDistance.Text=['Inter distance: ' num2str(round(mean(D),2))];
                app.ConvexHull_3.Text=['Minimum distance: ' num2str(round(min(D),2))];
                if app.nBalls>2
                    [~,av] = convhull(app.ball.X{generation});
                    app.ConvexHull.Text=['Convex hull: ' num2str(round(av,2))];
                    app.ConvexHull_2.Text=['Density: ' num2str(round(app.nBalls/av,2))];
                else % for 2 points CH, D not defined
                    app.ConvexHull.Text=['Convex hull: ' num2str(round(mean(D),2))];
                    app.ConvexHull_2.Text=['Density: ' num2str(2/round(mean(D),2))];
                end
            else % for one point CH, D and ID not defined
                app.ConvexHull.Text='Convex hull: 0';
                app.InterDistance.Text='Inter distance: 0';
                app.ConvexHull_2.Text='Density: 0';
            end
            plot(app.UIAxesPositions,app.ball.X{generation}(:,1),app.ball.X{generation}(:,2), '*k');
        end
        
        
        function suggestConstraint(app)
            % evaluate mean CH and ID
            sID=zeros(50,1);
            sCH=zeros(50,1);
            for i=1:50
                cont=1;
                while cont~=0
                    if app.targetCH==0 || app.nBalls >2
                        XX=2*app.arena.r*rand(app.nBalls, 2)-app.arena.r; % (x,y) nBalls-points random generation between -r and r (zero centered)
                    elseif app.nBalls == 2
                        x1=4*app.arena.r*rand(1,1)-4*app.arena.r; % x random between -4r and 0
                        y1=4*2*app.arena.r*rand(1,1)-4*app.arena.r; % y random between -8r and 8r (zero centered)
                        X1 = [x1,y1];
                        X2=-X1;
                        XX=[X1;X2];
                    end
                    for j=1:app.nBalls
                        d=[0,0;XX(j,:)]; %distance from center
                        if app.nBalls>2 && strcmp(app.arenashapeSwitch.Value, 'Circle') == 1 && pdist(d)>=app.arena.r % remove generation if points outside circle of radius r
                            cont=cont+1;
                            break
                        else
                            if j==app.nBalls
                                cont=0; % if all points are inside count=0 makes while loop to end and pass to next generation
                            end
                        end
                    end
                end
                sID(i,1) = round(mean(pdist(XX),2),2);
                if app.nBalls > 2
                    [~,av] = convhull(XX);
                    sCH(i,1) = round(av,2);
                elseif app.nBalls == 2
                    sCH(i,1) = 2*round(mean(pdist(XX),2),2);    
                end
            end
            app.suggestedID = mean(sID,1);
            app.suggestedCH = mean(sCH,1);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;
            app.UIFigure.Name='GeNEsIS - Step 1.1: create balanced positions';
            app.arena.r=app.ArenaradiusEditField.Value;
            app.nBalls=app.EditFieldNumerosity.Value;
            app.nGenerations=app.generationsEditField.Value;
            app.visualizeSlider.Limits=[1 app.nGenerations];
            app.visualizeSlider.Enable='off';
            app.Generation.Text=[ '1/' num2str(app.nGenerations)];
            app.Generation.Enable='off';
            app.UpdateLabel.Text='Not updated!';
            app.UpdateLabel.FontColor = [0.96,0.36,0.32];
            app.ProceedButton.Enable='off';
            app.targetCH = 0;
            app.tolerance = 0;
            app.arena.shape = 'c';
        end

        % Value changed function: ArenaradiusEditField
        function ArenaradiusEditFieldValueChanged(app, event)
            app.arena.r = app.ArenaradiusEditField.Value; 
            app.ComputeButton.Enable='on';
            app.UpdateLabel.Text='Not updated!';
            app.UpdateLabel.FontColor = [0.96,0.36,0.32];
            waitfor(msgbox('Big values of arena radius can make the program slow down or abort if using a circular arena! If you want to use a big numerosity and thus increasing the arena size, if possible choose a squared arena' , 'Attention'));
            drawnow;
            if isempty(app.ball)
                app.visualizeSlider.Enable='off';
                app.Generation.Enable='off';
                app.ProceedButton.Enable='off';
            end
            app.NothingButton.Value = 1;
            app.EditField.Enable='off';
            app.EditFieldLabel.Enable='off';
            app.EditFieldLabel.Text='--';
            app.ComputeButton.Enable='on';
            app.AcceptederrorEditField.Value = 0;
            app.targetCH = 0;
            figure(app.UIFigure)
        end

        % Value changed function: generationsEditField
        function generationsEditFieldValueChanged(app, event)
            app.nGenerations = app.generationsEditField.Value;
            app.visualizeSlider.Limits=[1 app.nGenerations];
            app.ComputeButton.Enable='on';
            app.UpdateLabel.Text='Not update!';
            app.UpdateLabel.FontColor = [0.96,0.36,0.32];
            app.visualizeSlider.Enable='off';
            app.Generation.Enable='off';
            app.targetCH = 0;
        end

        % Value changed function: EditFieldNumerosity
        function EditFieldNumerosityValueChanged(app, event)
            app.nBalls = app.EditFieldNumerosity.Value;
            app.InterdistanceButton.Enable='on';
            app.ConvexhullButton.Enable='on';
            app.DensityButton.Enable='on';
            if app.nBalls==1 % for one point CH, D and ID not defined, disabled
                app.NothingButton.Value = true;
                app.InterdistanceButton.Enable='off';
                app.ConvexhullButton.Enable='off';
                app.DensityButton.Enable='off';
            end
            app.ComputeButton.Enable='on'; 
            app.UpdateLabel.Text='Not updated!';
            app.UpdateLabel.FontColor = [0.96,0.36,0.32];
            app.visualizeSlider.Enable='off';
            app.Generation.Enable='off';
            app.NothingButton.Value = 1;
            app.EditField.Enable='off';
            app.EditFieldLabel.Enable='off';
            app.EditFieldLabel.Text='--';
            app.ComputeButton.Enable='on';
            app.AcceptederrorEditField.Value = 0;
            app.targetCH = 0;
        end

        % Selection changed function: BalanceButtonGroup
        function BalanceButtonGroupSelectionChanged(app, event)
            selectedButton = app.BalanceButtonGroup.SelectedObject;
            switch selectedButton.Text
                case 'Inter distance'
                    app.EditField.Enable='on';
                    app.EditFieldLabel.Enable='on';
                    app.EditFieldLabel.Text='Fixed ID:';
                    suggestConstraint(app);
                    app.EditField.Value = round(app.suggestedID,2);
                    app.AcceptederrorEditField.Value = 0.01;
                    app.tolerance = 0.01;
                    app.targetCH = 0;
                case 'Convex hull'
                    app.EditField.Enable='on';
                    app.EditFieldLabel.Enable='on';
                    app.EditFieldLabel.Text='Fixed CH:';
                    suggestConstraint(app);
                    app.EditField.Value = round(app.suggestedCH,2);
                    app.targetCH = app.suggestedCH;
                    app.AcceptederrorEditField.Value = 0.01;
                    app.tolerance = 0.01;
                    if app.nBalls==2
                        waitfor(msgbox('For two points the convex hull is in this step approximated with the distance. Since this value will be adjusted in the next step considering the elements areas, the distance will be after reduced in order to fit the desired CH. Thus, to allow more configurations the CH can be set here bigger than the arena dimentions, in which case you will see points outside it. In the next step, after distance adjustments, only the ones re-fitting inside the arena will be kept.','Info CH'));
                    end
                case 'Nothing'
                    app.EditField.Enable='off';
                    app.EditFieldLabel.Enable='off';
                    app.EditFieldLabel.Text='--';
                    app.ComputeButton.Enable='on';
                    app.AcceptederrorEditField.Value = 0;
                    app.tolerance = 0;
                    app.targetCH = 0;
                case 'Density'
                    app.EditField.Enable='on';
                    app.EditFieldLabel.Enable='on';
                    app.EditFieldLabel.Text='Fixed D:';
                    suggestConstraint(app);
                    app.EditField.Value = round(app.nBalls/app.suggestedCH,2);
                    app.AcceptederrorEditField.Value = 0.01;
                    app.tolerance = 0.01;
                    app.targetCH = app.suggestedCH;
                case 'Minimum distance'
                    app.EditField.Enable='on';
                    app.EditFieldLabel.Enable='on';
                    app.EditFieldLabel.Text='Minimum d:';
                    suggestConstraint(app);
                    app.EditField.Value = round(app.suggestedID,2);
                    app.AcceptederrorEditField.Value = 0;
                    app.tolerance = 0;
                    app.targetCH = 0;
            end
            app.UpdateLabel.Text='Not updated!';
            app.UpdateLabel.FontColor = [0.96,0.36,0.32];
            drawnow;
            if isempty(app.ball)
                app.visualizeSlider.Enable='off';
                app.Generation.Enable='off';
                app.ProceedButton.Enable='off';
            end
            figure(app.UIFigure)
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)

            app.ComputeButton.Enable='on';
            app.UpdateLabel.Text='Not updated!';
            app.UpdateLabel.FontColor = [0.96,0.36,0.32];
        end

        % Button pushed function: ComputeButton
        function ComputeButtonPushed(app, event)
            answer='Continue';
            if app.InterdistanceButton.Value==1||app.ConvexhullButton.Value==1
                answer = questdlg('The operation could take a long time.','Info', 'Continue', 'Abort', 'Continue');
            end
            switch answer
                case 'Continue'
                    f = waitbar(0,'Please wait while generating positions. Process at 0%');
                    stop=0; % parameter to check computation status (and abort if it takes too much time)
                    disp(['Start computation: ' datestr(now, 'dd/mm/yy-HH:MM')])
                    for i=1:app.nGenerations
                        if stop==1 % abort if generation computation takes too much time (>2 min)
                            disp('Aborted: parameters too restrictive')
                            cla(app.UIAxesPositions);
                            app.ProceedButton.Enable='off';
                            break;
                        end
                        switch app.BalanceButtonGroup.SelectedObject.Text
                            case 'Inter distance'
                                stop=computeIDPositions(app, i);
                            case 'Convex hull'
                                stop=computeCHPositions(app, i);
                            case 'Nothing'
                                computeRndPositions(app, i)
                            case 'Density'
                                stop=computeDPositions(app, i);
                            case 'Minimum distance'
                                stop=computeMinimumPositions(app, i);
                        end
                        waitbar(i/app.nGenerations,f,['Generation ' num2str(i) ' of ' num2str(app.nGenerations) '. Process at ' num2str(round(i/app.nGenerations*100)) '%']);
                        if ~ishghandle(f) % abort if user closes progress bar
                           disp('Stopped by user!');
                           app.nGenerations=i;
                           app.generationsEditField.Value=app.nGenerations;
                           app.visualizeSlider.Limits=[1 app.nGenerations];
                           break
                        end
                    end
                    if ishghandle(f) % if progress bar was not closed by user
                        close(f)
                    end
                    app.visualizeSlider.Value=1;
                    if app.nGenerations>1
                        app.visualizeSlider.Enable='on';
                    end
                    app.Generation.Text=[ '1/' num2str(app.nGenerations)];
                    app.Generation.Enable='on';
                    plotPositions(app, 1);
                    app.visualizeSlider.Enable='on';
                    app.UpdateLabel.Text='Updated';
                    app.UpdateLabel.FontColor = [0.32,0.72,0.53];
                    app.ProceedButton.Enable='on';

                    if stop==0
                        disp(['End computation: ' datestr(now, 'dd/mm/yy-HH:MM')])
                    end 
                    figure(app.UIFigure)
                case 'Abort'
                    drawnow;
                    app.visualizeSlider.Enable='off';
                    app.Generation.Enable='off';
                    app.ProceedButton.Enable='off';
                    figure(app.UIFigure)
            end
        end

        % Value changing function: visualizeSlider
        function visualizeSliderValueChanging(app, event)
            changingValue = round(event.Value);
            app.Generation.Text=[num2str(changingValue) '/' num2str(app.nGenerations)];
            plotPositions(app, changingValue)
            app.visualizeSlider.Value=changingValue;
        end

        % Button pushed function: ProceedButton
        function ProceedButtonPushed(app, event)
            shapesGenesis(app)
        end

        % Value changed function: ReferencearenarcmEditField
        function ReferencearenarcmEditFieldValueChanged(app, event)
            value = app.ReferencearenarcmEditField.Value;
            if value==0
                app.pxLabel.Text='1 px = ---';
                app.px2Label.Text='1 px^2 = ---';
            else
                pixelDim=value/app.arena.r;
                app.pxLabel.Text=['1 px = ', num2str(round(pixelDim,2)), ' cm'];
                app.px2Label.Text=['1 px^2 = ', num2str(round(pixelDim*pixelDim,2)), ' cm^2'];
            end
        end

        % Button pushed function: iButton
        function iButtonPushed(app, event)
            winopen('./Tutorial/GeNEsIS_create_tutorial.pdf')
        end

        % Value changed function: arenashapeSwitch
        function arenashapeSwitchValueChanged(app, event)

            app.NothingButton.Value = 1;
            app.EditField.Enable='off';
            app.EditFieldLabel.Enable='off';
            app.EditFieldLabel.Text='--';
            app.ComputeButton.Enable='on';
            app.AcceptederrorEditField.Value = 0;
            app.targetCH = 0;
            switch app.arenashapeSwitch.Value
                case 'Circle'
                    app.arena.shape = 'c';
                case 'Square'
                    app.arena.shape = 's';
            end
        end

        % Button pushed function: iButton_2
        function iButton_2Pushed(app, event)
            msgbox('The program works in pixels at this first step; you could not worry about effective dimensions at this stage! Nevertheless, you can use the conversion scale to have an idea of the dimensions, but remember that these will be fixed only in step 2: GeNEsIS_save, when the program will save the final images. Thus, to have a consistent conversion, pay attention to use the same reference (the arena radius in cm) you will save the images with!','Info: units');
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            clc;
            app.UIFigure.Visible='off';
            [file,path] = uigetfile;
            load([path '/' file], 'sample');
            app.UIFigure.Visible='on';
            app.arena=sample.arena;
            app.ball.X=sample.ball.X;
            app.nBalls=sample.nBalls;
            app.nGenerations=sample.nGenerations;
            app.targetCH=sample.targetCH;
            app.visualizeSlider.Value=1;
            if app.nGenerations ~= 1
                app.visualizeSlider.Limits=[1 app.nGenerations];
                app.visualizeSlider.Enable = 'on';
            end
            plotPositions(app, 1);
            app.ProceedButton.Enable = 'on';
            figure(app.UIFigure)
        end

        % Value changed function: AcceptederrorEditField
        function AcceptederrorEditFieldValueChanged(app, event)
            app.tolerance = app.AcceptederrorEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxesPositions = uiaxes(app.UIFigure);
            app.UIAxesPositions.XTick = [-100 -90 -80 -70 -60 -50 -40 -30 -20 -10 0 10 20 30 40 50 60 70 80 90 100];
            app.UIAxesPositions.XTickLabel = {'-100'; '-90'; '-80'; '-70'; '-60'; '-50'; '-40'; '-30'; '-20'; '-10'; '0'; '10'; '20'; '30'; '40'; '50'; '60'; '70'; '80'; '90'; '100'};
            app.UIAxesPositions.YTick = [-100 -90 -80 -70 -60 -50 -40 -30 -20 -10 0 10 20 30 40 50 60 70 80 90 100];
            app.UIAxesPositions.YTickLabel = {'-100'; '-90'; '-80'; '-70'; '-60'; '-50'; '-40'; '-30'; '-20'; '-10'; '0'; '10'; '20'; '30'; '40'; '50'; '60'; '70'; '80'; '90'; '100'};
            app.NumerosityLabel = uilabel(app.UIFigure);
            app.NumerosityLabel.Text = 'Numerosity';
            app.EditFieldNumerosity = uieditfield(app.UIFigure, 'numeric');
            app.EditFieldNumerosity.Limits = [1 Inf];
            app.EditFieldNumerosity.ValueChangedFcn = createCallbackFcn(app, @EditFieldNumerosityValueChanged, true);
            app.EditFieldNumerosity.Value = 3;
            app.InterDistance = uilabel(app.UIFigure);
            app.InterDistance.Text = 'Inter distance';
            app.ConvexHull = uilabel(app.UIFigure);
            app.ConvexHull.Text = 'Convex hull';
            app.ComputeButton = uibutton(app.UIFigure, 'push');
            app.ComputeButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeButtonPushed, true);
            app.ComputeButton.Text = 'Compute';
            app.visualizeSlider = uislider(app.UIFigure);
            app.visualizeSlider.Limits = [1 100000];
            app.visualizeSlider.MajorTicks = [];
            app.visualizeSlider.MajorTickLabels = {''};
            app.visualizeSlider.ValueChangingFcn = createCallbackFcn(app, @visualizeSliderValueChanging, true);
            app.visualizeSlider.MinorTicks = [];
            app.visualizeSlider.Value = 1;
            app.generationsEditFieldLabel = uilabel(app.UIFigure);
            app.generationsEditFieldLabel.Text = '# generations';
            app.generationsEditField = uieditfield(app.UIFigure, 'numeric');
            app.generationsEditField.Limits = [1 Inf];
            app.generationsEditField.ValueChangedFcn = createCallbackFcn(app, @generationsEditFieldValueChanged, true);
            app.generationsEditField.Value = 100;
            app.ProceedButton = uibutton(app.UIFigure, 'push');
            app.ProceedButton.ButtonPushedFcn = createCallbackFcn(app, @ProceedButtonPushed, true);
            app.ProceedButton.Enable = 'off';
            app.ProceedButton.Text = 'Proceed >>';
            app.Generation = uilabel(app.UIFigure);
            app.ArenaradiusEditFieldLabel = uilabel(app.UIFigure);
            app.ArenaradiusEditFieldLabel.Text = 'Arena radius';
            app.ArenaradiusEditField = uieditfield(app.UIFigure, 'numeric');
            app.ArenaradiusEditField.Limits = [4 300];
            app.ArenaradiusEditField.ValueChangedFcn = createCallbackFcn(app, @ArenaradiusEditFieldValueChanged, true);
            app.ArenaradiusEditField.Value = 10;
            app.UpdateLabel = uilabel(app.UIFigure);
            app.UpdateLabel.Text = 'Not updated!';
            app.BalanceButtonGroup = uibuttongroup(app.UIFigure);
            app.BalanceButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @BalanceButtonGroupSelectionChanged, true);
            app.BalanceButtonGroup.Title = 'Balance:';
            app.NothingButton = uiradiobutton(app.BalanceButtonGroup);
            app.NothingButton.Text = 'Nothing';
            app.NothingButton.Value = true;
            app.InterdistanceButton = uiradiobutton(app.BalanceButtonGroup);
            app.InterdistanceButton.Text = 'Inter distance';
            app.ConvexhullButton = uiradiobutton(app.BalanceButtonGroup);
            app.ConvexhullButton.Text = 'Convex hull';
            app.DensityButton = uiradiobutton(app.BalanceButtonGroup);
            app.DensityButton.Text = 'Density';
            app.EditFieldLabel = uilabel(app.BalanceButtonGroup);
            app.EditFieldLabel.Enable = 'off';
            app.EditFieldLabel.Text = '--';
            app.EditField = uieditfield(app.BalanceButtonGroup, 'numeric');
            app.EditField.Limits = [0 Inf];
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Enable = 'off';
            app.AcceptederrorLabel = uilabel(app.BalanceButtonGroup);
            app.AcceptederrorLabel.Text = 'Accepted error [%]: ';
            app.AcceptederrorEditField = uieditfield(app.BalanceButtonGroup, 'numeric');
            app.AcceptederrorEditField.Limits = [0 5];
            app.AcceptederrorEditField.ValueChangedFcn = createCallbackFcn(app, @AcceptederrorEditFieldValueChanged, true);
            app.MinimumdistanceButton = uiradiobutton(app.BalanceButtonGroup);
            app.MinimumdistanceButton.Text = 'Minimum distance';
            app.ConvexHull_2 = uilabel(app.UIFigure);
            app.ConvexHull_2.Text = 'Density';
            app.UnitsestimationPanel = uipanel(app.UIFigure);
            app.UnitsestimationPanel.Title = 'Units estimation';
            app.ReferencearenarcmEditFieldLabel = uilabel(app.UnitsestimationPanel);
            app.ReferencearenarcmEditFieldLabel.Text = 'Reference: arena r [cm]';
            app.ReferencearenarcmEditField = uieditfield(app.UnitsestimationPanel, 'numeric');
            app.ReferencearenarcmEditField.ValueChangedFcn = createCallbackFcn(app, @ReferencearenarcmEditFieldValueChanged, true);
            app.pxLabel = uilabel(app.UnitsestimationPanel);
            app.pxLabel.Text = '1 px = ---';
            app.px2Label = uilabel(app.UnitsestimationPanel);
            app.px2Label.Text = '1 px^2 = ---';
            app.LinearconversionLabel = uilabel(app.UnitsestimationPanel);
            app.LinearconversionLabel.Text = 'Linear conversion:';
            app.AreasconversionLabel = uilabel(app.UnitsestimationPanel);
            app.AreasconversionLabel.Text = 'Area''s conversion:';
            app.arenashapeSwitch = uiswitch(app.UIFigure, 'slider');
            app.arenashapeSwitch.Items = {'Circle', 'Square'};
            app.arenashapeSwitch.ValueChangedFcn = createCallbackFcn(app, @arenashapeSwitchValueChanged, true);
            app.arenashapeSwitch.Value = 'Circle';
            app.GeNEsIScreateLabel = uilabel(app.UIFigure);
            app.GeNEsIScreateLabel.Text = 'GeNEsIS - create';
            app.iButton = uibutton(app.UIFigure, 'push');
            app.iButton.ButtonPushedFcn = createCallbackFcn(app, @iButtonPushed, true);
            app.iButton.Text = 'i';
            app.iButton_2 = uibutton(app.UIFigure, 'push');
            app.iButton_2.ButtonPushedFcn = createCallbackFcn(app, @iButton_2Pushed, true);
            app.iButton_2.Text = 'i';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.ConvexHull_3 = uilabel(app.UIFigure);
            app.ConvexHull_3.Text = 'Minimum distance';
            app.FromborderEditFieldLabel = uilabel(app.UIFigure);
            app.FromborderEditFieldLabel.Text = 'From border';
            app.FromborderEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxcomputationtsEditFieldLabel = uilabel(app.UIFigure);
            app.MaxcomputationtsEditFieldLabel.Text = 'Max computation t  [s]';
            app.MaxcomputationtsEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxcomputationtsEditField.Value = 180;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GeNEsIS_create

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end