classdef GeNEsIS_display < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        Set1Tab                         matlab.ui.container.Tab
        TA_IDButton                     matlab.ui.control.StateButton
        TA_CHButton                     matlab.ui.control.StateButton
        TP_IDButton                     matlab.ui.control.StateButton
        TP_CHButton                     matlab.ui.control.StateButton
        NC_IDButton                     matlab.ui.control.StateButton
        NC_CHButton                     matlab.ui.control.StateButton
        CustomButton                    matlab.ui.control.StateButton
        TA_ID_fileLabel                 matlab.ui.control.Label
        TA_CH_fileLabel                 matlab.ui.control.Label
        TP_ID_fileLabel                 matlab.ui.control.Label
        TP_CH_fileLabel                 matlab.ui.control.Label
        NC_ID_fileLabel                 matlab.ui.control.Label
        NC_CH_fileLabel                 matlab.ui.control.Label
        Custom_fileLabel                matlab.ui.control.Label
        Label_1                         matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        Label_7                         matlab.ui.control.Label
        GroupLabel                      matlab.ui.control.Label
        Set2Tab                         matlab.ui.container.Tab
        TA_IDButton_2                   matlab.ui.control.StateButton
        TA_CHButton_2                   matlab.ui.control.StateButton
        TP_IDButton_2                   matlab.ui.control.StateButton
        TP_CHButton_2                   matlab.ui.control.StateButton
        NC_IDButton_2                   matlab.ui.control.StateButton
        NC_CHButton_2                   matlab.ui.control.StateButton
        CustomButton_2                  matlab.ui.control.StateButton
        TA_ID_fileLabel_2               matlab.ui.control.Label
        TA_CH_fileLabel_2               matlab.ui.control.Label
        TP_ID_fileLabel_2               matlab.ui.control.Label
        TP_CH_fileLabel_2               matlab.ui.control.Label
        NC_ID_fileLabel_2               matlab.ui.control.Label
        NC_CH_fileLabel_2               matlab.ui.control.Label
        Custom_fileLabel_2              matlab.ui.control.Label
        Label_8                         matlab.ui.control.Label
        Label_9                         matlab.ui.control.Label
        Label_10                        matlab.ui.control.Label
        Label_11                        matlab.ui.control.Label
        Label_12                        matlab.ui.control.Label
        Label_13                        matlab.ui.control.Label
        Label_14                        matlab.ui.control.Label
        GroupLabel_2                    matlab.ui.control.Label
        Set3Tab                         matlab.ui.container.Tab
        TA_IDButton_3                   matlab.ui.control.StateButton
        TA_CHButton_3                   matlab.ui.control.StateButton
        TP_IDButton_3                   matlab.ui.control.StateButton
        TP_CHButton_3                   matlab.ui.control.StateButton
        NC_IDButton_3                   matlab.ui.control.StateButton
        NC_CHButton_3                   matlab.ui.control.StateButton
        CustomButton_3                  matlab.ui.control.StateButton
        TA_ID_fileLabel_3               matlab.ui.control.Label
        TA_CH_fileLabel_3               matlab.ui.control.Label
        TP_ID_fileLabel_3               matlab.ui.control.Label
        TP_CH_fileLabel_3               matlab.ui.control.Label
        NC_ID_fileLabel_3               matlab.ui.control.Label
        NC_CH_fileLabel_3               matlab.ui.control.Label
        Custom_fileLabel_3              matlab.ui.control.Label
        Label_15                        matlab.ui.control.Label
        Label_16                        matlab.ui.control.Label
        Label_17                        matlab.ui.control.Label
        Label_18                        matlab.ui.control.Label
        Label_19                        matlab.ui.control.Label
        Label_20                        matlab.ui.control.Label
        Label_21                        matlab.ui.control.Label
        GroupLabel_3                    matlab.ui.control.Label
        Set4Tab                         matlab.ui.container.Tab
        TA_IDButton_4                   matlab.ui.control.StateButton
        TA_CHButton_4                   matlab.ui.control.StateButton
        TP_IDButton_4                   matlab.ui.control.StateButton
        TP_CHButton_4                   matlab.ui.control.StateButton
        NC_IDButton_4                   matlab.ui.control.StateButton
        NC_CHButton_4                   matlab.ui.control.StateButton
        CustomButton_4                  matlab.ui.control.StateButton
        TA_ID_fileLabel_4               matlab.ui.control.Label
        TA_CH_fileLabel_4               matlab.ui.control.Label
        TP_ID_fileLabel_4               matlab.ui.control.Label
        TP_CH_fileLabel_4               matlab.ui.control.Label
        NC_ID_fileLabel_4               matlab.ui.control.Label
        NC_CH_fileLabel_4               matlab.ui.control.Label
        Custom_fileLabel_4              matlab.ui.control.Label
        Label_22                        matlab.ui.control.Label
        Label_23                        matlab.ui.control.Label
        Label_24                        matlab.ui.control.Label
        Label_25                        matlab.ui.control.Label
        Label_26                        matlab.ui.control.Label
        Label_27                        matlab.ui.control.Label
        Label_28                        matlab.ui.control.Label
        GroupLabel_4                    matlab.ui.control.Label
        GeNEsISdisplayLabel             matlab.ui.control.Label
        TabGroup2                       matlab.ui.container.TabGroup
        HabituationTab                  matlab.ui.container.Tab
        habituationstimuliEditFieldLabel  matlab.ui.control.Label
        habituationstimuliEditField     matlab.ui.control.NumericEditField
        dishabituationstimuliEditFieldLabel  matlab.ui.control.Label
        dishabituationstimuliEditField  matlab.ui.control.NumericEditField
        HabituationsetDropDownLabel     matlab.ui.control.Label
        HabituationsetDropDown          matlab.ui.control.DropDown
        DishabituationsetDropDownLabel  matlab.ui.control.Label
        DishabituationsetDropDown       matlab.ui.control.DropDown
        cyclesEditFieldLabel            matlab.ui.control.Label
        cyclesEditField                 matlab.ui.control.NumericEditField
        SimultaneousdiscriminationTab   matlab.ui.container.Tab
        CoupletrainingDropDownLabel     matlab.ui.control.Label
        CoupletrainingDropDown          matlab.ui.control.DropDown
        CoupletestDropDownLabel         matlab.ui.control.Label
        CoupletestDropDown              matlab.ui.control.DropDown
        trainingtrialsEditFieldLabel    matlab.ui.control.Label
        trainingtrialsEditField         matlab.ui.control.NumericEditField
        testtrialsEditFieldLabel        matlab.ui.control.Label
        testtrialsEditField             matlab.ui.control.NumericEditField
        cyclesEditField_3Label          matlab.ui.control.Label
        cyclesEditField_2               matlab.ui.control.NumericEditField
        PresentationPanel               matlab.ui.container.Panel
        StimulusdurationsEditFieldLabel  matlab.ui.control.Label
        StimulusdurationsEditField      matlab.ui.control.NumericEditField
        InterstimulipausesEditFieldLabel  matlab.ui.control.Label
        InterstimulipausesEditField     matlab.ui.control.NumericEditField
        ProceedSwitchLabel              matlab.ui.control.Label
        ProceedSwitch                   matlab.ui.control.Switch
        StartpresentationButton         matlab.ui.control.Button
        SaveasLabel                     matlab.ui.control.Label
        SaveinfostoEditField            matlab.ui.control.EditField
        MonitorsizeLabel                matlab.ui.control.Label
        ScreenEditFieldLabel            matlab.ui.control.Label
        ScreenEditField                 matlab.ui.control.NumericEditField
        CheckscreenButton               matlab.ui.control.Button
        TypeDropDownLabel               matlab.ui.control.Label
        TypeDropDown                    matlab.ui.control.DropDown
        iButton                         matlab.ui.control.Button
    end

    
    properties (Access = private)
        stimulus % data cell {set, group}{1,element}
        shuffleOrder %random order presentation
        presentationArray
        presentationArrDiscr
        presentationSet
        presentationIndex
        tStart
        Nscreen
        grid
        sequence
        element
        elementDiscr
        sett
        settDiscr
        group
        groupDiscr
        sx
        dx
    end
    
    methods (Access = private)
        
        
        function [file, data]=loadData(app)
            app.UIFigure.Visible='off';
            [file,path] = uigetfile('*');
            data=load([path '/' file]);
            app.UIFigure.Visible='on';
        end
        
        function shuffle(app, set) % randomize stimuli inside a loaded file
            for i=1:7
                nElem=size(app.stimulus{set,i},2);
                app.shuffleOrder{set,i}=randperm(nElem);
                app.stimulus{set,i}=app.stimulus{set,i}(app.shuffleOrder{set,i});
            end
        end
        
        % create sequence of groups to present for habituation task (n1 habituation + n2 dishabituation repeteated for n of cycles)
        function createHabPresentation(app)
            n1 = app.habituationstimuliEditField.Value;
            n2 = app.dishabituationstimuliEditField.Value;
            rep = app.cyclesEditField.Value;
            switch app.HabituationsetDropDown.Value
                case 'Set 1'
                    hab = 1;
                case 'Set 2'
                    hab = 2;
                case 'Set 3'
                    hab = 3;
                case 'Set 4'
                    hab = 4;
            end
            switch app.DishabituationsetDropDown.Value
                case 'Set 1'
                    dis = 1;
                case 'Set 2'
                    dis = 2;
                case 'Set 3'
                    dis = 3;
                case 'Set 4'
                    dis = 4;
            end
            habCondArray = [];
            disCondArray = [];
            for i=1:7
                if ~isempty(app.stimulus{hab,i})
                    habCondArray=[habCondArray, i+7*(hab-1)];
                end
                if ~isempty(app.stimulus{dis,i})
                    disCondArray=[disCondArray, i+7*(dis-1)];
                end
            end
            HcondArray=[];
            DcondArray=[];
            for j=1:1000*rep
                habCondArray=habCondArray(randperm(length(habCondArray)));
                disCondArray=disCondArray(randperm(length(disCondArray)));
                HcondArray = [HcondArray habCondArray];
                DcondArray = [DcondArray disCondArray];
            end
            HcondArray=HcondArray(1:n1*rep);
            DcondArray=DcondArray(1:n2*rep);

            app.presentationArray=[];
            for j=1:rep
                app.presentationArray=[app.presentationArray HcondArray(1+(j-1)*n1:j*n1) DcondArray(1+(j-1)*n2:j*n2)];
            end
            arrayElements(app);
            disp('Habituation sequence created!')
        end
        
        function createDiscrPresentation(app)
            n1 = app.trainingtrialsEditField.Value;
            n2 = app.testtrialsEditField.Value;
            rep = app.cyclesEditField_2.Value;
            switch app.CoupletrainingDropDown.Value
                case 'Set 1+2'
                    hab{1} = 1;
                    hab{2} = 2;
                case 'Set 1+3'
                    hab{1} = 1;
                    hab{2} = 3;
                case 'Set 1+4'
                    hab{1} = 1;
                    hab{2} = 4;
                case 'Set 2+3'
                    hab{1} = 2;
                    hab{2} = 3;
                case 'Set 2+4'
                    hab{1} = 2;
                    hab{2} = 4;
                case 'Set 3+4'
                    hab{1} = 3;
                    hab{2} = 4;
            end
            switch app.CoupletestDropDown.Value
                case 'Set 1+2'
                    dis{1} = 1;
                    dis{2} = 2;
                case 'Set 1+3'
                    dis{1} = 1;
                    dis{2} = 3;
                case 'Set 1+4'
                    dis{1} = 1;
                    dis{2} = 4;
                case 'Set 2+3'
                    dis{1} = 2;
                    dis{2} = 3;
                case 'Set 2+4'
                    dis{1} = 2;
                    dis{2} = 4;
                case 'Set 3+4'
                    dis{1} = 3;
                    dis{2} = 4;
            end
            habCondArray{1} = [];
            disCondArray{1} = [];
            habCondArray{2} = [];
            disCondArray{2} = [];
            for i=1:7
                if ~isempty(app.stimulus{hab{1},i})
                    habCondArray{1}=[habCondArray{1}, i+7*(hab{1}-1)];
                end
                if ~isempty(app.stimulus{dis{1},i})
                    disCondArray{1}=[disCondArray{1}, i+7*(dis{1}-1)];
                end
                if ~isempty(app.stimulus{hab{2},i})
                    habCondArray{2}=[habCondArray{2}, i+7*(hab{2}-1)];
                end
                if ~isempty(app.stimulus{dis{2},i})
                    disCondArray{2}=[disCondArray{2}, i+7*(dis{2}-1)];
                end
            end
            HcondArray{1}=[];
            DcondArray{1}=[];
            HcondArray{2}=[];
            DcondArray{2}=[];
            for j=1:1000*rep
                index=randperm(length(habCondArray{1}));
                habCondArray{1}=habCondArray{1}(index);
                habCondArray{2}=habCondArray{2}(index);
                disCondArray{1}=disCondArray{1}(index);
                disCondArray{2}=disCondArray{2}(index);
                HcondArray{1} = [HcondArray{1} habCondArray{1}];
                DcondArray{1} = [DcondArray{1} disCondArray{1}];
                HcondArray{2} = [HcondArray{2} habCondArray{2}];
                DcondArray{2} = [DcondArray{2} disCondArray{2}];
            end
            HcondArray{1}=HcondArray{1}(1:n1*rep);
            DcondArray{1}=DcondArray{1}(1:n2*rep);
            HcondArray{2}=HcondArray{2}(1:n1*rep);
            DcondArray{2}=DcondArray{2}(1:n2*rep);

            app.presentationArrDiscr{1}=[];
            app.presentationArrDiscr{2}=[];
            H1=HcondArray{1};
            H2=HcondArray{2};
            D1=DcondArray{1};
            D2=DcondArray{2};
            for j=1:rep
                app.presentationArrDiscr{1}=[app.presentationArrDiscr{1} H1(1+(j-1)*n1:j*n1) D1(1+(j-1)*n2:j*n2)];
                app.presentationArrDiscr{2}=[app.presentationArrDiscr{2} H2(1+(j-1)*n1:j*n1) D2(1+(j-1)*n2:j*n2)];
            end
            arrayElements(app);
            disp('Discrimination sequence created!')
        end
       
        % create array of elements number to present at each trial inside a group
        function arrayElements(app)
            switch app.TypeDropDown.Value
                case 'Habituation'
                    counter=zeros(4,7);
                    elementOrd=zeros(length(app.presentationArray),1);
                    app.element=zeros(length(app.presentationArray),1);
                    for i=1:length(app.presentationArray)
                        if app.presentationArray(i)<=7
                            if counter(1,app.presentationArray(i))==size(app.stimulus{1,app.presentationArray(i)},2)
                                counter(1,app.presentationArray(i))=0;
                            end
                            counter(1,app.presentationArray(i))=counter(1,app.presentationArray(i))+1;
                            elementOrd(i)=counter(1,app.presentationArray(i));
                        elseif app.presentationArray(i)<=14
                            if counter(2,app.presentationArray(i)-7)==size(app.stimulus{2,app.presentationArray(i)-7},2)
                                counter(2,app.presentationArray(i)-7)=0;
                            end
                            counter(2,app.presentationArray(i)-7)=counter(2,app.presentationArray(i)-7)+1;
                            elementOrd(i)=counter(2,app.presentationArray(i)-7);
                        elseif app.presentationArray(i)<=21
                            if counter(3,app.presentationArray(i)-14)==size(app.stimulus{3,app.presentationArray(i)-14},2)
                                counter(3,app.presentationArray(i)-14)=0;
                            end
                            counter(3,app.presentationArray(i)-14)=counter(3,app.presentationArray(i)-14)+1;
                            elementOrd(i)=counter(3,app.presentationArray(i)-14);
                        else
                            if counter(4,app.presentationArray(i)-21)==size(app.stimulus{4,app.presentationArray(i)-21},2)
                                counter(4,app.presentationArray(i)-21)=0;
                            end
                            counter(4,app.presentationArray(i)-21)=counter(4,app.presentationArray(i)-21)+1;
                            elementOrd(i)=counter(4,app.presentationArray(i)-21);
                        end
                    end
                    for i=1:length(app.presentationArray)
                        if app.presentationArray(i)<=7
                            app.sett(i)=1;
                        elseif app.presentationArray(i)<=14
                            app.sett(i)=2;
                        elseif app.presentationArray(i)<=21
                            app.sett(i)=3;
                        else
                            app.sett(i)=4;
                        end
                        app.group(i)=mod(app.presentationArray(i),7);
                        if app.group(i)==0
                            app.group(i)=7;
                        end
                        app.element(i)=app.shuffleOrder{app.sett(i),app.group(i)}(elementOrd(i));
                    end
                case 'Discrimination'
                    counter=zeros(4,7);
                    elementOrd{1}=zeros(length(app.presentationArrDiscr{1}),1);
                    app.elementDiscr{1}=zeros(length(app.presentationArrDiscr{1}),1);
                    elementOrd{2}=zeros(length(app.presentationArrDiscr{2}),1);
                    app.elementDiscr{2}=zeros(length(app.presentationArrDiscr{2}),1);
                    for i=1:length(app.presentationArrDiscr{1})
                        if app.presentationArrDiscr{1}(i)<=7
                            if counter(1,app.presentationArrDiscr{1}(i))==size(app.stimulus{1,app.presentationArrDiscr{1}(i)},2)
                                counter(1,app.presentationArrDiscr{1}(i))=0;
                            end
                            counter(1,app.presentationArrDiscr{1}(i))=counter(1,app.presentationArrDiscr{1}(i))+1;
                            elementOrd{1}(i)=counter(1,app.presentationArrDiscr{1}(i));
                        elseif app.presentationArrDiscr{1}(i)<=14
                            if counter(2,app.presentationArrDiscr{1}(i)-7)==size(app.stimulus{2,app.presentationArrDiscr{1}(i)-7},2)
                                counter(2,app.presentationArrDiscr{1}(i)-7)=0;
                            end
                            counter(2,app.presentationArrDiscr{1}(i)-7)=counter(2,app.presentationArrDiscr{1}(i)-7)+1;
                            elementOrd{1}(i)=counter(2,app.presentationArrDiscr{1}(i)-7);
                        elseif app.presentationArrDiscr{1}(i)<=21
                            if counter(3,app.presentationArrDiscr{1}(i)-14)==size(app.stimulus{3,app.presentationArrDiscr{1}(i)-14},2)
                                counter(3,app.presentationArrDiscr{1}(i)-14)=0;
                            end
                            counter(3,app.presentationArrDiscr{1}(i)-14)=counter(3,app.presentationArrDiscr{1}(i)-14)+1;
                            elementOrd{1}(i)=counter(3,app.presentationArrDiscr{1}(i)-14);
                        else
                            if counter(4,app.presentationArrDiscr{1}(i)-21)==size(app.stimulus{4,app.presentationArrDiscr{1}(i)-21},2)
                                counter(4,app.presentationArrDiscr{1}(i)-21)=0;
                            end
                            counter(4,app.presentationArrDiscr{1}(i)-21)=counter(4,app.presentationArrDiscr{1}(i)-21)+1;
                            elementOrd{1}(i)=counter(4,app.presentationArrDiscr{1}(i)-21);
                        end
                    end
                    for i=1:length(app.presentationArrDiscr{2})
                        if app.presentationArrDiscr{2}(i)<=7
                            if counter(1,app.presentationArrDiscr{2}(i))==size(app.stimulus{1,app.presentationArrDiscr{2}(i)},2)
                                counter(1,app.presentationArrDiscr{2}(i))=0;
                            end
                            counter(1,app.presentationArrDiscr{2}(i))=counter(1,app.presentationArrDiscr{2}(i))+1;
                            elementOrd{2}(i)=counter(1,app.presentationArrDiscr{2}(i));
                        elseif app.presentationArrDiscr{1}(i)<=14
                            if counter(2,app.presentationArrDiscr{2}(i)-7)==size(app.stimulus{2,app.presentationArrDiscr{2}(i)-7},2)
                                counter(2,app.presentationArrDiscr{2}(i)-7)=0;
                            end
                            counter(2,app.presentationArrDiscr{2}(i)-7)=counter(2,app.presentationArrDiscr{2}(i)-7)+1;
                            elementOrd{2}(i)=counter(2,app.presentationArrDiscr{2}(i)-7);
                        elseif app.presentationArrDiscr{2}(i)<=21
                            if counter(3,app.presentationArrDiscr{2}(i)-14)==size(app.stimulus{3,app.presentationArrDiscr{2}(i)-14},2)
                                counter(3,app.presentationArrDiscr{2}(i)-14)=0;
                            end
                            counter(3,app.presentationArrDiscr{2}(i)-14)=counter(3,app.presentationArrDiscr{2}(i)-14)+1;
                            elementOrd{2}(i)=counter(3,app.presentationArrDiscr{2}(i)-14);
                        else
                            if counter(4,app.presentationArrDiscr{2}(i)-21)==size(app.stimulus{4,app.presentationArrDiscr{2}(i)-21},2)
                                counter(4,app.presentationArrDiscr{2}(i)-21)=0;
                            end
                            counter(4,app.presentationArrDiscr{2}(i)-21)=counter(4,app.presentationArrDiscr{2}(i)-21)+1;
                            elementOrd{2}(i)=counter(4,app.presentationArrDiscr{2}(i)-21);
                        end
                    end
                    for i=1:length(app.presentationArrDiscr{1})
                        if app.presentationArrDiscr{1}(i)<=7
                            app.settDiscr{1}(i)=1;
                        elseif app.presentationArrDiscr{1}(i)<=14
                            app.settDiscr{1}(i)=2;
                        elseif app.presentationArrDiscr{1}(i)<=21
                            app.settDiscr{1}(i)=3;
                        else
                            app.settDiscr{1}(i)=4;
                        end
                        app.groupDiscr{1}(i)=mod(app.presentationArrDiscr{1}(i),7);
                        if app.groupDiscr{1}(i)==0
                            app.groupDiscr{1}(i)=7;
                        end
                        app.elementDiscr{1}(i)=app.shuffleOrder{app.settDiscr{1}(i),app.groupDiscr{1}(i)}(elementOrd{1}(i));
                        app.sx(i) = randi([1 2]);
                        if app.sx(i)==1
                            app.dx(i) = 2;
                        else
                            app.dx(i) = 1;
                        end
                    end
                    for i=1:length(app.presentationArrDiscr{2})
                        if app.presentationArrDiscr{2}(i)<=7
                            app.settDiscr{2}(i)=1;
                        elseif app.presentationArrDiscr{2}(i)<=14
                            app.settDiscr{2}(i)=2;
                        elseif app.presentationArrDiscr{2}(i)<=21
                            app.settDiscr{2}(i)=3;
                        else
                            app.settDiscr{2}(i)=4;
                        end
                        app.groupDiscr{2}(i)=mod(app.presentationArrDiscr{2}(i),7);
                        if app.groupDiscr{2}(i)==0
                            app.groupDiscr{2}(i)=7;
                        end
                        app.elementDiscr{2}(i)=app.shuffleOrder{app.settDiscr{2}(i),app.groupDiscr{2}(i)}(elementOrd{2}(i));
                    end
            end
        end
        
        function saveSequence(app)
            switch app.TypeDropDown.Value
                case 'Habituation'
                    trial=1:length(app.presentationArray);
                    T=table(trial',app.sett',app.group',app.element);%,app.timing);
                    T.Properties.VariableNames = {'Trial','Numerosity','Group','Stimulus'};%,'Time'};
                    writetable(T,[app.SaveinfostoEditField.Value '.xlsx'],'Sheet',1)
                case 'Discrimination'
                    trial=1:length(app.presentationArrDiscr{1});
                    T=table(trial',app.settDiscr{1}',app.settDiscr{2}',app.sx', app.groupDiscr{1}',app.groupDiscr{2}',app.elementDiscr{1},app.elementDiscr{2});%,app.timing);
                    T.Properties.VariableNames = {'Trial','Numerosity_1','Numerosity_2', 'Side_n1', 'Group_1', 'Group_2','Stimulus_1', 'Stimulus_2'};%,'Time'};
                    writetable(T,[app.SaveinfostoEditField.Value '.xlsx'],'Sheet',1)
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;
            app.UIFigure.Name = 'GeNEsIS - Step 3: display images on screen through an experimetal procedure';
            app.stimulus=cell(4,7);
            app.Nscreen=1;
            app.settDiscr{1}=[];
            app.settDiscr{2}=[];
            app.groupDiscr{1}=[];
            app.groupDiscr{2}=[];
            app.elementDiscr{1}=[];
            app.elementDiscr{2}=[];
        end

        % Value changed function: TA_IDButton
        function TA_IDButtonValueChanged(app, event)
            value = app.TA_IDButton.Value;
            if value==1
                [file, data]=loadData(app);
                app.stimulus{1,1}=data.imgMatrix;
                app.TA_ID_fileLabel.Text=['tot# ' num2str(size(app.stimulus{1,1},2)) ' - (file:' file ')'];
                app.TA_IDButton.BackgroundColor=[0 1 0];
            else
                app.TA_IDButton.BackgroundColor=[1 0 0];
                app.stimulus{1,1}=[];
            end
        end

        % Value changed function: TA_CHButton
        function TA_CHButtonValueChanged(app, event)
            value = app.TA_CHButton.Value;
            if value==1
                [file, data]=loadData(app);
                app.stimulus{1,2}=data.imgMatrix;
                app.TA_CH_fileLabel.Text=['tot# ' num2str(size(app.stimulus{1,2},2)) ' - (file:' file ')'];
                app.TA_CHButton.BackgroundColor=[0 1 0];
            else
                app.TA_CHButton.BackgroundColor=[1 0 0];
                app.stimulus{1,2}=[];
            end
        end

        % Value changed function: TP_IDButton
        function TP_IDButtonValueChanged(app, event)
            value = app.TP_IDButton.Value;
            if value==1
                [file, data]=loadData(app);
                app.stimulus{1,3}=data.imgMatrix;
                app.TP_ID_fileLabel.Text=['tot# ' num2str(size(app.stimulus{1,3},2)) ' - (file:' file ')'];
                app.TP_IDButton.BackgroundColor=[0 1 0];
            else
                app.TP_IDButton.BackgroundColor=[1 0 0];
                app.stimulus{1,3}=[];
            end
        end

        % Value changed function: TP_CHButton
        function TP_CHButtonValueChanged(app, event)
            value = app.TP_CHButton.Value;
            if value==1
                [file, data]=loadData(app);
                app.stimulus{1,4}=data.imgMatrix;
                app.TP_CH_fileLabel.Text=['tot# ' num2str(size(app.stimulus{1,4},2)) ' - (file:' file ')'];
                app.TP_CHButton.BackgroundColor=[0 1 0];
            else
                app.TP_CHButton.BackgroundColor=[1 0 0];
                app.stimulus{1,4}=[];
            end
        end

        % Value changed function: NC_IDButton
        function NC_IDButtonValueChanged(app, event)
            value = app.NC_IDButton.Value;
            if value==1
                [file, data]=loadData(app);
                app.stimulus{1,5}=data.imgMatrix;
                app.NC_ID_fileLabel.Text=['tot# ' num2str(size(app.stimulus{1,5},2)) ' - (file:' file ')'];
                app.NC_IDButton.BackgroundColor=[0 1 0];
            else
                app.NC_IDButton.BackgroundColor=[1 0 0];
                app.stimulus{1,5}=[];
            end
        end

        % Value changed function: NC_CHButton
        function NC_CHButtonValueChanged(app, event)
            value = app.NC_CHButton.Value;
            if value==1
                [file, data]=loadData(app);
                app.stimulus{1,6}=data.imgMatrix;
                app.NC_CH_fileLabel.Text=['tot# ' num2str(size(app.stimulus{1,6},2)) ' - (file:' file ')'];
                app.NC_CHButton.BackgroundColor=[0 1 0];
            else
                app.NC_CHButton.BackgroundColor=[1 0 0];
                app.stimulus{1,6}=[];
            end
        end

        % Value changed function: CustomButton
        function CustomButtonValueChanged(app, event)
            value = app.CustomButton.Value;
            if value==1
                [file, data]=loadData(app);
                app.stimulus{1,7}=data.imgMatrix;
                app.Custom_fileLabel.Text=['tot# ' num2str(size(app.stimulus{1,7},2)) ' - (file:' file ')'];
                app.CustomButton.BackgroundColor=[0 1 0];
            else
                app.CustomButton.BackgroundColor=[1 0 0];
                app.stimulus{1,7}=[];
            end
        end

        % Value changed function: TA_IDButton_2
        function TA_IDButton_2ValueChanged(app, event)
            value = app.TA_IDButton_2.Value;
            if value==1
                [file, data]=loadData(app);
                app.stimulus{2,1}=data.imgMatrix;
                app.TA_ID_fileLabel_2.Text=['tot# ' num2str(size(app.stimulus{2,1},2)) ' - (file:' file ')'];
                app.TA_IDButton_2.BackgroundColor=[0 1 0];
            else
                app.TA_IDButton_2.BackgroundColor=[1 0 0];
                app.stimulus{2,1}=[];
            end
        end

        % Value changed function: TA_CHButton_2
        function TA_CHButton_2ValueChanged(app, event)
            value = app.TA_CHButton_2.Value;
            if value==1
                [file, data]=loadData(app);
                app.stimulus{2,2}=data.imgMatrix;
                app.TA_CH_fileLabel_2.Text=['tot# ' num2str(size(app.stimulus{2,2},2)) ' - (file:' file ')'];
                app.TA_CHButton_2.BackgroundColor=[0 1 0];
            else
                app.TA_CHButton_2.BackgroundColor=[1 0 0];
                app.stimulus{2,2}=[];
            end
        end

        % Value changed function: TP_IDButton_2
        function TP_IDButton_2ValueChanged(app, event)
            value = app.TP_IDButton_2.Value;
            if value==1
                [file, data]=loadData(app);
                app.stimulus{2,3}=data.imgMatrix;
                app.TP_ID_fileLabel_2.Text=['tot# ' num2str(size(app.stimulus{2,3},2)) ' - (file:' file ')'];
                app.TP_IDButton_2.BackgroundColor=[0 1 0];
            else
                app.TP_IDButton_2.BackgroundColor=[1 0 0];
                app.stimulus{2,3}=[];
            end
        end

        % Value changed function: TP_CHButton_2
        function TP_CHButton_2ValueChanged(app, event)
            value = app.TP_CHButton_2.Value;
            if value==1
                [file, data]=loadData(app);
                app.stimulus{2,4}=data.imgMatrix;
                app.TP_CH_fileLabel_2.Text=['tot# ' num2str(size(app.stimulus{2,4},2)) ' - (file:' file ')'];
                app.TP_CHButton_2.BackgroundColor=[0 1 0];
            else
                app.TP_CHButton_2.BackgroundColor=[1 0 0];
                app.stimulus{2,4}=[];
            end
        end

        % Value changed function: NC_IDButton_2
        function NC_IDButton_2ValueChanged(app, event)
            value = app.NC_IDButton_2.Value;
            if value==1
                [file, data]=loadData(app);
                app.stimulus{2,5}=data.imgMatrix;
                app.NC_ID_fileLabel_2.Text=['tot# ' num2str(size(app.stimulus{2,5},2)) ' - (file:' file ')'];
                app.NC_IDButton_2.BackgroundColor=[0 1 0];
            else
                app.NC_IDButton_2.BackgroundColor=[1 0 0];
                app.stimulus{2,5}=[];
            end
        end

        % Value changed function: NC_CHButton_2
        function NC_CHButton_2ValueChanged(app, event)
            value = app.NC_CHButton_2.Value;
            if value==1
                [file, data]=loadData(app);
                app.stimulus{2,6}=data.imgMatrix;
                app.NC_CH_fileLabel_2.Text=['tot# ' num2str(size(app.stimulus{2,6},2)) ' - (file:' file ')'];
                app.NC_CHButton_2.BackgroundColor=[0 1 0];
            else
                app.NC_CHButton_2.BackgroundColor=[1 0 0];
                app.stimulus{2,6}=[];
            end
        end

        % Value changed function: CustomButton_2
        function CustomButton_2ValueChanged(app, event)
            value = app.CustomButton_2.Value;
            if value==1
                [file, data]=loadData(app);
                app.stimulus{2,7}=data.imgMatrix;
                app.Custom_fileLabel_2.Text=['tot# ' num2str(size(app.stimulus{2,7},2)) ' - (file:' file ')'];
                app.CustomButton_2.BackgroundColor=[0 1 0];
            else
                app.CustomButton_2.BackgroundColor=[1 0 0];
                app.stimulus{2,7}=[];
            end
        end

        % Button pushed function: StartpresentationButton
        function StartpresentationButtonPushed(app, event)
            clc;
            PsychDefaultSetup(2);
            % Define black and white (white will be 1 and black 0).
            white(app.Nscreen) = WhiteIndex(app.Nscreen);    
            %black(app.Nscreen) = BlackIndex(app.Nscreen);
            % Open an on screen window using PsychImaging and color it black.
            [window(app.Nscreen), ~] = PsychImaging('OpenWindow', app.Nscreen, white(app.Nscreen));
            % Get the size of the on screen window in pixels, these are the last two numbers in "windowRect"
            [screenXpixels(app.Nscreen), screenYpixels(app.Nscreen)] = Screen('WindowSize', window(app.Nscreen));
            % Get the centre coordinate of the window in pixels.
            %[xCenter(app.Nscreen), yCenter(app.Nscreen)] = RectCenter(windowRect(app.Nscreen,:,:,:,:));
            % Query the inter-frame-interval. This refers to the minimum possible time between drawing to the screen
            %refreshTime(app.Nscreen) = Screen('GetFlipInterval', window(app.Nscreen));
            % Refresh rate of our screen. The relationship between the two is:
            % refreshTime = 1 / refreshRate
            %refreshRate(app.Nscreen) = FrameRate(window(app.Nscreen));
            % Queries the display size in mm as reported by the operating system. Note that there are some complexities here. See Screen DisplaySize? for
            % information. So always measure your screen size directly.
            %[width_mm_screen(app.Nscreen), height_mm_screen(app.Nscreen)] = Screen('DisplaySize', app.Nscreen);
            % Set up alpha-blending for smooth (anti-aliased) lines
            Screen('BlendFunction', window(app.Nscreen), 'GL_SRC_ALPHA', 'GL_ONE_MINUS_SRC_ALPHA');
            % Length of time and number of refreshFrames we will use for each drawing test
%                         numSecs = 1/app.frameRate;
%                         waitFrames(i) = round(numSecs / refreshTime(i));
            % Retreive the maximum priority number and set max priority. This means PTB will take processing priority over other system and applicaiton processes.
            topPriorityLevel = MaxPriority(window(app.Nscreen));
            Priority(topPriorityLevel);

            Screen('FillRect', window(app.Nscreen), white(app.Nscreen));
            Screen('Flip', window(app.Nscreen));
            if strcmp(app.ProceedSwitch.Value, 'Keyboard')
                KbStrokeWait;
            else
                WaitSecs(app.InterstimulipausesEditField.Value);
            end
            
            switch app.TypeDropDown.Value
                case 'Habituation'
                    nTrial = length(app.presentationArray);
                case 'Discrimination'
                    nTrial = length(app.presentationArrDiscr{1});
            end
            
            for trial=1:nTrial
                disp(' ')
                disp(['Trial ' num2str(trial) ': starting at time ' datestr(now,'HH:MM:SS.FFF')])
             
                switch app.TypeDropDown.Value

                    case 'Habituation'
                        disp(['Set ' num2str(app.sett(trial)) ', group ' num2str(app.group(trial))])
 
                        imageTexture = Screen('MakeTexture', window(app.Nscreen), app.stimulus{app.sett(trial),app.group(trial)}{1,app.element(trial)});
                        Screen('DrawTexture', window(app.Nscreen), imageTexture, [], [], 0);
                        Screen('Flip', window(app.Nscreen));
                        if strcmp(app.ProceedSwitch.Value, 'Keyboard')
                            KbStrokeWait;
                            Screen('FillRect', window(app.Nscreen), white(app.Nscreen));
                            Screen('Flip', window(app.Nscreen));
                            Screen('Close',imageTexture);
                        else
                            WaitSecs(app.StimulusdurationsEditField.Value);
                            if app.InterstimulipausesEditField.Value ~= 0
                                Screen('FillRect', window(app.Nscreen), white(app.Nscreen));
                                Screen('Flip', window(app.Nscreen));
                                Screen('Close',imageTexture);
                                WaitSecs(app.InterstimulipausesEditField.Value);
                            end
                        end
                    case 'Discrimination'
                        disp(['Left: set ' num2str(app.settDiscr{app.sx(trial)}(trial)) ', group ' num2str(app.groupDiscr{app.sx(trial)}(trial))])
                        disp(['Right: set ' num2str(app.settDiscr{app.dx(trial)}(trial)) ', group ' num2str(app.groupDiscr{app.dx(trial)}(trial))])
                        imageTextureL = Screen('MakeTexture', window(app.Nscreen), app.stimulus{app.settDiscr{app.sx(trial)}(trial),app.groupDiscr{app.sx(trial)}(trial)}{1,app.elementDiscr{app.sx(trial)}(trial)});
                        imageTextureR = Screen('MakeTexture', window(app.Nscreen), app.stimulus{app.settDiscr{app.dx(trial)}(trial),app.groupDiscr{app.dx(trial)}(trial)}{1,app.elementDiscr{app.dx(trial)}(trial)});                      
                        Screen('DrawTexture', window(app.Nscreen), imageTextureL, [], [0, 0, screenXpixels(app.Nscreen)/2, screenYpixels(app.Nscreen)], 0);
                        Screen('DrawTexture', window(app.Nscreen), imageTextureR, [], [screenXpixels(app.Nscreen)/2, 0, screenXpixels(app.Nscreen), screenYpixels(app.Nscreen)], 0);
                        Screen('Flip', window(app.Nscreen));
                        if strcmp(app.ProceedSwitch.Value, 'Keyboard')
                            KbStrokeWait;
                            Screen('FillRect', window(app.Nscreen), white(app.Nscreen));
                            Screen('Flip', window(app.Nscreen));
                            Screen('Close',imageTextureL);
                            Screen('Close',imageTextureR);
                        else
                            WaitSecs(app.StimulusdurationsEditField.Value);
                            if app.InterstimulipausesEditField.Value ~= 0
                                Screen('FillRect', window(app.Nscreen), white(app.Nscreen));
                                Screen('Flip', window(app.Nscreen));
                                Screen('Close',imageTextureL);
                                Screen('Close',imageTextureR);
                                WaitSecs(app.InterstimulipausesEditField.Value);
                            end
                        end
                end
            end            
            sca;
              
        end

        % Button pushed function: CheckscreenButton
        function CheckscreenButtonPushed(app, event)
            clc;
            PsychDefaultSetup(2); 
            % Define black and white (white will be 1 and black 0). This is because
            % in general luminace values are defined between 0 and 1 with 255 steps in
            % between. All values in Psychtoolbox are defined between 0 and 1
            white(app.Nscreen) = WhiteIndex(app.Nscreen);    
            black(app.Nscreen) = BlackIndex(app.Nscreen);   
            % Open an on screen window using PsychImaging and color it black.
            [window(app.Nscreen), ~] = PsychImaging('OpenWindow', app.Nscreen, black(app.Nscreen));
            % Get the size of the on screen window in pixels, these are the last two numbers in "windowRect"
            [~, screenYpixels(app.Nscreen)] = Screen('WindowSize', window(app.Nscreen));
            % Get the centre coordinate of the window in pixels.
            %[xCenter(app.Nscreen), yCenter(app.Nscreen)] = RectCenter(windowRect(app.Nscreen,:,:,:,:));
            % Query the inter-frame-interval. This refers to the minimum possible time between drawing to the screen
            %refreshTime(app.Nscreen) = Screen('GetFlipInterval', window(app.Nscreen));
            % Refresh rate of our screen. The relationship between the two is:
            % refreshTime = 1 / refreshRate
            %refreshRate(app.Nscreen) = FrameRate(window(app.Nscreen));
            % Queries the display size in mm as reported by the operating system. Note that there are some complexities here. See Screen DisplaySize? for
            % information. So always measure your screen size directly.
            %[width_mm_screen(app.Nscreen), height_mm_screen(app.Nscreen)] = Screen('DisplaySize', app.Nscreen);   
            % Set up alpha-blending for smooth (anti-aliased) lines
            Screen('BlendFunction', window(app.Nscreen), 'GL_SRC_ALPHA', 'GL_ONE_MINUS_SRC_ALPHA');
            % Retreive the maximum priority number and set max priority. This means PTB will take processing priority over other system and applicaiton processes.
            topPriorityLevel = MaxPriority(window(app.Nscreen));
            Priority(topPriorityLevel);
            
            % Draw text in the upper portion of the screen with the default font in red
            Screen('TextSize', window(app.Nscreen), 120);
            DrawFormattedText(window(app.Nscreen), ['Screen #' num2str(app.Nscreen)], 'center',screenYpixels(app.Nscreen) * 0.25, [1 0 0]);
            Screen('Flip', window(app.Nscreen));
            disp(['Checked screen ' num2str(app.Nscreen)])

            KbStrokeWait;
            sca;
        end

        % Value changed function: ScreenEditField
        function ScreenEditFieldValueChanged(app, event)
            app.Nscreen = app.ScreenEditField.Value;
            
        end

        % Value changed function: TA_CHButton_3
        function TA_CHButton_3ValueChanged2(app, event)
            value = app.TA_CHButton_3.Value;
            if value==1
                [file, data]=loadData(app);
                app.stimulus{3,2}=data.imgMatrix;
                app.TA_CH_fileLabel_3.Text=['tot# ' num2str(size(app.stimulus{3,2},2)) ' - (file:' file ')'];
                app.TA_CHButton_3.BackgroundColor=[0 1 0];
            else
                app.TA_CHButton_3.BackgroundColor=[1 0 0];
                app.stimulus{3,2}=[];
            end
        end

        % Value changed function: TP_IDButton_3
        function TP_IDButton_3ValueChanged2(app, event)
            value = app.TP_IDButton_3.Value;
            if value==1
                [file, data]=loadData(app);
                app.stimulus{3,3}=data.imgMatrix;
                app.TP_ID_fileLabel_3.Text=['tot# ' num2str(size(app.stimulus{3,3},2)) ' - (file:' file ')'];
                app.TP_IDButton_3.BackgroundColor=[0 1 0];
            else
                app.TP_IDButton_3.BackgroundColor=[1 0 0];
                app.stimulus{3,3}=[];
            end
        end

        % Value changed function: TP_CHButton_3
        function TP_CHButton_3ValueChanged2(app, event)
            value = app.TP_CHButton_3.Value;
            if value==1
                [file, data]=loadData(app);
                app.stimulus{3,4}=data.imgMatrix;
                app.TP_CH_fileLabel_3.Text=['tot# ' num2str(size(app.stimulus{3,4},2)) ' - (file:' file ')'];
                app.TP_CHButton_3.BackgroundColor=[0 1 0];
            else
                app.TP_CHButton_3.BackgroundColor=[1 0 0];
                app.stimulus{3,4}=[];
            end
        end

        % Value changed function: NC_IDButton_3
        function NC_IDButton_3ValueChanged2(app, event)
            value = app.NC_IDButton_3.Value;
            if value==1
                [file, data]=loadData(app);
                app.stimulus{3,5}=data.imgMatrix;
                app.NC_ID_fileLabel_3.Text=['tot# ' num2str(size(app.stimulus{3,5},2)) ' - (file:' file ')'];
                app.NC_IDButton_3.BackgroundColor=[0 1 0];
            else
                app.NC_IDButton_3.BackgroundColor=[1 0 0];
                app.stimulus{3,5}=[];
            end
        end

        % Value changed function: NC_CHButton_3
        function NC_CHButton_3ValueChanged2(app, event)
            value = app.NC_CHButton_3.Value;
            if value==1
                [file, data]=loadData(app);
                app.stimulus{3,6}=data.imgMatrix;
                app.NC_CH_fileLabel_3.Text=['tot# ' num2str(size(app.stimulus{3,6},2)) ' - (file:' file ')'];
                app.NC_CHButton_3.BackgroundColor=[0 1 0];
            else
                app.NC_CHButton_3.BackgroundColor=[1 0 0];
                app.stimulus{3,6}=[];
            end
        end

        % Value changed function: CustomButton_3
        function CustomButton_3ValueChanged2(app, event)
            value = app.CustomButton_3.Value;
            if value==1
                [file, data]=loadData(app);
                app.stimulus{3,7}=data.imgMatrix;
                app.Custom_fileLabel_3.Text=['tot# ' num2str(size(app.stimulus{3,7},2)) ' - (file:' file ')'];
                app.CustomButton_3.BackgroundColor=[0 1 0];
            else
                app.CustomButton_3.BackgroundColor=[1 0 0];
                app.stimulus{3,7}=[];
            end
        end

        % Value changed function: TA_IDButton_4
        function TA_IDButton_4ValueChanged2(app, event)
            value = app.TA_IDButton_4.Value;
            if value==1
                [file, data]=loadData(app);
                app.stimulus{4,1}=data.imgMatrix;
                app.TA_ID_fileLabel_4.Text=['tot# ' num2str(size(app.stimulus{4,1},2)) ' - (file:' file ')'];
                app.TA_IDButton_4.BackgroundColor=[0 1 0];
            else
                app.TA_IDButton_4.BackgroundColor=[1 0 0];
                app.stimulus{4,1}=[];
            end
        end

        % Value changed function: TA_CHButton_4
        function TA_CHButton_4ValueChanged2(app, event)
            value = app.TA_CHButton_4.Value;
            if value==1
                [file, data]=loadData(app);
                app.stimulus{4,2}=data.imgMatrix;
                app.TA_CH_fileLabel_4.Text=['tot# ' num2str(size(app.stimulus{4,2},2)) ' - (file:' file ')'];
                app.TA_CHButton_4.BackgroundColor=[0 1 0];
            else
                app.TA_CHButton_4.BackgroundColor=[1 0 0];
                app.stimulus{4,2}=[];
            end
        end

        % Value changed function: TP_IDButton_4
        function TP_IDButton_4ValueChanged2(app, event)
            value = app.TP_IDButton_4.Value;
            if value==1
                [file, data]=loadData(app);
                app.stimulus{4,3}=data.imgMatrix;
                app.TP_ID_fileLabel_4.Text=['tot# ' num2str(size(app.stimulus{4,3},2)) ' - (file:' file ')'];
                app.TP_IDButton_4.BackgroundColor=[0 1 0];
            else
                app.TP_IDButton_4.BackgroundColor=[1 0 0];
                app.stimulus{4,3}=[];
            end
        end

        % Value changed function: TP_CHButton_4
        function TP_CHButton_4ValueChanged2(app, event)
            value = app.TP_CHButton_4.Value;
            if value==1
                [file, data]=loadData(app);
                app.stimulus{4,4}=data.imgMatrix;
                app.TP_CH_fileLabel_4.Text=['tot# ' num2str(size(app.stimulus{4,4},2)) ' - (file:' file ')'];
                app.TP_CHButton_4.BackgroundColor=[0 1 0];
            else
                app.TP_CHButton_4.BackgroundColor=[1 0 0];
                app.stimulus{4,4}=[];
            end
        end

        % Value changed function: NC_IDButton_4
        function NC_IDButton_4ValueChanged2(app, event)
            value = app.NC_IDButton_4.Value;
            if value==1
                [file, data]=loadData(app);
                app.stimulus{4,5}=data.imgMatrix;
                app.NC_ID_fileLabel_4.Text=['tot# ' num2str(size(app.stimulus{4,5},2)) ' - (file:' file ')'];
                app.NC_IDButton_4.BackgroundColor=[0 1 0];
            else
                app.NC_IDButton_4.BackgroundColor=[1 0 0];
                app.stimulus{4,5}=[];
            end
        end

        % Value changed function: NC_CHButton_4
        function NC_CHButton_4ValueChanged2(app, event)
            value = app.NC_CHButton_4.Value;
            if value==1
                [file, data]=loadData(app);
                app.stimulus{4,6}=data.imgMatrix;
                app.NC_CH_fileLabel_4.Text=['tot# ' num2str(size(app.stimulus{4,6},2)) ' - (file:' file ')'];
                app.NC_CHButton_4.BackgroundColor=[0 1 0];
            else
                app.NC_CHButton_4.BackgroundColor=[1 0 0];
                app.stimulus{4,6}=[];
            end
        end

        % Value changed function: CustomButton_4
        function CustomButton_4ValueChanged2(app, event)
            value = app.CustomButton_4.Value;
            if value==1
                [file, data]=loadData(app);
                app.stimulus{4,7}=data.imgMatrix;
                app.Custom_fileLabel_4.Text=['tot# ' num2str(size(app.stimulus{4,7},2)) ' - (file:' file ')'];
                app.CustomButton_4.BackgroundColor=[0 1 0];
            else
                app.CustomButton_4.BackgroundColor=[1 0 0];
                app.stimulus{4,7}=[];
            end
        end

        % Value changed function: ProceedSwitch
        function ProceedSwitchValueChanged(app, event)
            value = app.ProceedSwitch.Value;
            if strcmp(value, 'Keyboard')
                app.StimulusdurationsEditField.Enable = 'off';
                app.StimulusdurationsEditFieldLabel.Enable = 'off';
                app.InterstimulipausesEditField.Enable = 'off';
                app.InterstimulipausesEditFieldLabel.Enable = 'off';
            else
                app.StimulusdurationsEditField.Enable = 'on';
                app.StimulusdurationsEditFieldLabel.Enable = 'on';
                app.InterstimulipausesEditField.Enable = 'on';
                app.InterstimulipausesEditFieldLabel.Enable = 'on';
            end
        end

        % Value changed function: TA_IDButton_3
        function TA_IDButton_3ValueChanged2(app, event)
            value = app.TA_IDButton_3.Value;
            if value==1
                [file, data]=loadData(app);
                app.stimulus{3,1}=data.imgMatrix;
                app.TA_ID_fileLabel_3.Text=['tot# ' num2str(size(app.stimulus{3,1},2)) ' - (file:' file ')'];
                app.TA_IDButton_3.BackgroundColor=[0 1 0];
            else
                app.TA_IDButton_3.BackgroundColor=[1 0 0];
                app.stimulus{3,1}=[];
            end
        end

        % Value changed function: SaveinfostoEditField
        function SaveinfostoEditFieldValueChanged(app, event)
            value = app.SaveinfostoEditField.Value;
            if isempty(value)
                app.TypeDropDown.Enable = 'off';
            else
                app.TypeDropDown.Enable = 'on';
            end
        end

        % Value changed function: TypeDropDown
        function TypeDropDownValueChanged(app, event)
            value_type = app.TypeDropDown.Value;
            shuffle(app,1);
            shuffle(app,2);
            shuffle(app,3);
            shuffle(app,4);
            switch value_type
                case 'Habituation'
                    createHabPresentation(app)
                    app.StartpresentationButton.Enable = 'on';
                case 'Discrimination'
                    createDiscrPresentation(app)
                    app.StartpresentationButton.Enable = 'on';
                case '--'
                    app.StartpresentationButton.Enable = 'off';
            end
            if strcmp(value_type, '--')==0 && ~isempty(app.SaveinfostoEditField.Value)
                app.StartpresentationButton.Enable='on';
            else
                app.StartpresentationButton.Enable='off';
            end
            saveSequence(app);
        end

        % Button pushed function: iButton
        function iButtonPushed(app, event)
            winopen('./Tutorial/GeNEsIS_display_tutorial.pdf')
        end

        % Value changed function: HabituationsetDropDown
        function HabituationsetDropDownValueChanged(app, event)
            app.StartpresentationButton.Enable = 'off';
            app.TypeDropDown.Value = '--';
        end

        % Value changed function: DishabituationsetDropDown
        function DishabituationsetDropDownValueChanged(app, event)
            app.StartpresentationButton.Enable = 'off';
            app.TypeDropDown.Value = '--';
        end

        % Value changed function: habituationstimuliEditField
        function habituationstimuliEditFieldValueChanged(app, event)
            app.TypeDropDown.Value = '--';
            app.StartpresentationButton.Enable = 'off';
        end

        % Value changed function: dishabituationstimuliEditField
        function dishabituationstimuliEditFieldValueChanged(app, event)
            app.TypeDropDown.Value = '--';
            if app.dishabituationstimuliEditField.Value == 0
                app.DishabituationsetDropDown.Enable = 'off';
                app.DishabituationsetDropDownLabel.Enable = 'off';
            else
                app.DishabituationsetDropDown.Enable = 'on';
                app.DishabituationsetDropDownLabel.Enable = 'on';
            end
            app.StartpresentationButton.Enable = 'off';
        end

        % Value changed function: cyclesEditField
        function cyclesEditFieldValueChanged(app, event)
            app.TypeDropDown.Value = '--';
            
        end

        % Value changed function: CoupletrainingDropDown
        function CoupletrainingDropDownValueChanged(app, event)
            app.TypeDropDown.Value = '--';
            app.StartpresentationButton.Enable = 'off';
        end

        % Value changed function: CoupletestDropDown
        function CoupletestDropDownValueChanged(app, event)
            app.TypeDropDown.Value = '--';
            app.StartpresentationButton.Enable = 'off';
        end

        % Value changed function: trainingtrialsEditField
        function trainingtrialsEditFieldValueChanged(app, event)
            app.TypeDropDown.Value = '--';
            app.StartpresentationButton.Enable = 'off';
        end

        % Value changed function: testtrialsEditField
        function testtrialsEditFieldValueChanged(app, event)
            app.TypeDropDown.Value = '--';
            if app.testtrialsEditField.Value == 0
                app.CoupletestDropDown.Enable = 'off';
                app.CoupletestDropDownLabel.Enable = 'off';
            else
                app.CoupletestDropDown.Enable = 'on';
                app.CoupletestDropDownLabel.Enable = 'on';
            end
            app.StartpresentationButton.Enable = 'off';
        end

        % Value changed function: cyclesEditField_2
        function cyclesEditField_2ValueChanged(app, event)
            app.TypeDropDown.Value = '--';
            app.StartpresentationButton.Enable = 'off';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Set1Tab = uitab(app.TabGroup);
            app.Set1Tab.Title = 'Set 1';
            app.TA_IDButton = uibutton(app.Set1Tab, 'state');
            app.TA_IDButton.ValueChangedFcn = createCallbackFcn(app, @TA_IDButtonValueChanged, true);
            app.TA_IDButton.Text = 'TA_ID';
            app.TA_CHButton = uibutton(app.Set1Tab, 'state');
            app.TA_CHButton.ValueChangedFcn = createCallbackFcn(app, @TA_CHButtonValueChanged, true);
            app.TA_CHButton.Text = 'TA_CH';
            app.TP_IDButton = uibutton(app.Set1Tab, 'state');
            app.TP_IDButton.ValueChangedFcn = createCallbackFcn(app, @TP_IDButtonValueChanged, true);
            app.TP_IDButton.Text = 'TP_ID';
            app.TP_CHButton = uibutton(app.Set1Tab, 'state');
            app.TP_CHButton.ValueChangedFcn = createCallbackFcn(app, @TP_CHButtonValueChanged, true);
            app.TP_CHButton.Text = 'TP_CH';
            app.NC_IDButton = uibutton(app.Set1Tab, 'state');
            app.NC_IDButton.ValueChangedFcn = createCallbackFcn(app, @NC_IDButtonValueChanged, true);
            app.NC_IDButton.Text = 'NC_ID';
            app.NC_CHButton = uibutton(app.Set1Tab, 'state');
            app.NC_CHButton.ValueChangedFcn = createCallbackFcn(app, @NC_CHButtonValueChanged, true);
            app.NC_CHButton.Text = 'NC_CH';
            app.CustomButton = uibutton(app.Set1Tab, 'state');
            app.CustomButton.ValueChangedFcn = createCallbackFcn(app, @CustomButtonValueChanged, true);
            app.CustomButton.Text = 'Custom';
            app.TA_ID_fileLabel = uilabel(app.Set1Tab);
            app.TA_ID_fileLabel.Text = 'TA_ID_file';
            app.TA_CH_fileLabel = uilabel(app.Set1Tab);
            app.TA_CH_fileLabel.Text = 'TA_CH_file';
            app.TP_ID_fileLabel = uilabel(app.Set1Tab);
            app.TP_ID_fileLabel.Text = 'TP_ID_file';
            app.TP_CH_fileLabel = uilabel(app.Set1Tab);
            app.TP_CH_fileLabel.Text = 'TP_CH_file';
            app.NC_ID_fileLabel = uilabel(app.Set1Tab);
            app.NC_ID_fileLabel.Text = 'NC_ID_file';
            app.NC_CH_fileLabel = uilabel(app.Set1Tab);
            app.NC_CH_fileLabel.Text = 'NC_CH_file';
            app.Custom_fileLabel = uilabel(app.Set1Tab);
            app.Custom_fileLabel.Text = 'Custom_file';
            app.Label_1 = uilabel(app.Set1Tab);
            app.Label_1.Text = '1';
            app.Label_2 = uilabel(app.Set1Tab);
            app.Label_2.Text = '2';
            app.Label_3 = uilabel(app.Set1Tab);
            app.Label_3.Text = '3';
            app.Label_4 = uilabel(app.Set1Tab);
            app.Label_4.Text = '4';
            app.Label_5 = uilabel(app.Set1Tab);
            app.Label_5.Text = '5';
            app.Label_6 = uilabel(app.Set1Tab);
            app.Label_6.Text = '6';
            app.Label_7 = uilabel(app.Set1Tab);
            app.Label_7.Text = '7';
            app.GroupLabel = uilabel(app.Set1Tab);
            app.GroupLabel.Text = 'Group:';
            app.Set2Tab = uitab(app.TabGroup);
            app.Set2Tab.Title = 'Set 2';
            app.TA_IDButton_2 = uibutton(app.Set2Tab, 'state');
            app.TA_IDButton_2.ValueChangedFcn = createCallbackFcn(app, @TA_IDButton_2ValueChanged, true);
            app.TA_IDButton_2.Text = 'TA_ID';
            app.TA_CHButton_2 = uibutton(app.Set2Tab, 'state');
            app.TA_CHButton_2.ValueChangedFcn = createCallbackFcn(app, @TA_CHButton_2ValueChanged, true);
            app.TA_CHButton_2.Text = 'TA_CH';
            app.TP_IDButton_2 = uibutton(app.Set2Tab, 'state');
            app.TP_IDButton_2.ValueChangedFcn = createCallbackFcn(app, @TP_IDButton_2ValueChanged, true);
            app.TP_IDButton_2.Text = 'TP_ID';
            app.TP_CHButton_2 = uibutton(app.Set2Tab, 'state');
            app.TP_CHButton_2.ValueChangedFcn = createCallbackFcn(app, @TP_CHButton_2ValueChanged, true);
            app.TP_CHButton_2.Text = 'TP_CH';
            app.NC_IDButton_2 = uibutton(app.Set2Tab, 'state');
            app.NC_IDButton_2.ValueChangedFcn = createCallbackFcn(app, @NC_IDButton_2ValueChanged, true);
            app.NC_IDButton_2.Text = 'NC_ID';
            app.NC_CHButton_2 = uibutton(app.Set2Tab, 'state');
            app.NC_CHButton_2.ValueChangedFcn = createCallbackFcn(app, @NC_CHButton_2ValueChanged, true);
            app.NC_CHButton_2.Text = 'NC_CH';
            app.CustomButton_2 = uibutton(app.Set2Tab, 'state');
            app.CustomButton_2.ValueChangedFcn = createCallbackFcn(app, @CustomButton_2ValueChanged, true);
            app.CustomButton_2.Text = 'Custom';
            app.TA_ID_fileLabel_2 = uilabel(app.Set2Tab);
            app.TA_ID_fileLabel_2.Text = 'TA_ID_file';
            app.TA_CH_fileLabel_2 = uilabel(app.Set2Tab);
            app.TA_CH_fileLabel_2.Text = 'TA_CH_file';
            app.TP_ID_fileLabel_2 = uilabel(app.Set2Tab);
            app.TP_ID_fileLabel_2.Text = 'TP_ID_file';
            app.TP_CH_fileLabel_2 = uilabel(app.Set2Tab);
            app.TP_CH_fileLabel_2.Text = 'TP_CH_file';
            app.NC_ID_fileLabel_2 = uilabel(app.Set2Tab);
            app.NC_ID_fileLabel_2.Text = 'NC_ID_file';
            app.NC_CH_fileLabel_2 = uilabel(app.Set2Tab);
            app.NC_CH_fileLabel_2.Text = 'NC_CH_file';
            app.Custom_fileLabel_2 = uilabel(app.Set2Tab);
            app.Custom_fileLabel_2.Text = 'Custom_file';
            app.Label_8 = uilabel(app.Set2Tab);
            app.Label_8.Text = '8';
            app.Label_9 = uilabel(app.Set2Tab);
            app.Label_9.Text = '9';
            app.Label_10 = uilabel(app.Set2Tab);
            app.Label_10.Text = '10';
            app.Label_11 = uilabel(app.Set2Tab);
            app.Label_11.Text = '11';
            app.Label_12 = uilabel(app.Set2Tab);
            app.Label_12.Text = '12';
            app.Label_13 = uilabel(app.Set2Tab);
            app.Label_13.Text = '13';
            app.Label_14 = uilabel(app.Set2Tab);
            app.Label_14.Text = '14';
            app.GroupLabel_2 = uilabel(app.Set2Tab);
            app.GroupLabel_2.Text = 'Group:';
            app.Set3Tab = uitab(app.TabGroup);
            app.Set3Tab.Title = 'Set 3';
            app.TA_IDButton_3 = uibutton(app.Set3Tab, 'state');
            app.TA_IDButton_3.ValueChangedFcn = createCallbackFcn(app, @TA_IDButton_3ValueChanged2, true);
            app.TA_IDButton_3.Text = 'TA_ID';
            app.TA_CHButton_3 = uibutton(app.Set3Tab, 'state');
            app.TA_CHButton_3.ValueChangedFcn = createCallbackFcn(app, @TA_CHButton_3ValueChanged2, true);
            app.TA_CHButton_3.Text = 'TA_CH';
            app.TP_IDButton_3 = uibutton(app.Set3Tab, 'state');
            app.TP_IDButton_3.ValueChangedFcn = createCallbackFcn(app, @TP_IDButton_3ValueChanged2, true);
            app.TP_IDButton_3.Text = 'TP_ID';
            app.TP_CHButton_3 = uibutton(app.Set3Tab, 'state');
            app.TP_CHButton_3.ValueChangedFcn = createCallbackFcn(app, @TP_CHButton_3ValueChanged2, true);
            app.TP_CHButton_3.Text = 'TP_CH';
            app.NC_IDButton_3 = uibutton(app.Set3Tab, 'state');
            app.NC_IDButton_3.ValueChangedFcn = createCallbackFcn(app, @NC_IDButton_3ValueChanged2, true);
            app.NC_IDButton_3.Text = 'NC_ID';
            app.NC_CHButton_3 = uibutton(app.Set3Tab, 'state');
            app.NC_CHButton_3.ValueChangedFcn = createCallbackFcn(app, @NC_CHButton_3ValueChanged2, true);
            app.NC_CHButton_3.Text = 'NC_CH';
            app.CustomButton_3 = uibutton(app.Set3Tab, 'state');
            app.CustomButton_3.ValueChangedFcn = createCallbackFcn(app, @CustomButton_3ValueChanged2, true);
            app.CustomButton_3.Text = 'Custom';
            app.TA_ID_fileLabel_3 = uilabel(app.Set3Tab);
            app.TA_ID_fileLabel_3.Text = 'TA_ID_file';
            app.TA_CH_fileLabel_3 = uilabel(app.Set3Tab);
            app.TA_CH_fileLabel_3.Text = 'TA_CH_file';
            app.TP_ID_fileLabel_3 = uilabel(app.Set3Tab);
            app.TP_ID_fileLabel_3.Text = 'TP_ID_file';
            app.TP_CH_fileLabel_3 = uilabel(app.Set3Tab);
            app.TP_CH_fileLabel_3.Text = 'TP_CH_file';
            app.NC_ID_fileLabel_3 = uilabel(app.Set3Tab);
            app.NC_ID_fileLabel_3.Text = 'NC_ID_file';
            app.NC_CH_fileLabel_3 = uilabel(app.Set3Tab);
            app.NC_CH_fileLabel_3.Text = 'NC_CH_file';
            app.Custom_fileLabel_3 = uilabel(app.Set3Tab);
            app.Custom_fileLabel_3.Text = 'Custom_file';
            app.Label_15 = uilabel(app.Set3Tab);
            app.Label_15.Text = '15';
            app.Label_16 = uilabel(app.Set3Tab);
            app.Label_16.Text = '16';
            app.Label_17 = uilabel(app.Set3Tab);
            app.Label_17.Text = '17';
            app.Label_18 = uilabel(app.Set3Tab);
            app.Label_18.Text = '18';
            app.Label_19 = uilabel(app.Set3Tab);
            app.Label_19.Text = '19';
            app.Label_20 = uilabel(app.Set3Tab);
            app.Label_20.Text = '20';
            app.Label_21 = uilabel(app.Set3Tab);
            app.Label_21.Text = '21';
            app.GroupLabel_3 = uilabel(app.Set3Tab);
            app.GroupLabel_3.Text = 'Group:';
            app.Set4Tab = uitab(app.TabGroup);
            app.Set4Tab.Title = 'Set 4';
            app.TA_IDButton_4 = uibutton(app.Set4Tab, 'state');
            app.TA_IDButton_4.ValueChangedFcn = createCallbackFcn(app, @TA_IDButton_4ValueChanged2, true);
            app.TA_IDButton_4.Text = 'TA_ID';
            app.TA_CHButton_4 = uibutton(app.Set4Tab, 'state');
            app.TA_CHButton_4.ValueChangedFcn = createCallbackFcn(app, @TA_CHButton_4ValueChanged2, true);
            app.TA_CHButton_4.Text = 'TA_CH';
            app.TP_IDButton_4 = uibutton(app.Set4Tab, 'state');
            app.TP_IDButton_4.ValueChangedFcn = createCallbackFcn(app, @TP_IDButton_4ValueChanged2, true);
            app.TP_IDButton_4.Text = 'TP_ID';
            app.TP_CHButton_4 = uibutton(app.Set4Tab, 'state');
            app.TP_CHButton_4.ValueChangedFcn = createCallbackFcn(app, @TP_CHButton_4ValueChanged2, true);
            app.TP_CHButton_4.Text = 'TP_CH';
            app.NC_IDButton_4 = uibutton(app.Set4Tab, 'state');
            app.NC_IDButton_4.ValueChangedFcn = createCallbackFcn(app, @NC_IDButton_4ValueChanged2, true);
            app.NC_IDButton_4.Text = 'NC_ID';
            app.NC_CHButton_4 = uibutton(app.Set4Tab, 'state');
            app.NC_CHButton_4.ValueChangedFcn = createCallbackFcn(app, @NC_CHButton_4ValueChanged2, true);
            app.NC_CHButton_4.Text = 'NC_CH';
            app.CustomButton_4 = uibutton(app.Set4Tab, 'state');
            app.CustomButton_4.ValueChangedFcn = createCallbackFcn(app, @CustomButton_4ValueChanged2, true);
            app.CustomButton_4.Text = 'Custom';
            app.TA_ID_fileLabel_4 = uilabel(app.Set4Tab);
            app.TA_ID_fileLabel_4.Text = 'TA_ID_file';
            app.TA_CH_fileLabel_4 = uilabel(app.Set4Tab);
            app.TA_CH_fileLabel_4.Text = 'TA_CH_file';
            app.TP_ID_fileLabel_4 = uilabel(app.Set4Tab);
            app.TP_ID_fileLabel_4.Text = 'TP_ID_file';
            app.TP_CH_fileLabel_4 = uilabel(app.Set4Tab);
            app.TP_CH_fileLabel_4.Text = 'TP_CH_file';
            app.NC_ID_fileLabel_4 = uilabel(app.Set4Tab);
            app.NC_ID_fileLabel_4.Text = 'NC_ID_file';
            app.NC_CH_fileLabel_4 = uilabel(app.Set4Tab);
            app.NC_CH_fileLabel_4.Text = 'NC_CH_file';
            app.Custom_fileLabel_4 = uilabel(app.Set4Tab);
            app.Custom_fileLabel_4.Text = 'Custom_file';
            app.Label_22 = uilabel(app.Set4Tab);
            app.Label_22.Text = '22';
            app.Label_23 = uilabel(app.Set4Tab);
            app.Label_23.Text = '23';
            app.Label_24 = uilabel(app.Set4Tab);
            app.Label_24.Text = '24';
            app.Label_25 = uilabel(app.Set4Tab);
            app.Label_25.Text = '25';
            app.Label_26 = uilabel(app.Set4Tab);
            app.Label_26.Text = '26';
            app.Label_27 = uilabel(app.Set4Tab);
            app.Label_27.Text = '27';
            app.Label_28 = uilabel(app.Set4Tab);
            app.Label_28.Text = '28';
            app.GroupLabel_4 = uilabel(app.Set4Tab);
            app.GroupLabel_4.Text = 'Group:';
            app.GeNEsISdisplayLabel = uilabel(app.UIFigure);
            app.GeNEsISdisplayLabel.Text = 'GeNEsIS - display';
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.HabituationTab = uitab(app.TabGroup2);
            app.HabituationTab.Title = 'Habituation';
            app.habituationstimuliEditFieldLabel = uilabel(app.HabituationTab);
            app.habituationstimuliEditFieldLabel.Text = '# habituation stimuli';
            app.habituationstimuliEditField = uieditfield(app.HabituationTab, 'numeric');
            app.habituationstimuliEditField.ValueChangedFcn = createCallbackFcn(app, @habituationstimuliEditFieldValueChanged, true);
            app.habituationstimuliEditField.Value = 10;
            app.dishabituationstimuliEditFieldLabel = uilabel(app.HabituationTab);
            app.dishabituationstimuliEditFieldLabel.Text = '# dishabituation stimuli';
            app.dishabituationstimuliEditField = uieditfield(app.HabituationTab, 'numeric');
            app.dishabituationstimuliEditField.ValueChangedFcn = createCallbackFcn(app, @dishabituationstimuliEditFieldValueChanged, true);
            app.HabituationsetDropDownLabel = uilabel(app.HabituationTab);
            app.HabituationsetDropDownLabel.Text = 'Habituation set';
            app.HabituationsetDropDown = uidropdown(app.HabituationTab);
            app.HabituationsetDropDown.Items = {'Set 1', 'Set 2', 'Set 3', 'Set 4'};
            app.HabituationsetDropDown.ValueChangedFcn = createCallbackFcn(app, @HabituationsetDropDownValueChanged, true);
            app.HabituationsetDropDown.Value = 'Set 1';
            app.DishabituationsetDropDownLabel = uilabel(app.HabituationTab);
            app.DishabituationsetDropDownLabel.Enable = 'off';
            app.DishabituationsetDropDownLabel.Text = 'Dishabituation set';
            app.DishabituationsetDropDown = uidropdown(app.HabituationTab);
            app.DishabituationsetDropDown.Items = {'Set 1', 'Set 2', 'Set 3', 'Set 4'};
            app.DishabituationsetDropDown.ValueChangedFcn = createCallbackFcn(app, @DishabituationsetDropDownValueChanged, true);
            app.DishabituationsetDropDown.Enable = 'off';
            app.DishabituationsetDropDown.Value = 'Set 1';
            app.cyclesEditFieldLabel = uilabel(app.HabituationTab);
            app.cyclesEditFieldLabel.Text = '# cycles';
            app.cyclesEditField = uieditfield(app.HabituationTab, 'numeric');
            app.cyclesEditField.ValueChangedFcn = createCallbackFcn(app, @cyclesEditFieldValueChanged, true);
            app.cyclesEditField.Value = 1;
            app.SimultaneousdiscriminationTab = uitab(app.TabGroup2);
            app.SimultaneousdiscriminationTab.Title = 'Simultaneous discrimination';
            app.CoupletrainingDropDownLabel = uilabel(app.SimultaneousdiscriminationTab);
            app.CoupletrainingDropDownLabel.Text = 'Couple training';
            app.CoupletrainingDropDown = uidropdown(app.SimultaneousdiscriminationTab);
            app.CoupletrainingDropDown.Items = {'Set 1+2', 'Set 1+3', 'Set 1+4', 'Set 2+3', 'Set 2+4', 'Set 3+4'};
            app.CoupletrainingDropDown.ValueChangedFcn = createCallbackFcn(app, @CoupletrainingDropDownValueChanged, true);
            app.CoupletrainingDropDown.Value = 'Set 1+2';
            app.CoupletestDropDownLabel = uilabel(app.SimultaneousdiscriminationTab);
            app.CoupletestDropDownLabel.Enable = 'off';
            app.CoupletestDropDownLabel.Text = 'Couple test';
            app.CoupletestDropDown = uidropdown(app.SimultaneousdiscriminationTab);
            app.CoupletestDropDown.Items = {'Set 1+2', 'Set 1+3', 'Set 1+4', 'Set 2+3', 'Set 2+4', 'Set 3+4'};
            app.CoupletestDropDown.ValueChangedFcn = createCallbackFcn(app, @CoupletestDropDownValueChanged, true);
            app.CoupletestDropDown.Enable = 'off';
            app.CoupletestDropDown.Value = 'Set 1+2';
            app.trainingtrialsEditFieldLabel = uilabel(app.SimultaneousdiscriminationTab);
            app.trainingtrialsEditFieldLabel.Text = '# training trials';
            app.trainingtrialsEditField = uieditfield(app.SimultaneousdiscriminationTab, 'numeric');
            app.trainingtrialsEditField.ValueChangedFcn = createCallbackFcn(app, @trainingtrialsEditFieldValueChanged, true);
            app.trainingtrialsEditField.Value = 10;
            app.testtrialsEditFieldLabel = uilabel(app.SimultaneousdiscriminationTab);
            app.testtrialsEditFieldLabel.Text = '# test trials';
            app.testtrialsEditField = uieditfield(app.SimultaneousdiscriminationTab, 'numeric');
            app.testtrialsEditField.ValueChangedFcn = createCallbackFcn(app, @testtrialsEditFieldValueChanged, true);
            app.cyclesEditField_3Label = uilabel(app.SimultaneousdiscriminationTab);
            app.cyclesEditField_3Label.Text = '# cycles';
            app.cyclesEditField_2 = uieditfield(app.SimultaneousdiscriminationTab, 'numeric');
            app.cyclesEditField_2.ValueChangedFcn = createCallbackFcn(app, @cyclesEditField_2ValueChanged, true);
            app.cyclesEditField_2.Value = 1;
            app.PresentationPanel = uipanel(app.UIFigure);
            app.PresentationPanel.Title = 'Presentation:';
            app.StimulusdurationsEditFieldLabel = uilabel(app.PresentationPanel);
            app.StimulusdurationsEditFieldLabel.Text = 'Stimulus duration [s]';
            app.StimulusdurationsEditField = uieditfield(app.PresentationPanel, 'numeric');
            app.StimulusdurationsEditField.Value = 2;
            app.InterstimulipausesEditFieldLabel = uilabel(app.PresentationPanel);
            app.InterstimulipausesEditFieldLabel.Text = 'Inter-stimuli pause [s]';
            app.InterstimulipausesEditField = uieditfield(app.PresentationPanel, 'numeric');
            app.InterstimulipausesEditField.Value = 2;
            app.ProceedSwitchLabel = uilabel(app.PresentationPanel);
            app.ProceedSwitchLabel.Text = 'Proceed:';
            app.ProceedSwitch = uiswitch(app.PresentationPanel, 'slider');
            app.ProceedSwitch.Items = {'Auto', 'Keyboard'};
            app.ProceedSwitch.ValueChangedFcn = createCallbackFcn(app, @ProceedSwitchValueChanged, true);
            app.ProceedSwitch.Value = 'Auto';
            app.StartpresentationButton = uibutton(app.PresentationPanel, 'push');
            app.StartpresentationButton.ButtonPushedFcn = createCallbackFcn(app, @StartpresentationButtonPushed, true);
            app.StartpresentationButton.Enable = 'off';
            app.StartpresentationButton.Text = 'Start presentation';
            app.SaveasLabel = uilabel(app.PresentationPanel);
            app.SaveasLabel.Text = 'Save as:';
            app.SaveinfostoEditField = uieditfield(app.PresentationPanel, 'text');
            app.SaveinfostoEditField.ValueChangedFcn = createCallbackFcn(app, @SaveinfostoEditFieldValueChanged, true);
            app.MonitorsizeLabel = uilabel(app.PresentationPanel);
            app.MonitorsizeLabel.Text = 'Monitor size:';
            app.ScreenEditFieldLabel = uilabel(app.PresentationPanel);
            app.ScreenEditFieldLabel.Text = 'Screen #';
            app.ScreenEditField = uieditfield(app.PresentationPanel, 'numeric');
            app.ScreenEditField.ValueChangedFcn = createCallbackFcn(app, @ScreenEditFieldValueChanged, true);
            app.ScreenEditField.Value = 1;
            app.CheckscreenButton = uibutton(app.PresentationPanel, 'push');
            app.CheckscreenButton.ButtonPushedFcn = createCallbackFcn(app, @CheckscreenButtonPushed, true);
            app.CheckscreenButton.Text = 'Check screen';
            app.TypeDropDownLabel = uilabel(app.PresentationPanel);
            app.TypeDropDownLabel.Enable = 'off';
            app.TypeDropDownLabel.Text = 'Type:';
            app.TypeDropDown = uidropdown(app.PresentationPanel);
            app.TypeDropDown.Items = {'--', 'Habituation', 'Discrimination'};
            app.TypeDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeDropDownValueChanged, true);
            app.TypeDropDown.Enable = 'off';
            app.TypeDropDown.Value = '--';
            app.iButton = uibutton(app.UIFigure, 'push');
            app.iButton.ButtonPushedFcn = createCallbackFcn(app, @iButtonPushed, true);
            app.iButton.Text = 'i';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GeNEsIS_display

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end