classdef GeNEsIS_frequency < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GeNEsISfrequencyLabel    matlab.ui.control.Label
        LoadGeNEsISset1Button    matlab.ui.control.Button
        LoadGeNEsISset2Button    matlab.ui.control.Button
        Slider                   matlab.ui.control.Slider
        Slider_2                 matlab.ui.control.Slider
        Button                   matlab.ui.control.Button
        Button_2                 matlab.ui.control.Button
        Button_3                 matlab.ui.control.Button
        Button_4                 matlab.ui.control.Button
        Fileset1Label            matlab.ui.control.Label
        Fileset2Label            matlab.ui.control.Label
        Sizeset1Label            matlab.ui.control.Label
        Sizeset2Label            matlab.ui.control.Label
        SaveasEditFieldLabel     matlab.ui.control.Label
        SaveasEditField          matlab.ui.control.EditField
        SaveimagesButton         matlab.ui.control.Button
        SavefrequencydataButton  matlab.ui.control.Button
        LoadPNGset1Button        matlab.ui.control.Button
        LoadPNGset2Button        matlab.ui.control.Button
        EqualizeDropDownLabel    matlab.ui.control.Label
        EqualizeDropDown         matlab.ui.control.DropDown
        BetweenDropDownLabel     matlab.ui.control.Label
        BetweenDropDown          matlab.ui.control.DropDown
        Label                    matlab.ui.control.Label
        Label_2                  matlab.ui.control.Label
        Label_3                  matlab.ui.control.Label
        Label_4                  matlab.ui.control.Label
        UIAxes                   matlab.ui.control.UIAxes
        UIAxes2                  matlab.ui.control.UIAxes
        UIAxes_2                 matlab.ui.control.UIAxes
        UIAxes2_2                matlab.ui.control.UIAxes
        UIAxes3                  matlab.ui.control.UIAxes
        UIAxes3_2                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Image1
        Image2
        currentIm
        A1
        A2
        P1
        P2
        ImageAmplitude1
        ImageAmplitude2
        ImagePhase1
        ImagePhase2
        ImageAmplitudeTot1
        ImageAmplitudeTot2
        ImagePhaseTot1
        ImagePhaseTot2
        
    end
    
    methods (Access = private)
        
        function [file, data]=loadData(app)
            app.UIFigure.Visible='off';
            [file,path] = uigetfile('*');
            data=load([path '/' file]);
            app.UIFigure.Visible='on';
        end
        
        function [Matrix, file]=loadData2(app)
            app.UIFigure.Visible='off';
            selpath = uigetdir;
            S = dir(fullfile(selpath, '*.png'));
            if numel(S)==0
                disp('No images found!')
            else
                file=S(1);
                for n = 1:numel(S)
                    F = fullfile(selpath,S(n).name);
                    Matrix{1,n} = imread(F);
                end
            end
            app.UIFigure.Visible='on';
        end
        
        function [avgFreq,totPower, avgFreqCut, totPowerCut,totPower10Cut, f1, Pf] = radialavg(app, imgf, N, M, set)
            imgfp = (abs(imgf)/(N*M)).^2;
            bkimg=ifftshift(imgf);
            imgfpCut = (abs(fftshift(bkimg(2:end,2:end)))/((N-1)*(M-1))).^2;
         
            % Adjust PSD size
            dimDiff = abs(N-M);
            dimMax = max(N,M);
            dimDiffCut = abs(N-M-2);
            dimMaxCut = max(N-1,M-1);
            % Make square
            if N > M                                                                    % More rows than columns
                if ~mod(dimDiff,2)                                                      % Even difference
                    imgfp = [NaN(N,dimDiff/2) imgfp NaN(N,dimDiff/2)];                  % Pad columns to match dimensions
                else                                                                    % Odd difference
                    imgfp = [NaN(N,floor(dimDiff/2)) imgfp NaN(N,floor(dimDiff/2)+1)];  % Pad columns to match dimensions
                end
                if ~mod(dimDiffCut,2)                                                      % Even difference
                    imgfpCut = [NaN(N-1,dimDiffCut/2) imgfp NaN(N-1,dimDiffCut/2)];                  % Pad columns to match dimensions
                else                                                                    % Odd difference
                    imgfpCut = [NaN(N-1,floor(dimDiffCut/2)) imgfp NaN(N-1,floor(dimDiffCut/2)+1)];  % Pad columns to match dimensions
                end
            elseif N < M                                                                % More columns than rows
                if ~mod(dimDiff,2)                                                      % Even difference
                    imgfp = [NaN(dimDiff/2,M); imgfp; NaN(dimDiff/2,M)];                % Pad rows to match dimensions
                else                                                                    % Odd difference
                    imgfp = [NaN(floor(dimDiff/2),M); imgfp; NaN(floor(dimDiff/2)+1,M)];% Pad rows to match dimensions
                end
                if ~mod(dimDiffCut,2)                                                      % Even difference
                    imgfpCut = [NaN(dimDiffCut/2,M-1); imgfp; NaN(dimDiffCut/2,M-1)];                % Pad rows to match dimensions
                else                                                                    % Odd difference
                    imgfpCut = [NaN(floor(dimDiffCut/2),M-1); imgfp; NaN(floor(dimDiffCut/2)+1,M-1)];% Pad rows to match dimensions
                end
            end
            
            % Only consider one half of spectrum (due to symmetry)
            %% Compute radially average power spectrum
            [X,Y] = meshgrid(-dimMax/2:dimMax/2-1, -dimMax/2:dimMax/2-1);               % Make Cartesian grid
            [~,rho] = cart2pol(X,Y);                                                    % Convert to polar coordinate axes
            rho = round(rho);
            i = cell(floor(dimMax/2) + 1, 1);
            for r = 0:floor(dimMax/2)
                i{r + 1} = find(rho == r);
            end
            Pf = zeros(1, floor(dimMax/2)+1);
            for r = 0:floor(dimMax/2)
               Pf(1, r + 1) = nanmean( imgfp( i{r+1} ) );                              %mean over all image fourier points at distance r 
            end
            
            [Xcut,Ycut] = meshgrid(-dimMaxCut/2:dimMaxCut/2-1, -dimMaxCut/2:dimMaxCut/2-1);               % Make Cartesian grid
            [~,rhoCut] = cart2pol(Xcut,Ycut);                                                    % Convert to polar coordinate axes
            rhoCut = round(rhoCut);
            i = cell(floor(dimMaxCut/2) + 1, 1);
            for r = 0:floor(dimMaxCut/2)
                i{r + 1} = find(rhoCut == r);
            end
            PfCut = zeros(1, floor(dimMaxCut/2)+1);
            for r = 0:floor(dimMaxCut/2)
               PfCut(1, r + 1) = nanmean( imgfpCut( i{r+1} ) );                              %mean over all image fourier points at distance r 
            end
            
            fs=1/sqrt(2); % 1 point every pixel -converted in radial- (change here 1 to convert in cm, how many points per cm)
            f1 = (0 : 1/dimMax : 0.5)/fs;
            f1Cut = (0 : 1/dimMaxCut : 0.5)/fs;
            avgFreq = nansum(f1.*Pf)/nansum(Pf);
            totPower = nansum(Pf);
            totPower10Cut = nansum(Pf(10:end));
            avgFreqCut = nansum(f1Cut.*PfCut)/nansum(PfCut);
            totPowerCut = nansum(PfCut);
            if set==1
                loglog(app.UIAxes3, f1, Pf);
                app.Label.Text = ['Total power: ' num2str(totPower)];
                app.Label_3.Text = ['Total power (no DC component): ' num2str(totPowerCut)];
            elseif set==2
                loglog(app.UIAxes3_2, f1, Pf);
                app.Label_2.Text = ['Total power: ' num2str(totPower)];
                app.Label_4.Text = ['Total power (no DC component): ' num2str(totPowerCut)];
            end
        end
        
        function equalize(app)
            switch app.EqualizeDropDown.Value
                case 'Nothing'
                    imagesc(app.UIAxes_2, app.Image1{app.currentIm{1}})
                    colormap(app.UIAxes_2,gray);
                    imagesc(app.UIAxes2_2, app.Image2{app.currentIm{2}})
                    colormap(app.UIAxes2_2,gray);
                case 'Amplitude'
                    switch app.BetweenDropDown.Value
                        case 'Images in group'
                            %image1
                            A=zeros(size(app.Image1{1},1),size(app.Image1{1},2));
                            for i=1:size(app.Image1,2)
                                A=A+app.A1{i};
                            end
                            A=A./size(app.Image1,2);
                            for i=1:size(app.Image1,2)
                                app.ImageAmplitude1{i} = ifft2(A.*exp(app.P1{i}*1i));
                            end
                            imagesc(app.UIAxes_2, abs(app.ImageAmplitude1{app.currentIm{1}}))
                            colormap(app.UIAxes_2,gray);
                            %image2
                            A=zeros(size(app.Image2{1},1),size(app.Image2{1},2));
                            for i=1:size(app.Image2,2)
                                A=A+app.A2{i};
                            end
                            A=A./size(app.Image2,2);
                            for i=1:size(app.Image2,2)
                                app.ImageAmplitude2{i} = ifft2(A.*exp(app.P2{i}*1i));
                            end
                            imagesc(app.UIAxes2_2, abs(app.ImageAmplitude2{app.currentIm{2}}))
                            colormap(app.UIAxes2_2,gray);
                        case 'All groups'
                            %image1
                            A=zeros(size(app.Image1{1},1),size(app.Image1{1},2));
                            for i=1:size(app.Image1,2)
                                A=A+app.A1{i};
                            end
                            for i=1:size(app.Image2,2)
                                A=A+app.A2{i};
                            end
                            A=A./(size(app.Image1,2)+size(app.Image2,2));
                            for i=1:size(app.Image1,2)
                                app.ImageAmplitudeTot1{i} = ifft2(A.*exp(app.P1{i}*1i));
                            end
                            imagesc(app.UIAxes_2, abs(app.ImageAmplitudeTot1{app.currentIm{1}}))
                            colormap(app.UIAxes_2,gray);
                            for i=1:size(app.Image2,2)
                                app.ImageAmplitudeTot2{i} = ifft2(A.*exp(app.P2{i}*1i));
                            end
                            imagesc(app.UIAxes2_2, abs(app.ImageAmplitudeTot2{app.currentIm{2}}))
                            colormap(app.UIAxes2_2, gray);
                    end
                case 'Phase'
                    switch app.BetweenDropDown.Value
                        case 'Images in group'
                            %image1
                            P=zeros(size(app.Image1{1},1),size(app.Image1{1},2));
                            for i=1:size(app.Image1,2)
                                P=P+app.P1{i};
                            end
                            P=P./size(app.Image1,2);
                            for i=1:size(app.Image1,2)
                                app.ImagePhase1{i} = ifft2(app.A1{i}.*exp(P*1i));
                            end
                            imagesc(app.UIAxes_2, abs(app.ImagePhase1{app.currentIm{1}}))
                            %image2
                            P=zeros(size(app.Image2{1},1),size(app.Image2{1},2));
                            for i=1:size(app.Image2,2)
                                P=P+app.P2{i};
                            end
                            P=P./size(app.Image2,2);
                            for i=1:size(app.Image2,2)
                                app.ImagePhase2{i} = ifft2(app.A2{i}.*exp(P*1i));
                            end
                            imagesc(app.UIAxes2_2, abs(app.ImagePhase2{app.currentIm{2}}))
                        case 'All groups'
                            P=zeros(size(app.Image1{1},1),size(app.Image1{1},2));
                            %image1
                            for i=1:size(app.Image1,2)
                                P=P+app.P1{i};
                            end
                            for i=1:size(app.Image2,2)
                                P=P+app.P2{i};
                            end
                            P=P./(size(app.Image1,2)+size(app.Image2,2));
                            for i=1:size(app.Image1,2)
                                app.ImagePhaseTot1{i} = ifft2(app.A1{i}.*exp(P*1i));
                            end
                            imagesc(app.UIAxes_2, abs(app.ImagePhaseTot1{app.currentIm{1}}))
                            for i=1:size(app.Image2,2)
                                app.ImagePhaseTot2{i} = ifft2(app.A2{i}.*exp(P*1i));
                            end
                            imagesc(app.UIAxes2_2, abs(app.ImagePhaseTot2{app.currentIm{2}}))
                    end                   
            end
        end 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;
            app.currentIm{1}=1;
            app.currentIm{2}=1;
        end

        % Button pushed function: LoadGeNEsISset1Button
        function LoadGeNEsISset1ButtonPushed(app, event)
            app.Slider.Enable = 'on';
            [file, data]=loadData(app);
            app.Image1=data.imgMatrix;
            app.Fileset1Label.Text = ['File: ' file];
            if size(app.Image1,2)>1
                app.Slider.Limits = [1 size(app.Image1,2)];
            else
                app.Slider.Enable='off';
            end
            app.currentIm{1}=1;
            app.Slider.Value=1;
            for i=1:size(app.Image1, 2)
                app.Image1{i}=im2gray(app.Image1{i});
            end
            im=abs(app.Image1{app.currentIm{1}});
            app.UIAxes.XLim = [0 size(im,1)];
            app.UIAxes.YLim = [0 size(im,2)];
            app.UIAxes_2.XLim = [0 size(im,1)];
            app.UIAxes_2.YLim = [0 size(im,2)];
            imagesc(app.UIAxes, im)
            colormap(app.UIAxes,gray);
            for i=1:size(app.Image1,2)
                F{i}=fft2(app.Image1{i});
                app.A1{i}=abs(F{i});
                app.P1{i}=angle(F{i});
            end
            imgf=fftshift(fft2(im));
            [~, ~, ~, ~, ~,~] = radialavg(app,imgf, size(im,1), size(im,2),1);
        end

        % Button pushed function: LoadGeNEsISset2Button
        function LoadGeNEsISset2ButtonPushed(app, event)
            app.Slider_2.Enable = 'on';
            [file, data]=loadData(app);
            app.Image2=data.imgMatrix;
            app.Fileset2Label.Text = ['File: ' file];
            if size(app.Image2,2)>1
                app.Slider_2.Limits = [1 size(app.Image2,2)];
            else
                app.Slider_2.Enable='off';
            end
            app.currentIm{2}=1;
            app.Slider_2.Value=1;
            for i=1:size(app.Image2, 2)
                app.Image2{i}=im2gray(app.Image2{i});
            end
            im=abs(app.Image2{app.currentIm{2}});
            app.UIAxes2.XLim = [0 size(im,1)];
            app.UIAxes2.YLim = [0 size(im,2)];
            app.UIAxes2_2.XLim = [0 size(im,1)];
            app.UIAxes2_2.YLim = [0 size(im,2)];
            imagesc(app.UIAxes2, im)
            colormap(app.UIAxes2,gray);
            for i=1:size(app.Image2,2)
                F{i}=fft2(app.Image2{i});
                app.A2{i}=abs(F{i});
                app.P2{i}=angle(F{i});
            end
            imgf=fftshift(fft2(im));
            [~, ~, ~, ~, ~,~] = radialavg(app,imgf, size(im,1), size(im,2),2);
            equalize(app);
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            changingValue = round(event.Value);
            app.currentIm{1}=changingValue;
            im=app.Image1{changingValue};
            imagesc(app.UIAxes, im)
            colormap(app.UIAxes,gray);
            switch app.EqualizeDropDown.Value
                case 'Nothing'
                    imagesc(app.UIAxes_2, abs(app.Image1{app.currentIm{1}}))
                    colormap(app.UIAxes_2,gray);
                case 'Amplitude'
                    switch app.BetweenDropDown.Value
                        case 'Images in group'
                            imagesc(app.UIAxes_2, abs(app.ImageAmplitude1{app.currentIm{1}}))
                        case 'All groups'
                            imagesc(app.UIAxes_2, abs(app.ImageAmplitudeTot1{app.currentIm{1}}))
                    end
                    colormap(app.UIAxes_2,gray);
                case 'Phase'
                    switch app.BetweenDropDown.Value
                        case 'Images in group'
                            imagesc(app.UIAxes_2, abs(app.ImagePhase1{app.currentIm{1}}))
                        case 'All groups'
                            imagesc(app.UIAxes_2, abs(app.ImagePhaseTot1{app.currentIm{1}}))
                    end
                    colormap(app.UIAxes_2,gray);
            end
            imgf=fftshift(fft2(im));
            [~, ~, ~, ~, ~,~] = radialavg(app,imgf, size(im,1), size(im,2),1);
        end

        % Value changing function: Slider_2
        function Slider_2ValueChanging(app, event)
            changingValue = round(event.Value);
            app.currentIm{2}=changingValue;
            im=app.Image2{changingValue};
            imagesc(app.UIAxes2, im)
            colormap(app.UIAxes2,gray);
            switch app.EqualizeDropDown.Value
                case 'Nothing'
                    imagesc(app.UIAxes2_2, abs(app.Image2{app.currentIm{2}}))
                    colormap(app.UIAxes2_2,gray);
                case 'Amplitude'
                    switch app.BetweenDropDown.Value
                        case 'Images in group'
                            imagesc(app.UIAxes2_2, abs(app.ImageAmplitude2{app.currentIm{2}}))
                        case 'All groups'
                            imagesc(app.UIAxes2_2, abs(app.ImageAmplitudeTot2{app.currentIm{2}}))
                    end
                    colormap(app.UIAxes2_2,gray);
                case 'Phase'
                    switch app.BetweenDropDown.Value
                        case 'Images in group'
                            imagesc(app.UIAxes2_2, abs(app.ImagePhase2{app.currentIm{2}}))
                        case 'All groups'
                            imagesc(app.UIAxes2_2, abs(app.ImagePhaseTot2{app.currentIm{2}}))
                    end
                colormap(app.UIAxes2_2,gray);
            end
            imgf=fftshift(fft2(im));
            [~, ~, ~, ~, ~,~] = radialavg(app,imgf, size(im,1), size(im,2),2);
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = round(app.Slider.Value);
            app.Slider.Value = value;
            app.currentIm{1}=value;
            im=app.Image1{app.currentIm{1}};
            imagesc(app.UIAxes, im)
            switch app.EqualizeDropDown.Value
                case 'Nothing'
                    imagesc(app.UIAxes_2, abs(app.Image1{app.currentIm{1}}))
                case 'Amplitude'
                    switch app.BetweenDropDown.Value
                        case 'Images in group'
                            imagesc(app.UIAxes_2, abs(app.ImageAmplitude1{app.currentIm{1}}))
                        case 'All groups'
                            imagesc(app.UIAxes_2, abs(app.ImageAmplitudeTot1{app.currentIm{1}}))
                    end
                case 'Phase'
                    switch app.BetweenDropDown.Value
                        case 'Images in group'
                            imagesc(app.UIAxes_2, abs(app.ImagePhase1{app.currentIm{1}}))
                        case 'All groups'
                            imagesc(app.UIAxes_2, abs(app.ImagePhaseTot1{app.currentIm{1}}))
                    end    
            end
            colormap(app.UIAxes_2,gray);
            imgf=fftshift(fft2(im));
            [~, ~, ~, ~, ~,~,~] = radialavg(app,imgf, size(im,1), size(im,2),1);
        end

        % Value changed function: Slider_2
        function Slider_2ValueChanged(app, event)
            value = round(app.Slider_2.Value);
            app.Slider_2.Value = value;
            app.currentIm{2} = value;
            im=app.Image2{app.currentIm{2}};
            imagesc(app.UIAxes2, im)
            switch app.EqualizeDropDown.Value
                case 'Nothing'
                    imagesc(app.UIAxes2_2, abs(app.Image2{app.currentIm{2}}))
                case 'Amplitude'
                    switch app.BetweenDropDown.Value
                        case 'Images in group'
                            imagesc(app.UIAxes2_2, abs(app.ImageAmplitude2{app.currentIm{2}}))
                        case 'All groups'
                            imagesc(app.UIAxes2_2, abs(app.ImageAmplitudeTot2{app.currentIm{2}}))
                    end
                case 'Phase'
                    switch app.BetweenDropDown.Value
                        case 'Images in group'
                            imagesc(app.UIAxes2_2, abs(app.ImagePhase2{app.currentIm{2}}))
                        case 'All groups'
                            imagesc(app.UIAxes2_2, abs(app.ImagePhaseTot2{app.currentIm{2}}))
                    end
            end
            colormap(app.UIAxes2_2,gray);
            imgf=fftshift(fft2(im));
            [~, ~, ~, ~, ~,~,~] = radialavg(app,imgf, size(im,1), size(im,2),2);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            if app.currentIm{1} ~= 1
                app.currentIm{1}=app.currentIm{1}-1;
                app.Slider.Value = app.currentIm{1};
                im=app.Image1{app.currentIm{1}};
                imagesc(app.UIAxes, im)
                switch app.EqualizeDropDown.Value
                    case 'Nothing'
                        imagesc(app.UIAxes_2, abs(app.Image1{app.currentIm{1}}))
                    case 'Amplitude'
                        switch app.BetweenDropDown.Value
                            case 'Images in group'
                                imagesc(app.UIAxes_2, abs(app.ImageAmplitude1{app.currentIm{1}}))
                            case 'All groups'
                                imagesc(app.UIAxes_2, abs(app.ImageAmplitudeTot1{app.currentIm{1}}))
                        end
                    case 'Phase'
                        switch app.BetweenDropDown.Value
                            case 'Images in group'
                                imagesc(app.UIAxes_2, abs(app.ImagePhase1{app.currentIm{1}}))
                            case 'All groups'
                                imagesc(app.UIAxes_2, abs(app.ImagePhaseTot1{app.currentIm{1}}))
                        end
                end
                colormap(app.UIAxes_2,gray);
                imgf=fftshift(fft2(im));
                [~, ~, ~,~,~,~,~] = radialavg(app,imgf, size(im,1), size(im,2),1);
            end
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            if app.currentIm{2} ~= 1
                app.currentIm{2}=app.currentIm{2}-1;
                app.Slider_2.Value = app.currentIm{2};
                im=app.Image2{app.currentIm{2}};
                imagesc(app.UIAxes2, im)
                switch app.EqualizeDropDown.Value
                    case 'Nothing'
                        imagesc(app.UIAxes2_2, abs(app.Image2{app.currentIm{2}}))
                    case 'Amplitude'
                        switch app.BetweenDropDown.Value
                            case 'Images in group'
                                imagesc(app.UIAxes2_2, abs(app.ImageAmplitude2{app.currentIm{2}}))
                            case 'All groups'
                                imagesc(app.UIAxes2_2, abs(app.ImageAmplitudeTot2{app.currentIm{2}}))
                        end
                    case 'Phase'
                        switch app.BetweenDropDown.Value
                            case 'Images in group'
                                imagesc(app.UIAxes2_2, abs(app.ImagePhase2{app.currentIm{2}}))
                            case 'All groups'
                                imagesc(app.UIAxes2_2, abs(app.ImagePhaseTot2{app.currentIm{2}}))
                        end
                end
                colormap(app.UIAxes2_2,gray);
                imgf=fftshift(fft2(im));
                [~, ~, ~, ~, ~,~,~] = radialavg(app,imgf, size(im,1), size(im,2),2);
            end
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            if app.currentIm{1} ~= size(app.Image1,2)
                app.currentIm{1}=app.currentIm{1}+1;
                app.Slider.Value = app.currentIm{1};
                im=app.Image1{app.currentIm{1}};
                imagesc(app.UIAxes, im)
                switch app.EqualizeDropDown.Value
                    case 'Nothing'
                        imagesc(app.UIAxes_2, abs(app.Image1{app.currentIm{1}}))
                    case 'Amplitude'
                        switch app.BetweenDropDown.Value
                            case 'Images in group'
                                imagesc(app.UIAxes_2, abs(app.ImageAmplitude1{app.currentIm{1}}))
                            case 'All groups'
                                imagesc(app.UIAxes_2, abs(app.ImageAmplitudeTot1{app.currentIm{1}}))
                        end
                    case 'Phase'
                        switch app.BetweenDropDown.Value
                            case 'Images in group'
                                imagesc(app.UIAxes_2, abs(app.ImagePhase1{app.currentIm{1}}))
                            case 'All groups'
                                imagesc(app.UIAxes_2, abs(app.ImagePhaseTot1{app.currentIm{1}}))
                        end
                end
                colormap(app.UIAxes_2,gray);
                imgf=fftshift(fft2(im));
                [~, ~, ~, ~, ~,~,~] = radialavg(app,imgf, size(im,1), size(im,2),1);
            end
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            if app.currentIm{2} ~= size(app.Image2,2)
                app.currentIm{2}=app.currentIm{2}+1;
                app.Slider_2.Value = app.currentIm{2};
                im=app.Image2{app.currentIm{2}};
                imagesc(app.UIAxes2, im)
                switch app.EqualizeDropDown.Value
                    case 'Nothing'
                        imagesc(app.UIAxes2_2, abs(app.Image2{app.currentIm{2}}))
                    case 'Amplitude'
                        switch app.BetweenDropDown.Value
                            case 'Images in group'
                                imagesc(app.UIAxes2_2, abs(app.ImageAmplitude2{app.currentIm{2}}))
                            case 'All groups'
                                imagesc(app.UIAxes2_2, abs(app.ImageAmplitudeTot2{app.currentIm{2}}))
                        end
                    case 'Phase'
                        switch app.BetweenDropDown.Value
                            case 'Images in group'
                                imagesc(app.UIAxes2_2, abs(app.ImagePhase2{app.currentIm{2}}))
                            case 'All groups'
                                imagesc(app.UIAxes2_2, abs(app.ImagePhaseTot2{app.currentIm{2}}))
                        end
                end
                colormap(app.UIAxes2_2,gray);
                imgf=fftshift(fft2(im));
                [~, ~, ~, ~, ~,~] = radialavg(app,imgf, size(im,1), size(im,2),2);
            end
        end

        % Button pushed function: SaveimagesButton
        function SaveimagesButtonPushed(app, event)
            name = app.SaveasEditField.Value;
            mkdir('Final_images');
            answer = questdlg('What do you want to save?', 'Save options','Set 1','Set 2','Set 1');
            switch answer
                case 'Set 1'
                    switch app.EqualizeDropDown.Value
                        case 'Nothing'
                            imgMatrix = app.Image1;
                        case 'Amplitude'
                           switch app.BetweenDropDown.Value
                               case 'Images in group'
                                   imgMatrix = app.ImageAmplitude1;
                               case 'All groups'
                                   imgMatrix = app.ImageAmplitudeTot1;
                           end
                        case 'Phase'
                            switch app.BetweenDropDown.Value
                               case 'Images in group'
                                   imgMatrix = app.ImagePhase1;
                               case 'All groups'
                                   imgMatrix = app.ImagePhaseTot1;
                           end
                    end
                case 'Set 2'
                   switch app.EqualizeDropDown.Value
                        case 'Nothing'
                            imgMatrix = app.Image2;
                        case 'Amplitude'
                           switch app.BetweenDropDown.Value
                               case 'Images in group'
                                   imgMatrix = app.ImageAmplitude2;
                               case 'All groups'
                                   imgMatrix = app.ImageAmplitudeTot2;
                           end
                        case 'Phase'
                            switch app.BetweenDropDown.Value
                               case 'Images in group'
                                   imgMatrix = app.ImagePhase2;
                               case 'All groups'
                                   imgMatrix = app.ImagePhaseTot2;
                           end
                   end
            end
            save(['Final_images\' name], 'imgMatrix', '-v7.3')
            answer2 = questdlg('Do you want to save also images?', 'Save options','Yes','No','Yes');
            switch answer2
                case 'Yes'
                    w = waitbar(0, ['Saved 0 of ' num2str(size(imgMatrix,2)) ' images...']);
                    f = figure(1);
                    set(gca,'xtick',[],'ytick',[])
                    set(gca,'XColor', 'none','YColor','none')
                    axis 'off'
                    for i=1:size(imgMatrix,2)
                        %hold off;
                        imagesc(abs(imgMatrix{i}));
                        colormap(gray);
                        set(gca,'xtick',[],'ytick',[])
                        set(gca,'XColor', 'none','YColor','none')
                        Image = getframe(f);
                        waitbar(i/size(imgMatrix,2),w,['Saved ' num2str(i) ' of ' num2str(size(imgMatrix,2)) ' images...']);
                        imwrite(Image.cdata, ['Final_images\' name '_' num2str(i) '.png']);
                    end
                    close(w);
                    close(f);
            end
        end

        % Button pushed function: SavefrequencydataButton
        function SavefrequencydataButtonPushed(app, event)
            name = app.SaveasEditField.Value;
            mkdir('Final_images');
            answer = questdlg('What do you want to save?', 'Save options','Set 1','Set 2','Set 1');
            set=0;
            switch answer
                case 'Set 1'
                    set=1;
                    switch app.EqualizeDropDown.Value
                        case 'Nothing'
                            imgMatrix = app.Image1;
                        case 'Amplitude'
                           switch app.BetweenDropDown.Value
                               case 'Images in group'
                                   imgMatrix = app.ImageAmplitude1;
                               case 'All groups'
                                   imgMatrix = app.ImageAmplitudeTot1;
                           end
                        case 'Phase'
                            switch app.BetweenDropDown.Value
                               case 'Images in group'
                                   imgMatrix = app.ImagePhase1;
                               case 'All groups'
                                   imgMatrix = app.ImagePhaseTot1;
                           end
                    end
                case 'Set 2'
                   set=2;
                   switch app.EqualizeDropDown.Value
                        case 'Nothing'
                            imgMatrix = app.Image2;
                        case 'Amplitude'
                           switch app.BetweenDropDown.Value
                               case 'Images in group'
                                   imgMatrix = app.ImageAmplitude2;
                               case 'All groups'
                                   imgMatrix = app.ImageAmplitudeTot2;
                           end
                        case 'Phase'
                            switch app.BetweenDropDown.Value
                               case 'Images in group'
                                   imgMatrix = app.ImagePhase2;
                               case 'All groups'
                                   imgMatrix = app.ImagePhaseTot2;
                           end
                   end
            end
            f = waitbar(0,'Calculating avarage frequencies...');
            n=(1:size(imgMatrix,2))';
            avgFreq_px=zeros(size(imgMatrix,2),1);
            totPower=zeros(size(imgMatrix,2),1);
            avgFreqCut_px=zeros(size(imgMatrix,2),1);
            totPowerCut=zeros(size(imgMatrix,2),1);
            totPower10Cut=zeros(size(imgMatrix,2),1);
            for i=1:size(imgMatrix,2)
                imgf=fftshift(fft2(imgMatrix{i}));
                [avgFreq_px(i), totPower(i),avgFreqCut_px(i), totPowerCut(i),totPower10Cut(i), ~,~] = radialavg(app,imgf, size(imgMatrix{i},1), size(imgMatrix{i},2),set);
                waitbar(i/size(imgMatrix,2),f,['Calculating avarage frequencies: ' num2str(i) ' of ' num2str(size(imgMatrix,2)) '...']);
            end
            T = table(n,avgFreq_px,totPower, avgFreqCut_px,totPowerCut,totPower10Cut, 'VariableNames', {'Stimulus','Weighted_average_freq','Total_power_Area', 'Weighted_average_freq_NoDC','Total_power_Area_NoDC','Total_power_Area_10cut'});
            writetable(T, [name, '.xlsx'])
            close(f)
        end

        % Button pushed function: LoadPNGset1Button
        function LoadPNGset1ButtonPushed(app, event)
            clc;
            app.Slider.Enable = 'on';
            [app.Image1, file]=loadData2(app);
            app.Fileset1Label.Text = ['File: ' file.name];
            if size(app.Image1,2)>1
                app.Slider.Limits = [1 size(app.Image1,2)];
            else
                app.Slider.Enable='off';
            end
            for i=1:size(app.Image1, 2)
                app.Image1{i}=im2gray(app.Image1{i});
            end
            app.currentIm{1}=1;
            app.Slider.Value=1;
            im=app.Image1{1};
            app.UIAxes.XLim = [0 size(im,1)];
            app.UIAxes.YLim = [0 size(im,2)];
            app.UIAxes_2.XLim = [0 size(im,1)];
            app.UIAxes_2.YLim = [0 size(im,2)];
            imagesc(app.UIAxes, im)
            for i=1:size(app.Image1,2)
                F{i}=fft2(app.Image1{i});
                app.A1{i}=abs(F{i});
                app.P1{i}=angle(F{i});
            end
            imgf=fftshift(fft2(im));
            [~, ~, ~, ~, ~,~,~] = radialavg(app,imgf, size(im,1), size(im,2),1);
        end

        % Button pushed function: LoadPNGset2Button
        function LoadPNGset2ButtonPushed(app, event)
            clc;
            app.Slider_2.Enable = 'on';
            [app.Image2, file]=loadData2(app);
            app.Fileset2Label.Text = ['File: ' file.name];
            if size(app.Image2,2)>1
                app.Slider_2.Limits = [1 size(app.Image2,2)];
            else
                app.Slider_2.Enable='off';
            end
            for i=1:size(app.Image2, 2)
                app.Image2{i}=im2gray(app.Image2{i});
            end
            app.currentIm{2}=1;
            app.Slider_2.Value=1;
            im=app.Image2{1};
            app.UIAxes2.XLim = [0 size(im,1)];
            app.UIAxes2.YLim = [0 size(im,2)];
            app.UIAxes2_2.XLim = [0 size(im,1)];
            app.UIAxes2_2.YLim = [0 size(im,2)];
            imagesc(app.UIAxes2, im)
            for i=1:size(app.Image2,2)
                F{i}=fft2(app.Image2{i});
                app.A2{i}=abs(F{i});
                app.P2{i}=angle(F{i});
            end
            imgf=fftshift(fft2(im));
            [~, ~, ~, ~, ~,~,~] = radialavg(app,imgf, size(im,1), size(im,2),2);
            equalize(app);
        end

        % Value changed function: EqualizeDropDown
        function EqualizeDropDownValueChanged(app, event)
            value = app.EqualizeDropDown.Value;
            equalize(app)
        end

        % Value changed function: BetweenDropDown
        function BetweenDropDownValueChanged(app, event)
            value = app.BetweenDropDown.Value;
            equalize(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.GeNEsISfrequencyLabel = uilabel(app.UIFigure);
            app.GeNEsISfrequencyLabel.Text = 'GeNEsIS - frequency';
            app.LoadGeNEsISset1Button = uibutton(app.UIFigure, 'push');
            app.LoadGeNEsISset1Button.ButtonPushedFcn = createCallbackFcn(app, @LoadGeNEsISset1ButtonPushed, true);
            app.LoadGeNEsISset1Button.Text = 'Load GeNEsIS set 1';
            app.LoadGeNEsISset2Button = uibutton(app.UIFigure, 'push');
            app.LoadGeNEsISset2Button.ButtonPushedFcn = createCallbackFcn(app, @LoadGeNEsISset2ButtonPushed, true);
            app.LoadGeNEsISset2Button.Text = 'Load GeNEsIS set 2';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [1 10];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Slider.Value = 1;
            app.Slider_2 = uislider(app.UIFigure);
            app.Slider_2.Limits = [1 10];
            app.Slider_2.ValueChangedFcn = createCallbackFcn(app, @Slider_2ValueChanged, true);
            app.Slider_2.ValueChangingFcn = createCallbackFcn(app, @Slider_2ValueChanging, true);
            app.Slider_2.Value = 1;
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '<<';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '>>';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '<<';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '>>';
            app.Fileset1Label = uilabel(app.UIFigure);
            app.Fileset1Label.Text = 'File set 1';
            app.Fileset2Label = uilabel(app.UIFigure);
            app.Fileset2Label.Text = 'File set 2';
            app.Sizeset1Label = uilabel(app.UIFigure);
            app.Sizeset1Label.Text = 'Size set 1';
            app.Sizeset2Label = uilabel(app.UIFigure);
            app.Sizeset2Label.Text = 'Size set 2';
            app.SaveasEditFieldLabel = uilabel(app.UIFigure);
            app.SaveasEditFieldLabel.Text = 'Save as:';
            app.SaveasEditField = uieditfield(app.UIFigure, 'text');
            app.SaveimagesButton = uibutton(app.UIFigure, 'push');
            app.SaveimagesButton.ButtonPushedFcn = createCallbackFcn(app, @SaveimagesButtonPushed, true);
            app.SaveimagesButton.Text = 'Save images';
            app.SavefrequencydataButton = uibutton(app.UIFigure, 'push');
            app.SavefrequencydataButton.ButtonPushedFcn = createCallbackFcn(app, @SavefrequencydataButtonPushed, true);
            app.SavefrequencydataButton.Text = 'Save frequency data';
            app.LoadPNGset1Button = uibutton(app.UIFigure, 'push');
            app.LoadPNGset1Button.ButtonPushedFcn = createCallbackFcn(app, @LoadPNGset1ButtonPushed, true);
            app.LoadPNGset1Button.Text = 'Load PNG set 1';
            app.LoadPNGset2Button = uibutton(app.UIFigure, 'push');
            app.LoadPNGset2Button.ButtonPushedFcn = createCallbackFcn(app, @LoadPNGset2ButtonPushed, true);
            app.LoadPNGset2Button.Text = 'Load PNG set 2';
            app.EqualizeDropDownLabel = uilabel(app.UIFigure);
            app.EqualizeDropDownLabel.Text = 'Equalize';
            app.EqualizeDropDown = uidropdown(app.UIFigure);
            app.EqualizeDropDown.Items = {'Nothing', 'Amplitude', 'Phase'};
            app.EqualizeDropDown.ValueChangedFcn = createCallbackFcn(app, @EqualizeDropDownValueChanged, true);
            app.EqualizeDropDown.Value = 'Nothing';
            app.BetweenDropDownLabel = uilabel(app.UIFigure);
            app.BetweenDropDownLabel.Text = 'Between';
            app.BetweenDropDown = uidropdown(app.UIFigure);
            app.BetweenDropDown.Items = {'Images in group', 'All groups'};
            app.BetweenDropDown.ValueChangedFcn = createCallbackFcn(app, @BetweenDropDownValueChanged, true);
            app.BetweenDropDown.Value = 'Images in group';
            app.Label = uilabel(app.UIFigure);
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_4 = uilabel(app.UIFigure);
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes2 = uiaxes(app.UIFigure);
            app.UIAxes_2 = uiaxes(app.UIFigure);
            app.UIAxes2_2 = uiaxes(app.UIFigure);
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Power spectrum')
            xlabel(app.UIAxes3, 'f [cycle/px]')
            ylabel(app.UIAxes3, 'Power')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes3_2 = uiaxes(app.UIFigure);
            title(app.UIAxes3_2, 'Power spectrum')
            xlabel(app.UIAxes3_2, 'f [cycle/px]')
            ylabel(app.UIAxes3_2, 'Power')
            zlabel(app.UIAxes3_2, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GeNEsIS_frequency

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end