classdef shapesGenesis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        LoadshapeButton                 matlab.ui.control.Button
        iButton_2                       matlab.ui.control.Button
        iButton                         matlab.ui.control.Button
        OpenGeNEsIS_saveButton          matlab.ui.control.Button
        GeNEsIScreateLabel              matlab.ui.control.Label
        AdditionalconstraintsPanel      matlab.ui.container.Panel
        OnewithrmaximumCheckBox         matlab.ui.control.CheckBox
        OnewithrminimumCheckBox         matlab.ui.control.CheckBox
        AtleastonewithrbiggerEditField  matlab.ui.control.NumericEditField
        AtleastonewithrbiggerEditFieldLabel  matlab.ui.control.Label
        AtleastonewithrsmallerEditField  matlab.ui.control.NumericEditField
        AtleastonewithrsmallerEditFieldLabel  matlab.ui.control.Label
        r_minEditField                  matlab.ui.control.NumericEditField
        r_minEditFieldLabel             matlab.ui.control.Label
        r_maxEditField                  matlab.ui.control.NumericEditField
        r_maxEditFieldLabel             matlab.ui.control.Label
        min_distEditField               matlab.ui.control.NumericEditField
        min_distEditFieldLabel          matlab.ui.control.Label
        max_distEditField               matlab.ui.control.NumericEditField
        max_distEditFieldLabel          matlab.ui.control.Label
        ElementsPanel                   matlab.ui.container.Panel
        RadiusvariabilityEditField      matlab.ui.control.NumericEditField
        RadiusvariabilityLabel          matlab.ui.control.Label
        MeanradiusEditField             matlab.ui.control.NumericEditField
        MeanradiusLabel_2               matlab.ui.control.Label
        ShapeDropDown                   matlab.ui.control.DropDown
        ShapeLabel                      matlab.ui.control.Label
        UnitsexstimationPanel           matlab.ui.container.Panel
        AreasconversionLabel            matlab.ui.control.Label
        LinearconversionLabel           matlab.ui.control.Label
        px2Label                        matlab.ui.control.Label
        pxLabel                         matlab.ui.control.Label
        ReferencearenarcmEditField      matlab.ui.control.NumericEditField
        ReferencearenarcmEditFieldLabel  matlab.ui.control.Label
        Generation                      matlab.ui.control.Label
        Slider                          matlab.ui.control.Slider
        LoadButton                      matlab.ui.control.Button
        ConvexHull_2                    matlab.ui.control.Label
        Next                            matlab.ui.control.Button
        Previous                        matlab.ui.control.Button
        SaveasEditField                 matlab.ui.control.EditField
        SaveasEditFieldLabel            matlab.ui.control.Label
        SaveButton                      matlab.ui.control.Button
        KeepCheckBox                    matlab.ui.control.CheckBox
        BalanceButtonGroup              matlab.ui.container.ButtonGroup
        FixedParamEditField             matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        ConstantradiusButton            matlab.ui.control.RadioButton
        TotalperimeterButton            matlab.ui.control.RadioButton
        TotalareaButton                 matlab.ui.control.RadioButton
        NothingButton                   matlab.ui.control.RadioButton
        UpdateLabel                     matlab.ui.control.Label
        r_maxLabel                      matlab.ui.control.Label
        r_minLabel                      matlab.ui.control.Label
        ComputeButton                   matlab.ui.control.Button
        TotalPerimeterLabel             matlab.ui.control.Label
        TotalAreaLabel                  matlab.ui.control.Label
        ConvexHull                      matlab.ui.control.Label
        InterDistance                   matlab.ui.control.Label
        UIAxesNumerosity                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp;
        % external frame defined as:
        % arena.s (arena shape, 'c'=circle, 's'=square)
        % arena.r (dimention, radius for circle; half-side for square)
        arena;
    % stimulus defined as: 
        % ball.s (stimulus shape, 'c'=circle, 's'=square, 'd'=diamond, 't'=triangle); 
        % ball.r (characteristic dimention, radius for circle, side size for square triangle and diamond);
        % ball.X (x,y coordinates of the baricenter);
        % ball.A (shapes areas)
        % ball.P (shapes perimeters)
        % ball.r, .X, .A, .P are cells of {#generations} for the different set generations
        ball;
    % numerosity (number of stimuli -shapes- for generation)
        nBalls;
    % number of random point set generations
        nGenerations;
        costraints;
        mean_r;
        var_r;
        totalPoints;
        targetCH;
        tolerance;

        customShape
    end
    
    methods (Access = private)
        
    % generate unbalanced circle (with mean r)
        function computeUnbalanced(app,n)
            start=1;
            if app.OnewithrmaximumCheckBox.Value==1 && app.OnewithrminimumCheckBox.Value==1 && app.nBalls>1
                app.ball.r{n}(1)=app.r_maxEditField.Value;
                app.ball.A{n}(1)=pi*app.ball.r{n}(1)^2;
                app.ball.P{n}(1)=2*pi*app.ball.r{n}(1);
                app.ball.r{n}(2)=app.r_minEditField.Value;
                app.ball.A{n}(2)=pi*app.ball.r{n}(2)^2;
                app.ball.P{n}(2)=2*pi*app.ball.r{n}(2);
                start=3;
            elseif app.OnewithrminimumCheckBox.Value==1
                app.ball.r{n}(1)=app.r_minEditField.Value;
                app.ball.A{n}(1)=pi*app.ball.r{n}(1)^2;
                app.ball.P{n}(1)=2*pi*app.ball.r{n}(1);
                start=2;
            elseif app.OnewithrmaximumCheckBox.Value==1
                app.ball.r{n}(1)=app.r_maxEditField.Value;
                app.ball.A{n}(1)=pi*app.ball.r{n}(1)^2;
                app.ball.P{n}(1)=2*pi*app.ball.r{n}(1);
                start=2; 
            end
            if app.nBalls==2 && start==2
                app.ball.r{n}(2)=abs(normrnd(app.mean_r,app.var_r));
                app.ball.A{n}(2)=pi*app.ball.r{n}(2)^2;
                app.ball.P{n}(2)=2*pi*app.ball.r{n}(2);
            elseif start<app.nBalls || (app.nBalls == 1 && start == 1)
                for i=start:app.nBalls
                    app.ball.r{n}(i)=abs(normrnd(app.mean_r,app.var_r));
                    app.ball.A{n}(i)=pi*app.ball.r{n}(i)^2;
                    app.ball.P{n}(i)=2*pi*app.ball.r{n}(i);
                end
            end
        end
    % generate unbalanced square (with mean side)    
        function computeSquareUnbalanced(app,n)
            if app.nBalls==1
                app.ball.r{n}(1)=app.r_maxEditField.Value;
                app.ball.A{n}(1)=app.ball.r{n}(1)^2;
                app.ball.P{n}(1)=4*app.ball.r{n}(1);
            end
            start=1;
            if app.OnewithrmaximumCheckBox.Value==1 && app.OnewithrminimumCheckBox.Value==1 && app.nBalls>1
                app.ball.r{n}(1)=app.r_maxEditField.Value;
                app.ball.A{n}(1)=app.ball.r{n}(1)^2;
                app.ball.P{n}(1)=4*app.ball.r{n}(1);
                app.ball.r{n}(2)=app.r_minEditField.Value;
                app.ball.A{n}(2)=app.ball.r{n}(2)^2;
                app.ball.P{n}(2)=4*app.ball.r{n}(2);
                start=3;
            elseif app.OnewithrminimumCheckBox.Value==1
                app.ball.r{n}(1)=app.r_minEditField.Value;
                app.ball.A{n}(1)=app.ball.r{n}(1)^2;
                app.ball.P{n}(1)=4*app.ball.r{n}(1);
                start=2;
            elseif app.OnewithrmaximumCheckBox.Value==1
                app.ball.r{n}(1)=app.r_maxEditField.Value;
                app.ball.A{n}(1)=app.ball.r{n}(1)^2;
                app.ball.P{n}(1)=4*app.ball.r{n}(1);
                start=2;
            end
            if app.nBalls == 2 && start==2
                app.ball.r{n}(2)=abs(normrnd(app.mean_r,app.var_r));
                app.ball.A{n}(2)=app.ball.r{n}(2)^2;
                app.ball.P{n}(2)=4*app.ball.r{n}(2);
            elseif start<app.nBalls || (app.nBalls == 1 && start == 1)
                for i=start:app.nBalls
                    app.ball.r{n}(i)=abs(normrnd(app.mean_r,app.var_r));
                    app.ball.A{n}(i)=app.ball.r{n}(i)^2;
                    app.ball.P{n}(i)=4*app.ball.r{n}(i);
                end
            end
        end
    % generate unbalanced triangle (with mean side)     
        function computeTriangleUnbalanced(app,n)
            start=1;
            if app.OnewithrmaximumCheckBox.Value==1 && app.OnewithrminimumCheckBox.Value==1 && app.nBalls>1
                app.ball.r{n}(1)=app.r_maxEditField.Value;
                app.ball.A{n}(1)=sqrt(3)/4*app.ball.r{n}(1)^2;
                app.ball.P{n}(1)=3*app.ball.r{n}(1);
                app.ball.r{n}(2)=app.r_minEditField.Value;
                app.ball.A{n}(2)=sqrt(3)/4*app.ball.r{n}(2)^2;
                app.ball.P{n}(2)=3*app.ball.r{n}(2);
                start=3;
            elseif app.OnewithrminimumCheckBox.Value==1
                app.ball.r{n}(1)=app.r_minEditField.Value;
                app.ball.A{n}(1)=sqrt(3)/4*app.ball.r{n}(1)^2;
                app.ball.P{n}(1)=3*app.ball.r{n}(1);
                start=2;
            elseif app.OnewithrmaximumCheckBox.Value==1
                app.ball.r{n}(1)=app.r_maxEditField.Value;
                app.ball.A{n}(1)=sqrt(3)/4*app.ball.r{n}(1)^2;
                app.ball.P{n}(1)=3*app.ball.r{n}(1);
                start=2;
            end
            if app.nBalls == 2 && start==2
                app.ball.r{n}(2)=abs(normrnd(app.mean_r,app.var_r));
                app.ball.A{n}(2)=sqrt(3)/4*app.ball.r{n}(2)^2;
                app.ball.P{n}(2)=3*app.ball.r{n}(2);
            elseif start<app.nBalls || (app.nBalls == 1 && start == 1)
                for i=start:app.nBalls
                    app.ball.r{n}(i)=abs(normrnd(app.mean_r,app.var_r));
                    app.ball.A{n}(i)=sqrt(3)/4*app.ball.r{n}(i)^2;
                    app.ball.P{n}(i)=3*app.ball.r{n}(i);
                end
            end
        end
        
     % generate circle with fixed total area
        function computeAreas(app,n)
            balancedA=app.costraints.constParam;
            app.mean_r=sqrt(balancedA/pi/app.nBalls);
            
            app.ball.A{n}(app.nBalls)=0;
            partialAreaRef=0;
            start=1;
            if app.OnewithrmaximumCheckBox.Value==1 && app.OnewithrminimumCheckBox.Value==1 && app.nBalls>1
                app.ball.r{n}(1)=app.r_maxEditField.Value;
                app.ball.A{n}(1)=pi*app.ball.r{n}(1)^2;
                app.ball.P{n}(1)=2*pi*app.ball.r{n}(1);
                app.ball.r{n}(2)=app.r_minEditField.Value;
                app.ball.A{n}(2)=pi*app.ball.r{n}(2)^2;
                app.ball.P{n}(2)=2*pi*app.ball.r{n}(2);
                partialAreaRef=app.ball.A{n}(1)+app.ball.A{n}(2);
                start=3;
            elseif app.OnewithrminimumCheckBox.Value==1
                app.ball.r{n}(1)=app.r_minEditField.Value;
                app.ball.A{n}(1)=pi*app.ball.r{n}(1)^2;
                app.ball.P{n}(1)=2*pi*app.ball.r{n}(1);
                partialAreaRef=app.ball.A{n}(1);
                start=2;
            elseif app.OnewithrmaximumCheckBox.Value==1
                app.ball.r{n}(1)=app.r_maxEditField.Value;
                app.ball.A{n}(1)=pi*app.ball.r{n}(1)^2;
                app.ball.P{n}(1)=2*pi*app.ball.r{n}(1);
                partialAreaRef=app.ball.A{n}(1);
                start=2; 
            end
            if app.nBalls == 2 && start ==2
                app.ball.A{n}(app.nBalls)=balancedA-app.ball.A{n}(1);
                app.ball.r{n}(app.nBalls)=sqrt(app.ball.A{n}(app.nBalls)/pi);
                app.ball.P{n}(app.nBalls)=2*pi*app.ball.r{n}(app.nBalls);
                app.mean_r=app.MeanradiusEditField.Value;
            elseif start<app.nBalls || (app.nBalls == 1 && start == 1)
                while app.ball.A{n}(app.nBalls)<=0
                    partialArea=partialAreaRef;
                    for i=start:app.nBalls-1
                        app.ball.r{n}(i)=abs(normrnd(app.mean_r,app.var_r));
                        app.ball.A{n}(i)=pi*app.ball.r{n}(i)^2;
                        app.ball.P{n}(i)=2*pi*app.ball.r{n}(i);
                        partialArea=partialArea+app.ball.A{n}(i);
                    end
                app.ball.A{n}(app.nBalls)=balancedA-partialArea;
                end
                app.ball.r{n}(app.nBalls)=sqrt(app.ball.A{n}(app.nBalls)/pi);
                app.ball.P{n}(app.nBalls)=2*pi*app.ball.r{n}(app.nBalls);
                app.mean_r=app.MeanradiusEditField.Value;
                
            end
        end
     % generate squares with fixed total area  
        function computeSquareAreas(app,n)
            balancedA=app.costraints.constParam;
            app.mean_r=sqrt(balancedA/app.nBalls);
            
            app.ball.A{n}(app.nBalls)=0;
            partialAreaRef=0;
            start=1;
            if app.OnewithrmaximumCheckBox.Value==1 && app.OnewithrminimumCheckBox.Value==1 && app.nBalls>1
                app.ball.r{n}(1)=app.r_maxEditField.Value;
                app.ball.A{n}(1)=app.ball.r{n}(1)^2;
                app.ball.P{n}(1)=4*app.ball.r{n}(1);
                app.ball.r{n}(2)=app.r_minEditField.Value;
                app.ball.A{n}(2)=app.ball.r{n}(2)^2;
                app.ball.P{n}(2)=4*app.ball.r{n}(2);
                partialAreaRef=app.ball.A{n}(1)+app.ball.A{n}(2);
                start=3;
            elseif app.OnewithrminimumCheckBox.Value==1
                app.ball.r{n}(1)=app.r_minEditField.Value;
                app.ball.A{n}(1)=app.ball.r{n}(1)^2;
                app.ball.P{n}(1)=4*app.ball.r{n}(1);
                partialAreaRef=app.ball.A{n}(1);
                start=2;
            elseif app.OnewithrmaximumCheckBox.Value==1
                app.ball.r{n}(1)=app.r_maxEditField.Value;
                app.ball.A{n}(1)=app.ball.r{n}(1)^2;
                app.ball.P{n}(1)=4*app.ball.r{n}(1);
                partialAreaRef=app.ball.A{n}(1);
                start=2;
            end
            if app.nBalls == 2 && start==2
                app.ball.A{n}(app.nBalls)=balancedA-app.ball.A{n}(1);
                app.ball.r{n}(app.nBalls)=sqrt(app.ball.A{n}(app.nBalls));
                app.ball.P{n}(app.nBalls)=4*app.ball.r{n}(app.nBalls);
                app.mean_r=app.MeanradiusEditField.Value;
            elseif start<app.nBalls || (app.nBalls == 1 && start == 1)
                while app.ball.A{n}(app.nBalls)<=0
                    partialArea=partialAreaRef;
                    for i=start:app.nBalls-1
                        app.ball.r{n}(i)=abs(normrnd(app.mean_r,app.var_r));
                        app.ball.A{n}(i)=app.ball.r{n}(i)^2;
                        app.ball.P{n}(i)=4*app.ball.r{n}(i);
                        partialArea=partialArea+app.ball.A{n}(i);
                    end
                app.ball.A{n}(app.nBalls)=balancedA-partialArea;
                end
                app.ball.r{n}(app.nBalls)=sqrt(app.ball.A{n}(app.nBalls));
                app.ball.P{n}(app.nBalls)=4*app.ball.r{n}(app.nBalls);
                app.mean_r=app.MeanradiusEditField.Value;
            end
        end
    % generate triangles with fixed total area    
        function computeTriangleAreas(app,n)
            balancedA=app.costraints.constParam;
            app.mean_r=sqrt(balancedA/app.nBalls*4/sqrt(3));
            
            app.ball.A{n}(app.nBalls)=0;
            partialAreaRef=0;
            start=1;
            if app.OnewithrmaximumCheckBox.Value==1 && app.OnewithrminimumCheckBox.Value==1 && app.nBalls>1
                app.ball.r{n}(1)=app.r_maxEditField.Value;
                app.ball.A{n}(1)=sqrt(3)/4*app.ball.r{n}(1)^2;
                app.ball.P{n}(1)=3*app.ball.r{n}(1);
                app.ball.r{n}(2)=app.r_minEditField.Value;
                app.ball.A{n}(2)=sqrt(3)/4*app.ball.r{n}(2)^2;
                app.ball.P{n}(2)=3*app.ball.r{n}(2);
                partialAreaRef=app.ball.A{n}(1)+app.ball.A{n}(2);
                start=3;
            elseif app.OnewithrminimumCheckBox.Value==1
                app.ball.r{n}(1)=app.r_minEditField.Value;
                app.ball.A{n}(1)=sqrt(3)/4*app.ball.r{n}(1)^2;
                app.ball.P{n}(1)=3*app.ball.r{n}(1);
                partialAreaRef=app.ball.A{n}(1);
                start=2;
            elseif app.OnewithrmaximumCheckBox.Value==1
                app.ball.r{n}(1)=app.r_maxEditField.Value;
                app.ball.A{n}(1)=sqrt(3)/4*app.ball.r{n}(1)^2;
                app.ball.P{n}(1)=3*app.ball.r{n}(1);
                partialAreaRef=app.ball.A{n}(1);
                start=2;
            end
            if app.nBalls == 2 && start==2
                app.ball.A{n}(app.nBalls)=balancedA-app.ball.A{n}(1);
                app.ball.r{n}(app.nBalls)=sqrt(app.ball.A{n}(app.nBalls)*4/sqrt(3));
                app.ball.P{n}(app.nBalls)=3*app.ball.r{n}(app.nBalls);
                app.mean_r=app.MeanradiusEditField.Value; 
            elseif start<app.nBalls || (app.nBalls == 1 && start == 1)
                while app.ball.A{n}(app.nBalls)<=0
                    partialArea=partialAreaRef;
                    for i=start:app.nBalls-1
                        app.ball.r{n}(i)=abs(normrnd(app.mean_r,app.var_r));
                        app.ball.A{n}(i)=sqrt(3)/4*app.ball.r{n}(i)^2;
                        app.ball.P{n}(i)=3*app.ball.r{n}(i);
                        partialArea=partialArea+app.ball.A{n}(i);
                    end
                app.ball.A{n}(app.nBalls)=balancedA-partialArea;
                end
                app.ball.r{n}(app.nBalls)=sqrt(app.ball.A{n}(app.nBalls)*4/sqrt(3));
                app.ball.P{n}(app.nBalls)=3*app.ball.r{n}(app.nBalls);
                app.mean_r=app.MeanradiusEditField.Value;
            end
        end
        
    % generate circles with fixed total perimeter    
        function computePerimeters(app, n)
            balancedP=app.costraints.constParam;
            app.mean_r=balancedP/2/pi/app.nBalls;

            app.ball.P{n}(app.nBalls)=0;
            partialPerimeterRef=0;
            start=1;
            if app.OnewithrmaximumCheckBox.Value==1 && app.OnewithrminimumCheckBox.Value==1 && app.nBalls>1
                app.ball.r{n}(1)=app.r_maxEditField.Value;
                app.ball.A{n}(1)=pi*app.ball.r{n}(1)^2;
                app.ball.P{n}(1)=2*pi*app.ball.r{n}(1);
                app.ball.r{n}(2)=app.r_minEditField.Value;
                app.ball.A{n}(2)=pi*app.ball.r{n}(2)^2;
                app.ball.P{n}(2)=2*pi*app.ball.r{n}(2);
                partialPerimeterRef=app.ball.P{n}(1)+app.ball.P{n}(2);
                start=3;
            elseif app.OnewithrminimumCheckBox.Value==1
                app.ball.r{n}(1)=app.r_minEditField.Value;
                app.ball.A{n}(1)=pi*app.ball.r{n}(1)^2;
                app.ball.P{n}(1)=2*pi*app.ball.r{n}(1);
                partialPerimeterRef=app.ball.P{n}(1);
                start=2;
            elseif app.OnewithrmaximumCheckBox.Value==1
                app.ball.r{n}(1)=app.r_maxEditField.Value;
                app.ball.A{n}(1)=pi*app.ball.r{n}(1)^2;
                app.ball.P{n}(1)=2*pi*app.ball.r{n}(1);
                partialPerimeterRef=app.ball.P{n}(1);
                start=2; 
            end
            if app.nBalls == 2 && start==2
                app.ball.P{n}(app.nBalls)=balancedP-app.ball.P{n}(1);
                app.ball.r{n}(app.nBalls)=(app.ball.P{n}(app.nBalls))/2/pi;
                app.ball.A{n}(app.nBalls)=pi*(app.ball.r{n}(app.nBalls))^2;
                app.mean_r=app.MeanradiusEditField.Value;
            elseif start<app.nBalls || (app.nBalls == 1 && start == 1)
                while app.ball.P{n}(app.nBalls)<=0
                    partialPerimeter=partialPerimeterRef;
                    for i=start:app.nBalls-1
                        app.ball.r{n}(i)=abs(normrnd(app.mean_r,app.var_r));
                        app.ball.P{n}(i)=2*pi*app.ball.r{n}(i);
                        app.ball.A{n}(i)=pi*app.ball.r{n}(i)^2;
                        partialPerimeter=partialPerimeter+app.ball.P{n}(i);
                    end
                    app.ball.P{n}(app.nBalls)=balancedP-partialPerimeter;
                end
                app.ball.r{n}(app.nBalls)=(app.ball.P{n}(app.nBalls))/2/pi;
                app.ball.A{n}(app.nBalls)=pi*(app.ball.r{n}(app.nBalls))^2;
                app.mean_r=app.MeanradiusEditField.Value;
            end
        end
   % generate squares with fixed total perimeter      
        function computeSquarePerimeters(app, n)
            balancedP=app.costraints.constParam;
            app.mean_r=balancedP/4/app.nBalls;

            app.ball.P{n}(app.nBalls)=0;
            partialPerimeterRef=0;
            start=1;
            if app.OnewithrmaximumCheckBox.Value==1 && app.OnewithrminimumCheckBox.Value==1 && app.nBalls>1
                app.ball.r{n}(1)=app.r_maxEditField.Value;
                app.ball.A{n}(1)=app.ball.r{n}(1)^2;
                app.ball.P{n}(1)=4*app.ball.r{n}(1);
                app.ball.r{n}(2)=app.r_minEditField.Value;
                app.ball.A{n}(2)=app.ball.r{n}(2)^2;
                app.ball.P{n}(2)=4*app.ball.r{n}(2);
                partialPerimeterRef=app.ball.P{n}(1)+app.ball.P{n}(2);
                start=3;
            elseif app.OnewithrminimumCheckBox.Value==1
                app.ball.r{n}(1)=app.r_minEditField.Value;
                app.ball.A{n}(1)=app.ball.r{n}(1)^2;
                app.ball.P{n}(1)=4*app.ball.r{n}(1);
                partialPerimeterRef=app.ball.P{n}(1);
                start=2;
            elseif app.OnewithrmaximumCheckBox.Value==1
                app.ball.r{n}(1)=app.r_maxEditField.Value;
                app.ball.A{n}(1)=app.ball.r{n}(1)^2;
                app.ball.P{n}(1)=4*app.ball.r{n}(1);
                partialPerimeterRef=app.ball.P{n}(1);
                start=2;
            end
            if app.nBalls == 2 && start==2
                app.ball.P{n}(app.nBalls)=balancedP-app.ball.P{n}(1);
                app.ball.r{n}(app.nBalls)=(app.ball.P{n}(app.nBalls))/4;
                app.ball.A{n}(app.nBalls)=(app.ball.r{n}(app.nBalls))^2;
                app.mean_r=app.MeanradiusEditField.Value;
            elseif start<app.nBalls || (app.nBalls == 1 && start == 1)
                while app.ball.P{n}(app.nBalls)<=0
                    partialPerimeter=partialPerimeterRef;
                    for i=start:app.nBalls-1
                        app.ball.r{n}(i)=abs(normrnd(app.mean_r,app.var_r));
                        app.ball.P{n}(i)=4*app.ball.r{n}(i);
                        app.ball.A{n}(i)=app.ball.r{n}(i)^2;
                        partialPerimeter=partialPerimeter+app.ball.P{n}(i);
                    end
                    app.ball.P{n}(app.nBalls)=balancedP-partialPerimeter;
                end
                app.ball.r{n}(app.nBalls)=(app.ball.P{n}(app.nBalls))/4;
                app.ball.A{n}(app.nBalls)=(app.ball.r{n}(app.nBalls))^2;
                app.mean_r=app.MeanradiusEditField.Value;
            end
        end
    % generate triangles with fixed total perimeter     
        function computeTrianglePerimeters(app, n)
            balancedP=app.costraints.constParam;
            app.mean_r=balancedP/3/app.nBalls;

            app.ball.P{n}(app.nBalls)=0;
            partialPerimeterRef=0;
            start=1;
            if app.OnewithrmaximumCheckBox.Value==1 && app.OnewithrminimumCheckBox.Value==1 && app.nBalls>1
                app.ball.r{n}(1)=app.r_maxEditField.Value;
                app.ball.A{n}(1)=sqrt(3)/4*app.ball.r{n}(1)^2;
                app.ball.P{n}(1)=3*app.ball.r{n}(1);
                app.ball.r{n}(2)=app.r_minEditField.Value;
                app.ball.A{n}(2)=sqrt(3)/4*app.ball.r{n}(2)^2;
                app.ball.P{n}(2)=3*app.ball.r{n}(2);
                partialPerimeterRef=app.ball.P{n}(1)+app.ball.P{n}(2);
                start=3;
            elseif app.OnewithrminimumCheckBox.Value==1
                app.ball.r{n}(1)=app.r_minEditField.Value;
                app.ball.A{n}(1)=sqrt(3)/4*app.ball.r{n}(1)^2;
                app.ball.P{n}(1)=3*app.ball.r{n}(1);
                partialPerimeterRef=app.ball.P{n}(1);
                start=2;
            elseif app.OnewithrmaximumCheckBox.Value==1
                app.ball.r{n}(1)=app.r_maxEditField.Value;
                app.ball.A{n}(1)=sqrt(3)/4*app.ball.r{n}(1)^2;
                app.ball.P{n}(1)=3*app.ball.r{n}(1);
                partialPerimeterRef=app.ball.P{n}(1);
                start=2;
            end
            if app.nBalls == 2 && start==2
                app.ball.P{n}(app.nBalls)=balancedP-app.ball.P{n}(1);
                app.ball.r{n}(app.nBalls)=(app.ball.P{n}(app.nBalls))/3;
                app.ball.A{n}(app.nBalls)=sqrt(3)/4*(app.ball.r{n}(app.nBalls))^2;
                app.mean_r=app.MeanradiusEditField.Value;
            elseif start<app.nBalls || (app.nBalls == 1 && start == 1)
                while app.ball.P{n}(app.nBalls)<=0
                    partialPerimeter=partialPerimeterRef;
                    for i=start:app.nBalls-1
                        app.ball.r{n}(i)=abs(normrnd(app.mean_r,app.var_r));
                        app.ball.P{n}(i)=3*app.ball.r{n}(i);
                        app.ball.A{n}(i)=sqrt(3)/4*app.ball.r{n}(i)^2;
                        partialPerimeter=partialPerimeter+app.ball.P{n}(i);
                    end
                    app.ball.P{n}(app.nBalls)=balancedP-partialPerimeter;
                end
                app.ball.r{n}(app.nBalls)=(app.ball.P{n}(app.nBalls))/3;
                app.ball.A{n}(app.nBalls)=sqrt(3)/4*(app.ball.r{n}(app.nBalls))^2;
                app.mean_r=app.MeanradiusEditField.Value;
            end
        end
        
    % generate circles with fixed radius    
        function computeRadii(app,n)
            balancedR=app.costraints.constParam;
            for i=1:app.nBalls
                app.ball.r{n}(i)=balancedR;
                app.ball.A{n}(i)=pi*app.ball.r{n}(i)^2;
                app.ball.P{n}(i)=2*pi*app.ball.r{n}(i);
            end
        end
    % generate squares with fixed side      
        function computeSquareRadii(app,n)
            balancedR=app.costraints.constParam;
            for i=1:app.nBalls
                app.ball.r{n}(i)=balancedR;
                app.ball.A{n}(i)=app.ball.r{n}(i)^2;
                app.ball.P{n}(i)=4*app.ball.r{n}(i);
            end
        end
    % generate triangle with fixed side    
        function computeTriangleRadii(app,n)
            balancedR=app.costraints.constParam;
            for i=1:app.nBalls
                app.ball.r{n}(i)=balancedR;
                app.ball.A{n}(i)=sqrt(3)/4*app.ball.r{n}(i)^2;
                app.ball.P{n}(i)=3*app.ball.r{n}(i);
            end
        end
        
   % plot arena and parameters     
        function plotEnvironment (app, generation)
            cla(app.UIAxesNumerosity)
            hold(app.UIAxesNumerosity, 'on')
            app.Slider.Enable='on';
            if app.arena.shape == 'c'
                th = 0:pi/50:2*pi;
                x_circle = app.arena.r* cos(th);
                y_circle = app.arena.r * sin(th);
                plot(app.UIAxesNumerosity, x_circle, y_circle, 'Color', 'k');
            end
            if app.ball.shape ~= 'x'
                [k,realCH] = computeRealCH(app, generation);
                app.ConvexHull.Text=['Convex hull: ' num2str(round(realCH,2))];
                app.ConvexHull_2.Text=['Density: ' num2str(round(app.nBalls/realCH,2))];
                if app.targetCH ~=0
                    plot(app.UIAxesNumerosity, app.totalPoints(k,1), app.totalPoints(k,2), 'r')
                end
                if app.nBalls>1
                    D = pdist(app.ball.X{generation});
                    app.InterDistance.Text=['Inter distance: ' num2str(round(mean(D),2))];
                else % for one element ID not defined
                    app.InterDistance.Text='Inter distance: 0';
                    app.ConvexHull_2.Text='Density: 0';
                end
                % compute total area, perimeter and relevant parameters
                totalA=0;
                totalP=0;
                for i=1:app.nBalls
                    totalA=totalA+app.ball.A{generation}(i);
                    totalP=totalP+app.ball.P{generation}(i);
                end
                app.TotalAreaLabel.Text=['Total area: ' num2str(round(totalA,2))];
                app.TotalPerimeterLabel.Text=['Total perimeter: ' num2str(round(totalP,2))];
                app.r_minLabel.Text=['r_min: ' num2str(round(min(app.ball.r{generation}),2))];
                app.r_maxLabel.Text=['r_max: ' num2str(round(max(app.ball.r{generation}),2))];
                if app.nGenerations==1
                    app.Slider.Enable='off';
                end
            end
        end
  % plot circle shapes      
        function plotCircle(app, generation) 
            for i=1:app.nBalls
                th = 0:pi/50:2*pi;
                x_circle = app.ball.r{generation}(i) * cos(th) + app.ball.X{generation}(i,1);
                y_circle = app.ball.r{generation}(i) * sin(th) + app.ball.X{generation}(i,2);
                plot(app.UIAxesNumerosity, x_circle, y_circle, 'Color', [0.32,0.72,0.53]);
                fill(app.UIAxesNumerosity, x_circle, y_circle, [0.32,0.72,0.53])
            end
            app.Generation.Text=[num2str(generation) '/' num2str(app.nGenerations)];
            app.KeepCheckBox.Value=app.ball.keep(generation);
        end
    % plot square shapes      
        function plotSquare(app, generation)
            if app.ball.shape=='s'
                for i=1:app.nBalls
                    rectangle(app.UIAxesNumerosity, 'Position', [app.ball.X{generation}(i,1)-app.ball.r{generation}(i)/2  app.ball.X{generation}(i,2)-app.ball.r{generation}(i)/2 app.ball.r{generation}(i) app.ball.r{generation}(i)], 'FaceColor', [0.32,0.72,0.53]);
                end
            elseif app.ball.shape=='d' % plot diamond shapes 
                for i=1:app.nBalls
                    h = patch(app.UIAxesNumerosity, 'XData',[app.ball.X{generation}(i,1)-app.ball.r{generation}(i)/2 app.ball.X{generation}(i,1)+app.ball.r{generation}(i)/2 app.ball.X{generation}(i,1)+app.ball.r{generation}(i)/2 app.ball.X{generation}(i,1)-app.ball.r{generation}(i)/2],'YData',[app.ball.X{generation}(i,2)-app.ball.r{generation}(i)/2 app.ball.X{generation}(i,2)-app.ball.r{generation}(i)/2 app.ball.X{generation}(i,2)+app.ball.r{generation}(i)/2 app.ball.X{generation}(i,2)+app.ball.r{generation}(i)/2],'FaceColor',[0.32,0.72,0.53]);
                    rotate(h, [0 0 1], 45, [app.ball.X{generation}(i,1), app.ball.X{generation}(i,2),0]);
                end
            end
            app.Generation.Text=[num2str(generation) '/' num2str(app.nGenerations)];
            app.KeepCheckBox.Value=app.ball.keep(generation);
        end
    % plot triangle shapes     
        function plotTriangle(app, generation)
            for i=1:app.nBalls  
                patch(app.UIAxesNumerosity, 'XData',[app.ball.X{generation}(i,1)-app.ball.r{generation}(i)/2 app.ball.X{generation}(i,1)+app.ball.r{generation}(i)/2 app.ball.X{generation}(i,1)],'YData',[app.ball.X{generation}(i,2)-sqrt(3)/6*app.ball.r{generation}(i) app.ball.X{generation}(i,2)-sqrt(3)/6*app.ball.r{generation}(i) app.ball.X{generation}(i,2)+sqrt(3)/3*app.ball.r{generation}(i)],'FaceColor',[0.32,0.72,0.53]);
            end
            app.Generation.Text=[num2str(generation) '/' num2str(app.nGenerations)];
            app.KeepCheckBox.Value=app.ball.keep(generation);
        end
    % plot custom image      
        function plotCustom(app, generation)
            hold(app.UIAxesNumerosity, 'on');
            for i=1:app.nBalls
                axes('Position',[app.ball.X{generation}(i,1)-2 app.ball.X{generation}(i,2)-2 2 2])
                imshow(imresize(app.customShape,0.010), 'Parent', app.UIAxesNumerosity)
            end
            app.Generation.Text=[num2str(generation) '/' num2str(app.nGenerations)];
            app.KeepCheckBox.Value=app.ball.keep(generation);
        end
     
    % check for constraints match    
        function constraintArea(app, n)
            %shape outside arena
            if app.ball.shape ~= 'x'
                if app.arena.shape == 'c'
                    for i=1:app.nBalls
                        d=[0,0;app.ball.X{n}(i,:)];
                        if pdist(d)>=app.arena.r-app.ball.r{n}(i)
                            emptyGeneration(app, n);
                            disp('Generation deleted for elements out of circular arena')
                            return;
                        end
                    end
                else
                    for i=1:app.nBalls
                        if app.ball.shape=='c'
                            if app.ball.X{n}(i,1)>=app.arena.r-app.ball.r{n}(i) || app.ball.X{n}(i,1)<=-app.arena.r+app.ball.r{n}(i) || app.ball.X{n}(i,2)>=app.arena.r-app.ball.r{n}(i) || app.ball.X{n}(i,2)<=-app.arena.r+app.ball.r{n}(i)
                                emptyGeneration(app, n);
                                disp('Generation deleted for elements out of squared arena')
                                return;
                            end
                         elseif app.ball.shape=='s'||app.ball.shape=='d'
                             if (app.ball.X{n}(i,1) >= app.arena.r-app.ball.r{n}(i)/2) || (app.ball.X{n}(i,1) <= -app.arena.r + app.ball.r{n}(i)/2) || (app.ball.X{n}(i,2) >= app.arena.r-app.ball.r{n}(i)/2) || (app.ball.X{n}(i,2)<=-app.arena.r+app.ball.r{n}(i)/2)
                                emptyGeneration(app, n);
                                disp('Generation deleted for elements out of squared arena')
                                return;
                             end
                        end
                    end
                end
                if app.nBalls > 1
                    %not overlapping shapes
                    for i=1:app.nBalls
                        for j=(i+1):app.nBalls
                            pts=[app.ball.X{n}(i,:); app.ball.X{n}(j,:)];
                            if app.ball.shape=='c'
                                if pdist(pts)<=app.ball.r{n}(i)+app.ball.r{n}(j)+app.costraints.min_d||pdist(pts)>=app.ball.r{n}(i)+app.ball.r{n}(j)+app.costraints.max_d
                                    emptyGeneration(app, n);
                                    disp('Generation deleted for overlapping elements (or not fullfilling closeness constraints)')
                                    disp(['Distance removed: ' num2str(pdist(pts))])
                                    return;
                                end
                            elseif app.ball.shape=='s'||app.ball.shape=='d'
                                if pdist(pts)<=sqrt(2)*app.ball.r{n}(i)/2+sqrt(2)*app.ball.r{n}(j)/2+app.costraints.min_d||pdist(pts)>=sqrt(2)*app.ball.r{n}(i)/2+sqrt(2)*app.ball.r{n}(j)/2+app.costraints.max_d
                                    emptyGeneration(app, n);
                                    disp('Generation deleted for overlapping elements (or not fullfilling closeness constraints)')
                                    disp(['Distance removed: ' num2str(pdist(pts))])
                                    return;
                                end
                            elseif app.ball.shape=='t'
                                if pdist(pts)<=sqrt(3)/3*app.ball.r{n}(i)+sqrt(3)/3*app.ball.r{n}(j)+app.costraints.min_d||pdist(pts)>=sqrt(3)/3*app.ball.r{n}(i)+sqrt(3)/3*app.ball.r{n}(j)+app.costraints.max_d
                                    emptyGeneration(app, n);
                                    disp('Generation deleted for overlapping elements (or not fullfilling closeness constraints)')
                                    disp(['Distance removed: ' num2str(pdist(pts))])
                                    return;
                                end
                            end
                         end
                    end 
                end
            end
            %radius costraints
            check=0;
            if app.ball.shape ~= 'x'
                for i=1:app.nBalls
                    if (app.ball.r{n}(i)<=app.AtleastonewithrsmallerEditField.Value && app.ball.r{n}(i)>=app.AtleastonewithrbiggerEditField.Value)
                        check=1;
                    end
                    if app.ball.r{n}(i)<=0 || app.ball.r{n}(i)<app.costraints.r_min||app.ball.r{n}(i)>app.costraints.r_max
                        emptyGeneration(app, n);
                        disp('Generation deleted for elemnts dimentions not fullfilling constraints')
                        return;
                    end
                end
                if check==0
                    emptyGeneration(app, n);
                    disp('Generation deleted for elemnts dimentions not fullfilling constraints')
                end
            end

        end
        
    % delete generation n  
        function emptyGeneration(app, n)
            app.nGenerations=app.nGenerations-1;
            app.ball.X{n}={};
            app.ball.r{n}={};
            app.ball.A{n}={};
            app.ball.P{n}={};
        end
        
        function [k,realCH] = computeRealCH(app, n)
            app.totalPoints = zeros(1,2);
            for i=1:app.nBalls
                if app.ball.shape == 'c'
                    theta = linspace(0, 360, 100*pi*app.ball.r{n}(i));
                    x = app.ball.X{n}(i,1) + app.ball.r{n}(i) * cosd(theta);
                    y = app.ball.X{n}(i,2) + app.ball.r{n}(i) * sind(theta);
                    xy = [x; y]';                   
                elseif app.ball.shape == 's'
                    xleft = app.ball.X{n}(i,1) - app.ball.r{n}(i)/2;
                    xright = app.ball.X{n}(i,1) + app.ball.r{n}(i)/2;
                    yup = app.ball.X{n}(i,2) + app.ball.r{n}(i)/2; 
                    ydown = app.ball.X{n}(i,2) - app.ball.r{n}(i)/2;
                    xline = linspace(xleft, xright, app.ball.r{n}(i)*10)';
                    yline = linspace(ydown, yup, app.ball.r{n}(i)*10)';
                    yuparr = [];
                    ydownarr = [];
                    xleftarr = [];
                    xrightarr = [];
                    for rep = 1:app.ball.r{n}(i)*10
                        yuparr = [yuparr;yup];
                        ydownarr = [ydownarr; ydown];
                        xleftarr = [xleftarr; xleft];
                        xrightarr = [xrightarr; xright];
                    end
                    for side=1:4
                        if side == 1
                            xy=[xline, yuparr];
                        elseif side == 2
                            xynew=[xleftarr, yline];
                            xy = [xy; xynew];
                        elseif side == 3
                            xynew=[xline, ydownarr];
                            xy = [xy; xynew];
                        elseif side == 4
                            xynew=[xrightarr, yline];
                            xy = [xy; xynew];
                        end         
                    end
                elseif app.ball.shape == 'd'
                    vl=[app.ball.X{n}(i,1)-app.ball.r{n}(i)/sqrt(2), app.ball.X{n}(i,2)];
                    vr=[app.ball.X{n}(i,1)+app.ball.r{n}(i)/sqrt(2), app.ball.X{n}(i,2)];
                    vu=[app.ball.X{n}(i,1), app.ball.X{n}(i,2)+app.ball.r{n}(i)/sqrt(2)];
                    vd=[app.ball.X{n}(i,1), app.ball.X{n}(i,2)+app.ball.r{n}(i)/sqrt(2)];
                    for rep= 1:4
                        if rep==1
                            x=linspace(vu(1), vr(1), 1500)';
                            y=linspace(vu(2), vr(2), 1500)';
                            xy=[x,y];
                        elseif rep==2
                            x=linspace(vr(1), vd(1), 1500)';
                            y=linspace(vr(2), vd(2), 1500)';
                            xy=[xy;[x,y]];
                        elseif rep==3
                            x=linspace(vd(1), vl(1), 1500)';
                            y=linspace(vd(2), vl(2), 1500)';
                            xy=[xy;[x,y]];
                        else
                            x=linspace(vl(1), vu(1), 1500)';
                            y=linspace(vl(2), vu(2), 1500)';
                            xy=[xy;[x,y]];
                        end
                    end
                elseif app.ball.shape == 't'
                    v1=[app.ball.X{n}(i,1)-app.ball.r{n}(i)/2, app.ball.X{n}(i,2)-sqrt(3)/6*app.ball.r{n}(i)];
                    v2=[app.ball.X{n}(i,1)+app.ball.r{n}(i)/2, app.ball.X{n}(i,2)-sqrt(3)/6*app.ball.r{n}(i)];
                    v3=[app.ball.X{n}(i,1), app.ball.X{n}(i,2)+sqrt(3)/3*app.ball.r{n}(i)];
                    for rep= 1:3
                        if rep==1
                            x=linspace(v1(1), v2(1), 10)';
                            y=linspace(v1(2), v2(2), 10)';
                            xy=[x,y];
                        elseif rep==2
                            x=linspace(v2(1), v3(1), 10)';
                            y=linspace(v2(2), v3(2), 10)';
                            xy=[xy;[x,y]];
                        else
                            x=linspace(v1(1), v3(1), 10)';
                            y=linspace(v1(2), v3(2), 10)';
                            xy=[xy;[x,y]];
                        end
                    end
                end
                
                if app.ball.shape ~= 'x'
                    if app.totalPoints == zeros(1,2)
                        app.totalPoints = xy;
                    else
                        app.totalPoints=[app.totalPoints; xy];
                    end
                    [k,realCH] = convhull(app.totalPoints);
                end
            end
        end
        
        function refinePosition2CH(app, n, targetCH)
            [~,currentCH]=computeRealCH(app,n);
            while currentCH>targetCH+targetCH*app.tolerance/100
                CM = sum(app.ball.X{n},1)/app.nBalls;
                for i = 1:app.nBalls
                    dist = sqrt((CM(1)-app.ball.X{n}(i,1))^2+(CM(2)-app.ball.X{n}(i,2))^2);
                    x_ref = (CM(1)-app.ball.X{n}(i,1))/dist;
                    y_ref = (CM(2)-app.ball.X{n}(i,2))/dist;
                    if currentCH-targetCH>600
                        %disp('speed 6')
                        app.ball.X{n}(i,1)=app.ball.X{n}(i,1)+x_ref/3;
                        app.ball.X{n}(i,2)=app.ball.X{n}(i,2)+y_ref/3;
                    elseif currentCH-targetCH>250
                        %disp('speed 5')
                        app.ball.X{n}(i,1)=app.ball.X{n}(i,1)+x_ref/8;
                        app.ball.X{n}(i,2)=app.ball.X{n}(i,2)+y_ref/8;
                    elseif currentCH-targetCH>150
                        %disp('speed 4')
                        app.ball.X{n}(i,1)=app.ball.X{n}(i,1)+x_ref/20;
                        app.ball.X{n}(i,2)=app.ball.X{n}(i,2)+y_ref/20;
                    elseif currentCH-targetCH>30
                        %disp('speed 3')
                        app.ball.X{n}(i,1)=app.ball.X{n}(i,1)+x_ref/80;
                        app.ball.X{n}(i,2)=app.ball.X{n}(i,2)+y_ref/80;
                    elseif currentCH-targetCH>5
                        %disp('speed 2')
                        app.ball.X{n}(i,1)=app.ball.X{n}(i,1)+x_ref/150;
                        app.ball.X{n}(i,2)=app.ball.X{n}(i,2)+y_ref/150;
                    else
                        %disp('speed 1')
                        app.ball.X{n}(i,1)=app.ball.X{n}(i,1)+x_ref/1000;
                        app.ball.X{n}(i,2)=app.ball.X{n}(i,2)+y_ref/1000;
                    end
                end
                [~,currentCH]=computeRealCH(app,n);
            end
            while currentCH<targetCH-targetCH*app.tolerance/100
                CM = sum(app.ball.X{n},1)/app.nBalls;
                for i = 1:app.nBalls
                    dist = sqrt((CM(1)-app.ball.X{n}(i,1))^2+(CM(2)-app.ball.X{n}(i,2))^2);
                    x_ref = (app.ball.X{n}(i,1)-CM(1))/dist;
                    y_ref = (app.ball.X{n}(i,2)-CM(2))/dist;
                    app.ball.X{n}(i,1)=app.ball.X{n}(i,1)+x_ref/5000;
                    app.ball.X{n}(i,2)=app.ball.X{n}(i,2)+y_ref/5000;
                end
                [~,currentCH]=computeRealCH(app,n);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, main)
            clc;
            app.UIFigure.Name='GeNEsIS - Step 1.2: create balanced shapes';
            app.CallingApp=main;
            app.arena=app.CallingApp.arena;
            app.UIAxesNumerosity.XLim = [-app.arena.r app.arena.r];
            app.UIAxesNumerosity.YLim = [-app.arena.r app.arena.r];
            app.nBalls=app.CallingApp.nBalls;
            app.ball=app.CallingApp.ball;
            app.targetCH=app.CallingApp.targetCH;
            app.tolerance=app.CallingApp.tolerance;
            app.nGenerations=app.CallingApp.nGenerations;
            app.targetCH = app.CallingApp.targetCH;
            app.costraints.constParam=app.FixedParamEditField.Value;
            app.Slider.Limits=[1 app.nGenerations];
            app.Generation.Text=['1/' num2str(app.nGenerations)];
            app.Slider.Value=1;
            app.costraints.r_min=0;
            app.costraints.r_max=100;
            app.costraints.min_d=app.min_distEditField.Value;
            app.costraints.max_d=app.max_distEditField.Value;
            app.UpdateLabel.Text='Not updated!';
            app.costraints.constParam=app.FixedParamEditField.Value;
            app.UpdateLabel.FontColor = [0.96,0.36,0.32];
            app.ball.shape='c';
            app.MeanradiusEditField.Value=round(app.arena.r/app.nBalls/2,2);
            app.mean_r=app.arena.r/app.nBalls/2;
            app.RadiusvariabilityEditField.Value=0.1;
            app.var_r=0.1;
            if app.nBalls == 1
                app.max_distEditField.Enable = 'off';
                app.min_distEditField.Enable = 'off';
                app.OnewithrminimumCheckBox.Enable = 'off';
                app.OnewithrmaximumCheckBox.Enable = 'off';
                app.AtleastonewithrsmallerEditField.Enable = 'off';
                app.AtleastonewithrbiggerEditField.Enable = 'off';
            end
        end

        % Button pushed function: Next
        function NextButtonPushed(app, event)
            if round(app.Slider.Value)~=app.nGenerations
                app.Slider.Value=round(app.Slider.Value)+1;
            end
            plotEnvironment(app, app.Slider.Value)
            if app.ball.shape=='c'
                plotCircle(app, app.Slider.Value)
            elseif app.ball.shape=='s'||app.ball.shape=='d'
                plotSquare(app, app.Slider.Value)
            elseif app.ball.shape=='t'
                plotTriangle(app, app.Slider.Value)
            end
        end

        % Button pushed function: Previous
        function PreviousButtonPushed(app, event)
            if round(app.Slider.Value)~=1
                app.Slider.Value=round(app.Slider.Value)-1;
            end
            plotEnvironment(app, app.Slider.Value)
            if app.ball.shape=='c'
                plotCircle(app, app.Slider.Value)
            elseif app.ball.shape=='s'||app.ball.shape=='d'
                plotSquare(app, app.Slider.Value)
            elseif app.ball.shape=='t'
                plotTriangle(app, app.Slider.Value)
            end
        end

        % Button pushed function: ComputeButton
        function ComputeButtonPushed(app, event)
            selectedButton = app.BalanceButtonGroup.SelectedObject;
            shape=app.ball.shape;
            app.ball=app.CallingApp.ball;
            app.ball.shape=shape;
            app.nGenerations=app.CallingApp.nGenerations;
            % create shapes
            f = waitbar(0,'Wait while generating figures...');
            switch selectedButton.Text
                case 'Nothing'
                    for n=1:app.nGenerations    
                        waitbar(n/app.nGenerations,f,'Wait while generating figures...');
                        if app.ball.shape=='c'
                            computeUnbalanced(app,n)
                        elseif app.ball.shape=='s'||app.ball.shape=='d'
                            computeSquareUnbalanced(app,n)
                        elseif app.ball.shape=='t'
                            computeTriangleUnbalanced(app,n)
                        end
                    end
                    disp('Area and perimeter unbalanced')
                case 'Total area'
                    for n=1:app.nGenerations    
                        waitbar(n/app.nGenerations,f,'Wait while generating figures...');
                        if app.ball.shape=='c'
                            computeAreas(app,n)
                        elseif app.ball.shape=='s'||app.ball.shape=='d'
                            computeSquareAreas(app,n)
                        elseif app.ball.shape=='t'
                            computeTriangleAreas(app,n)
                        end
                    end
                    disp('Area balanced')
                case'Total perimeter'
                    for n=1:app.nGenerations    
                        waitbar(n/app.nGenerations,f,'Wait while generating figures...');
                        if app.ball.shape=='c'
                            computePerimeters(app,n)
                        elseif app.ball.shape=='s'||app.ball.shape=='d'
                            computeSquarePerimeters(app,n)
                        elseif app.ball.shape=='t'
                            computeTrianglePerimeters(app,n)
                        end
                    end
                    disp('Perimeter balanced')
                case 'Constant radius'
                    for n=1:app.nGenerations    
                        waitbar(n/app.nGenerations,f,'Wait while generating figures...');
                        if app.ball.shape=='c'
                            computeRadii(app,n)
                        elseif app.ball.shape=='s'||app.ball.shape=='d'
                            computeSquareRadii(app,n)
                        elseif app.ball.shape=='t'
                            computeTriangleRadii(app,n)
                        end
                    end
                    disp('Constant radius')
            end
            close(f);
            %constraint shapes
            w = waitbar(0,'Wait while refining CH...');
            G=app.nGenerations;
            for gen=1:G
                %refine CH
                if app.targetCH ~=0
                    refinePosition2CH(app, gen, app.targetCH);
                end
                waitbar(gen/G,w, ['Wait while refining CH. ' num2str(round(gen/G*100)) '% adjusted...']);
                constraintArea(app, gen);
            end
            close(w);
            %remove empty (out of constraints) generations
            if app.ball.shape ~= 'x'
                app.ball.X = app.ball.X(~cellfun(@isempty, app.ball.X));
                app.ball.r = app.ball.r(~cellfun(@isempty, app.ball.r));
                app.ball.A = app.ball.A(~cellfun(@isempty, app.ball.A));
                app.ball.P = app.ball.P(~cellfun(@isempty, app.ball.P));
            end
            
            if app.nGenerations==0
                disp('Your costraints are too restrictive!')
                app.Slider.Enable='off';
                app.Generation.Enable='off';
                app.KeepCheckBox.Enable='off';
                app.Previous.Enable='off';
                app.Next.Enable='off';
                app.SaveButton.Enable='off';
                app.UpdateLabel.Text='Updated';
                app.UpdateLabel.FontColor = [0.32,0.72,0.53];
                cla(app.UIAxesNumerosity)
                app.ComputeButton.Enable='on';
                figure(app.UIFigure)
                f = warndlg('No configurations fulfilling your constraints found! Maybe you set uncompatible parameters, or your costraints have a low probability to appear (in which case you could try to recompute the shapes or increase consistently the number of generations from the previous passage).','Constraints too restrictive');  
            else
                if app.nGenerations==1
                    app.Slider.Enable='off';
                    app.Generation.Enable='off';
                else
                    app.Slider.Enable='on';
                    app.Generation.Enable='on';
                    app.KeepCheckBox.Enable='on';
                    app.Slider.Limits=[1 app.nGenerations];
                end
                % check for unwanted generations
                for i=1:app.nGenerations
                    app.ball.keep(i)=1;
                end
                plotEnvironment(app, 1)
                if app.ball.shape=='c'
                    plotCircle(app, 1)
                elseif app.ball.shape=='s'||app.ball.shape=='d'
                    plotSquare(app, 1)
                elseif app.ball.shape=='t'
                    plotTriangle(app, 1)
                elseif app.ball.shape=='x'
                    plotCustom(app, 1)
                end  
                app.SaveButton.Enable='on';
                app.Slider.Value=1;
                app.Slider.Enable='on';
                app.Generation.Enable='on';
                app.KeepCheckBox.Enable='on';
                app.Previous.Enable='on';
                app.Next.Enable='on';
                app.UpdateLabel.Text='Updated';
                app.SaveButton.Enable='on';
                app.SaveasEditField.Enable='on';
                app.SaveasEditFieldLabel.Enable='on';
                app.UpdateLabel.FontColor = [0.32,0.72,0.53];
                figure(app.UIFigure)
            end
        end

        % Value changed function: r_minEditField
        function r_minEditFieldValueChanged(app, event)
            app.costraints.r_min = app.r_minEditField.Value;
            app.UpdateLabel.Text='Not updated!';
            app.UpdateLabel.FontColor = [0.96,0.36,0.32];
            app.ComputeButton.Enable='on';
        end

        % Value changed function: r_maxEditField
        function r_maxEditFieldValueChanged(app, event)
            app.costraints.r_max = app.r_maxEditField.Value;
            app.UpdateLabel.Text='Not updated!';
            app.UpdateLabel.FontColor = [0.96,0.36,0.32];
            app.ComputeButton.Enable='on';
        end

        % Value changed function: min_distEditField
        function min_distEditFieldValueChanged(app, event)
            app.costraints.min_d = app.min_distEditField.Value;
            app.UpdateLabel.Text='Not updated!';
            app.UpdateLabel.FontColor = [0.96,0.36,0.32];
            app.ComputeButton.Enable='on';
        end

        % Value changed function: max_distEditField
        function max_distEditFieldValueChanged(app, event)
            app.costraints.max_d = app.max_distEditField.Value;
            app.UpdateLabel.Text='Not updated!';
            app.UpdateLabel.FontColor = [0.96,0.36,0.32];
            app.ComputeButton.Enable='on';
        end

        % Selection changed function: BalanceButtonGroup
        function BalanceButtonGroupSelectionChanged(app, event)
            selectedButton = app.BalanceButtonGroup.SelectedObject;
            app.UpdateLabel.Text='Not updated!';
            app.UpdateLabel.FontColor = [0.96,0.36,0.32];
            app.Label.Enable='on';
            app.MeanradiusEditField.Enable='on';
            app.MeanradiusLabel_2.Enable = 'on';
            app.RadiusvariabilityLabel.Enable='on';
            app.RadiusvariabilityEditField.Enable='on';
            switch selectedButton.Text
                case 'Nothing'
                  app.Label.Text = '--';
                  app.Label.Enable='off';
                  app.FixedParamEditField.Value=0;
                  app.FixedParamEditField.Enable='off';
                case 'Total area'
                  app.Label.Text = 'Fixed area:';
                  app.FixedParamEditField.Value=3.14156*round(app.arena.r/app.nBalls/2,2)*round(app.arena.r/app.nBalls/2,2)*app.nBalls;
                  app.FixedParamEditField.Enable='on';
                  app.MeanradiusEditField.Enable='off';
                  app.MeanradiusLabel_2.Enable = 'off';
                case 'Total perimeter'
                  app.Label.Text = 'Fixed perimeter:';
                  app.FixedParamEditField.Value=2*3.14156*round(app.arena.r/app.nBalls/2,2)*app.nBalls;
                  app.FixedParamEditField.Enable='on';
                  app.MeanradiusEditField.Enable='off';
                  app.MeanradiusLabel_2.Enable = 'off';
                case 'Constant radius'
                  app.Label.Text = 'Fixed radius:';
                  app.FixedParamEditField.Value=round(app.arena.r/app.nBalls/2,2);
                  app.FixedParamEditField.Enable='on';
                  app.MeanradiusEditField.Enable='off';
                  app.RadiusvariabilityEditField.Enable='off';
                  app.MeanradiusLabel_2.Enable = 'off';
                  app.RadiusvariabilityLabel.Enable='off';
            end
            drawnow;
            app.Slider.Enable='off';
            app.Next.Enable='off';
            app.Previous.Enable='off';
            app.KeepCheckBox.Enable='off';
            app.Generation.Enable='off'; 
            app.SaveButton.Enable='off';
            app.SaveasEditField.Enable='off';
            app.SaveasEditFieldLabel.Enable='off';
            app.costraints.constParam = app.FixedParamEditField.Value;
            figure(app.UIFigure)
        end

        % Value changed function: FixedParamEditField
        function FixedParamEditFieldValueChanged(app, event)
            app.costraints.constParam=app.FixedParamEditField.Value;
            app.ComputeButton.BackgroundColor=[1.00,0.41,0.16];
            app.SaveButton.BackgroundColor = [0.851 0.3255 0.098];
            app.UpdateLabel.Text='Not updated!';
            app.UpdateLabel.FontColor = [1 0 0];
        end

        % Value changed function: ShapeDropDown
        function ShapeDropDownValueChanged(app, event)
            value = app.ShapeDropDown.Value;
            switch value
                case 'Circle'
                    app.ball.shape='c';
                case 'Square'
                    app.ball.shape='s';
                case 'Diamond'
                    app.ball.shape='d';
                case 'Triangle'
                    app.ball.shape='t';
                case 'Custom shape'
                    app.ball.shape='x';
            end
            app.UpdateLabel.Text='Not updated!';
            app.UpdateLabel.FontColor = [1 0 0];
            app.SaveButton.BackgroundColor = [0.851 0.3255 0.098];
            app.ComputeButton.Enable='on';
            drawnow;
            app.Slider.Enable='off';
            app.Next.Enable='off';
            app.Previous.Enable='off';
            app.KeepCheckBox.Enable='off';
            app.Generation.Enable='off';
            app.SaveButton.Enable='off';
            app.SaveasEditField.Enable='off';
            app.SaveasEditFieldLabel.Enable='off';
            figure(app.UIFigure)
        end

        % Value changed function: KeepCheckBox
        function KeepCheckBoxValueChanged(app, event)
            value = app.KeepCheckBox.Value;
            app.ball.keep(uint32(app.Slider.Value))=value;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            ballPrev=app.ball;
            nGenerationsPrev=app.nGenerations;
            for i=1:app.nGenerations
               if  app.ball.keep(i)==0
                   emptyGeneration(app, i);
               end
            end
            app.ball.X = app.ball.X(~cellfun(@isempty, app.ball.X));
            app.ball.r = app.ball.r(~cellfun(@isempty, app.ball.r));
            app.ball.A = app.ball.A(~cellfun(@isempty, app.ball.A));
            app.ball.P = app.ball.P(~cellfun(@isempty, app.ball.P));
            sample.arena=app.arena;
            sample.ball=app.ball;
            sample.nBalls=app.nBalls;
            sample.nGenerations=app.nGenerations;
            sample.costraints=app.costraints;
            sample.CallingApp.ball=app.CallingApp.ball;
            sample.targetCH=app.targetCH;
            mkdir('GeNEsIStimuli');
            save(['GeNEsIStimuli\GeNEsIS__' app.SaveasEditField.Value], 'sample');
            app.nGenerations=nGenerationsPrev;
            app.ball=ballPrev;
            app.OpenGeNEsIS_saveButton.Enable = 'on';
            disp('GeNEsIS file saved!')
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            clc;
            app.UIFigure.Visible='off';
            [file,path] = uigetfile;
            load([path '/' file], 'sample');
            app.UIFigure.Visible='on';
            app.arena=sample.arena;
            app.ball=sample.ball;
            app.CallingApp.ball=sample.ball;
            app.nBalls=sample.nBalls;
            app.nGenerations=sample.nGenerations;
            app.CallingApp.nGenerations=sample.nGenerations;
            app.costraints=sample.costraints;
            app.targetCH=sample.targetCH;
            app.CallingApp.ball=sample.CallingApp.ball;
            app.CallingApp.nGenerations=size(sample.CallingApp.ball.X,2);
            app.Slider.Value=1;
            app.UpdateLabel.Text='Not updated!';
            app.MeanradiusEditField.Value=round(app.arena.r/app.nBalls/2,2);
            app.mean_r=app.arena.r/app.nBalls/2;
            app.RadiusvariabilityEditField.Value=0.1;
            app.var_r=0.1;
            app.UpdateLabel.FontColor = [1 0 0];
            value = app.ShapeDropDown.Value;
            switch value
                case 'Circle'
                    app.ball.shape='c';
                case 'Square'
                    app.ball.shape='s';
                case 'Diamond'
                    app.ball.shape='d';
                case 'Triangle'
                    app.ball.shape='t';
            end   
            app.ComputeButton.Enable='on';
            app.ComputeButton.BackgroundColor=[1.00,0.41,0.16];
            cla(app.UIAxesNumerosity);
            drawnow;
            app.Slider.Enable='off';
            app.Next.Enable='off';
            app.Previous.Enable='off';
            app.KeepCheckBox.Enable='off';
            app.Generation.Enable='off';
            app.SaveButton.Enable='off';
            app.SaveasEditField.Enable='off';
            app.SaveasEditFieldLabel.Enable='off';
            figure(app.UIFigure)
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            changingValue = round(event.Value);
            plotEnvironment(app, changingValue)
            if app.ball.shape=='c'
                plotCircle(app, changingValue)
            elseif app.ball.shape=='s'||app.ball.shape=='d'
                plotSquare(app, changingValue)
            elseif app.ball.shape=='t'
                plotTriangle(app, changingValue)
            elseif app.ball.shape=='x'
                plotCustom(app, changingValue)
            end
        end

        % Value changed function: MeanradiusEditField
        function MeanradiusEditFieldValueChanged(app, event)
            app.mean_r = app.MeanradiusEditField.Value;
            app.ComputeButton.Enable='on';
            app.ComputeButton.BackgroundColor=[1.00,0.41,0.16];
            app.UpdateLabel.Text='Not updated!';
            app.UpdateLabel.FontColor = [1 0 0];
            app.SaveButton.BackgroundColor = [0.851 0.3255 0.098];
            drawnow;
            app.Slider.Enable='off';
            app.Next.Enable='off';
            app.Previous.Enable='off';
            app.KeepCheckBox.Enable='off';
            app.Generation.Enable='off';
            app.SaveButton.Enable='off';
            app.SaveasEditField.Enable='off';
            app.SaveasEditFieldLabel.Enable='off';
            figure(app.UIFigure)
        end

        % Value changed function: RadiusvariabilityEditField
        function RadiusvariabilityEditFieldValueChanged(app, event)
            app.var_r = app.RadiusvariabilityEditField.Value;
            app.ComputeButton.Enable='on';
            app.ComputeButton.BackgroundColor=[1.00,0.41,0.16];
            app.UpdateLabel.Text='Not updated!';
            app.UpdateLabel.FontColor = [1 0 0];
            app.SaveButton.BackgroundColor = [0.851 0.3255 0.098];
            drawnow;
            app.Slider.Enable='off';
            app.Next.Enable='off';
            app.Previous.Enable='off';
            app.KeepCheckBox.Enable='off';
            app.Generation.Enable='off';
            app.SaveButton.Enable='off';
            app.SaveasEditField.Enable='off';
            app.SaveasEditFieldLabel.Enable='off';
            figure(app.UIFigure)
        end

        % Value changed function: AtleastonewithrbiggerEditField
        function AtleastonewithrbiggerEditFieldValueChanged(app, event)

            app.ComputeButton.Enable='on';
            app.ComputeButton.BackgroundColor=[1.00,0.41,0.16];
            app.UpdateLabel.Text='Not updated!';
            app.UpdateLabel.FontColor = [1 0 0];
            app.SaveButton.BackgroundColor = [0.851 0.3255 0.098];
            drawnow;
            app.Slider.Enable='off';
            app.Next.Enable='off';
            app.Previous.Enable='off';
            app.KeepCheckBox.Enable='off';
            app.Generation.Enable='off';
            app.SaveButton.Enable='off';
            app.SaveasEditField.Enable='off';
            app.SaveasEditFieldLabel.Enable='off';
            figure(app.UIFigure)
        end

        % Value changed function: AtleastonewithrsmallerEditField
        function AtleastonewithrsmallerEditFieldValueChanged(app, event)

            app.ComputeButton.Enable='on';
            app.ComputeButton.BackgroundColor=[1.00,0.41,0.16];
            app.UpdateLabel.Text='Not updated!';
            app.UpdateLabel.FontColor = [1 0 0];
            app.SaveButton.BackgroundColor = [0.851 0.3255 0.098];
            drawnow;
            app.Slider.Enable='off';
            app.Next.Enable='off';
            app.Previous.Enable='off';
            app.KeepCheckBox.Enable='off';
            app.Generation.Enable='off';
            app.SaveButton.Enable='off';
            app.SaveasEditField.Enable='off';
            app.SaveasEditFieldLabel.Enable='off';
            figure(app.UIFigure)
        end

        % Value changed function: OnewithrmaximumCheckBox
        function OnewithrmaximumCheckBoxValueChanged(app, event)

            app.ComputeButton.BackgroundColor=[1.00,0.41,0.16];
            app.UpdateLabel.Text='Not updated!';
            app.UpdateLabel.FontColor = [1 0 0];
        end

        % Value changed function: OnewithrminimumCheckBox
        function OnewithrminimumCheckBoxValueChanged(app, event)

            app.ComputeButton.BackgroundColor=[1.00,0.41,0.16];
            app.UpdateLabel.Text='Not updated!';
            app.UpdateLabel.FontColor = [1 0 0];
        end

        % Value changed function: ReferencearenarcmEditField
        function ReferencearenarcmEditFieldValueChanged(app, event)
            value = app.ReferencearenarcmEditField.Value;
            if value==0
                app.pxLabel.Text='1 px = ---';
                app.px2Label.Text='1 px^2 = ---';
            else
                pixelDim=value/app.arena.r;
                app.pxLabel.Text=['1 px = ', num2str(round(pixelDim,2)), ' cm'];
                app.px2Label.Text=['1 px^2 = ', num2str(round(pixelDim*pixelDim,2)), ' cm^2'];
            end
        end

        % Button pushed function: OpenGeNEsIS_saveButton
        function OpenGeNEsIS_saveButtonPushed(app, event)
            GeNEsIS_save()
        end

        % Button pushed function: iButton
        function iButtonPushed(app, event)
            winopen('./Tutorial/GeNEsIS_create_tutorial.pdf')
        end

        % Button pushed function: iButton_2
        function iButton_2Pushed(app, event)
            f = msgbox('The program works in pixels at this first step; you could not worry about effective dimensions at this stage! Nevertheless, you can use the conversion scale to have an idea of the dimensions, but remember that these will be fixed only in step 2: GeNEsIS_save, when the program will save the final images. Thus, to have a consistent conversion, pay attention to use the same reference (the arena radius in cm) you will save the images with!','Info: units');
        end

        % Button pushed function: LoadshapeButton
        function LoadshapeButtonPushed(app, event)
            clc;
            app.UIFigure.Visible='off';
            [file,path] = uigetfile;
            app.customShape = imread([path file]);
            figure(app.UIFigure)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxesNumerosity = uiaxes(app.UIFigure);
            app.InterDistance = uilabel(app.UIFigure);
            app.InterDistance.Text = 'Inter distance:';
            app.ConvexHull = uilabel(app.UIFigure);
            app.ConvexHull.Text = 'Convex hull:';
            app.TotalAreaLabel = uilabel(app.UIFigure);
            app.TotalAreaLabel.Text = 'Total area:';
            app.TotalPerimeterLabel = uilabel(app.UIFigure);
            app.TotalPerimeterLabel.Text = 'Total perimeter:';
            app.ComputeButton = uibutton(app.UIFigure, 'push');
            app.ComputeButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeButtonPushed, true);
            app.ComputeButton.Text = 'Compute';
            app.r_minLabel = uilabel(app.UIFigure);
            app.r_minLabel.Text = 'r_min:';
            app.r_maxLabel = uilabel(app.UIFigure);
            app.r_maxLabel.Text = 'r_max:';
            app.UpdateLabel = uilabel(app.UIFigure);
            app.UpdateLabel.Text = 'Not updated!';
            app.BalanceButtonGroup = uibuttongroup(app.UIFigure);
            app.BalanceButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @BalanceButtonGroupSelectionChanged, true);
            app.BalanceButtonGroup.Title = 'Balance:';
            app.NothingButton = uiradiobutton(app.BalanceButtonGroup);
            app.NothingButton.Text = 'Nothing';
            app.NothingButton.Value = true;
            app.TotalareaButton = uiradiobutton(app.BalanceButtonGroup);
            app.TotalareaButton.Text = 'Total area';
            app.TotalperimeterButton = uiradiobutton(app.BalanceButtonGroup);
            app.TotalperimeterButton.Text = 'Total perimeter';
            app.ConstantradiusButton = uiradiobutton(app.BalanceButtonGroup);
            app.ConstantradiusButton.Text = 'Constant radius';
            app.Label = uilabel(app.BalanceButtonGroup);
            app.Label.Enable = 'off';
            app.Label.Text = '--';
            app.FixedParamEditField = uieditfield(app.BalanceButtonGroup, 'numeric');
            app.FixedParamEditField.Limits = [0 Inf];
            app.FixedParamEditField.ValueChangedFcn = createCallbackFcn(app, @FixedParamEditFieldValueChanged, true);
            app.FixedParamEditField.Enable = 'off';
            app.KeepCheckBox = uicheckbox(app.UIFigure);
            app.KeepCheckBox.ValueChangedFcn = createCallbackFcn(app, @KeepCheckBoxValueChanged, true);
            app.KeepCheckBox.Enable = 'off';
            app.KeepCheckBox.Text = 'Keep';
            app.KeepCheckBox.Value = true;
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.SaveasEditFieldLabel = uilabel(app.UIFigure);
            app.SaveasEditFieldLabel.Text = 'Save as:';
            app.SaveasEditField = uieditfield(app.UIFigure, 'text');
            app.Previous = uibutton(app.UIFigure, 'push');
            app.Previous.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.Previous.Enable = 'off';
            app.Previous.Text = '<<';
            app.Next = uibutton(app.UIFigure, 'push');
            app.Next.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.Next.Enable = 'off';
            app.Next.Text = '>>';
            app.ConvexHull_2 = uilabel(app.UIFigure);
            app.ConvexHull_2.Text = 'Density:';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [1 100];
            app.Slider.MajorTicks = [];
            app.Slider.MajorTickLabels = {''};
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Slider.MinorTicks = [];
            app.Slider.Enable = 'off';
            app.Slider.Value = 1;
            app.Generation = uilabel(app.UIFigure);
            app.Generation.Enable = 'off';
            app.UnitsexstimationPanel = uipanel(app.UIFigure);
            app.UnitsexstimationPanel.Title = 'Units exstimation';
            app.ReferencearenarcmEditFieldLabel = uilabel(app.UnitsexstimationPanel);
            app.ReferencearenarcmEditFieldLabel.Text = 'Reference: arena r [cm]';
            app.ReferencearenarcmEditField = uieditfield(app.UnitsexstimationPanel, 'numeric');
            app.ReferencearenarcmEditField.ValueChangedFcn = createCallbackFcn(app, @ReferencearenarcmEditFieldValueChanged, true);
            app.pxLabel = uilabel(app.UnitsexstimationPanel);
            app.pxLabel.Text = '1 px = ---';
            app.px2Label = uilabel(app.UnitsexstimationPanel);
            app.px2Label.Text = '1 px^2 = ---';
            app.LinearconversionLabel = uilabel(app.UnitsexstimationPanel);
            app.LinearconversionLabel.Text = 'Linear conversion:';
            app.AreasconversionLabel = uilabel(app.UnitsexstimationPanel);
            app.AreasconversionLabel.Text = 'Area''s conversion:';
            app.ElementsPanel = uipanel(app.UIFigure);
            app.ElementsPanel.Title = 'Elements:';
            app.ShapeLabel = uilabel(app.ElementsPanel);
            app.ShapeLabel.Text = 'Shape:';
            app.ShapeDropDown = uidropdown(app.ElementsPanel);
            app.ShapeDropDown.Items = {'Circle', 'Square', 'Diamond', 'Triangle', 'Custom shape'};
            app.ShapeDropDown.ValueChangedFcn = createCallbackFcn(app, @ShapeDropDownValueChanged, true);
            app.ShapeDropDown.Value = 'Circle';
            app.MeanradiusLabel_2 = uilabel(app.ElementsPanel);
            app.MeanradiusLabel_2.Text = 'Mean radius:';
            app.MeanradiusEditField = uieditfield(app.ElementsPanel, 'numeric');
            app.MeanradiusEditField.Limits = [0 Inf];
            app.MeanradiusEditField.ValueChangedFcn = createCallbackFcn(app, @MeanradiusEditFieldValueChanged, true);
            app.RadiusvariabilityLabel = uilabel(app.ElementsPanel);
            app.RadiusvariabilityLabel.Text = 'Radius variability:';
            app.RadiusvariabilityEditField = uieditfield(app.ElementsPanel, 'numeric');
            app.RadiusvariabilityEditField.Limits = [0 Inf];
            app.RadiusvariabilityEditField.ValueChangedFcn = createCallbackFcn(app, @RadiusvariabilityEditFieldValueChanged, true);
            app.RadiusvariabilityEditField.Value = 0.2;
            app.AdditionalconstraintsPanel = uipanel(app.UIFigure);
            app.AdditionalconstraintsPanel.Title = 'Additional constraints:';
            app.max_distEditFieldLabel = uilabel(app.AdditionalconstraintsPanel);
            app.max_distEditFieldLabel.Text = 'Maximum distance:';
            app.max_distEditField = uieditfield(app.AdditionalconstraintsPanel, 'numeric');
            app.max_distEditField.Limits = [0 Inf];
            app.max_distEditField.ValueChangedFcn = createCallbackFcn(app, @max_distEditFieldValueChanged, true);
            app.max_distEditField.Value = 1000;
            app.min_distEditFieldLabel = uilabel(app.AdditionalconstraintsPanel);
            app.min_distEditFieldLabel.Text = 'Minimum distance:';
            app.min_distEditField = uieditfield(app.AdditionalconstraintsPanel, 'numeric');
            app.min_distEditField.Limits = [0 Inf];
            app.min_distEditField.ValueChangedFcn = createCallbackFcn(app, @min_distEditFieldValueChanged, true);
            app.r_maxEditFieldLabel = uilabel(app.AdditionalconstraintsPanel);
            app.r_maxEditFieldLabel.Text = 'Maximum radius:';
            app.r_maxEditField = uieditfield(app.AdditionalconstraintsPanel, 'numeric');
            app.r_maxEditField.Limits = [0 Inf];
            app.r_maxEditField.ValueChangedFcn = createCallbackFcn(app, @r_maxEditFieldValueChanged, true);
            app.r_maxEditField.Value = 1000;
            app.r_minEditFieldLabel = uilabel(app.AdditionalconstraintsPanel);
            app.r_minEditFieldLabel.Text = 'Minimum radius:';
            app.r_minEditField = uieditfield(app.AdditionalconstraintsPanel, 'numeric');
            app.r_minEditField.Limits = [0 Inf];
            app.r_minEditField.ValueChangedFcn = createCallbackFcn(app, @r_minEditFieldValueChanged, true);
            app.AtleastonewithrsmallerEditFieldLabel = uilabel(app.AdditionalconstraintsPanel);
            app.AtleastonewithrsmallerEditFieldLabel.Text = 'At least one with r smaller:';
            app.AtleastonewithrsmallerEditField = uieditfield(app.AdditionalconstraintsPanel, 'numeric');
            app.AtleastonewithrsmallerEditField.ValueChangedFcn = createCallbackFcn(app, @AtleastonewithrsmallerEditFieldValueChanged, true);
            app.AtleastonewithrsmallerEditField.Value = 1000;
            app.AtleastonewithrbiggerEditFieldLabel = uilabel(app.AdditionalconstraintsPanel);
            app.AtleastonewithrbiggerEditFieldLabel.Text = 'At least one with r bigger:';
            app.AtleastonewithrbiggerEditField = uieditfield(app.AdditionalconstraintsPanel, 'numeric');
            app.AtleastonewithrbiggerEditField.ValueChangedFcn = createCallbackFcn(app, @AtleastonewithrbiggerEditFieldValueChanged, true);
            app.OnewithrminimumCheckBox = uicheckbox(app.AdditionalconstraintsPanel);
            app.OnewithrminimumCheckBox.ValueChangedFcn = createCallbackFcn(app, @OnewithrminimumCheckBoxValueChanged, true);
            app.OnewithrminimumCheckBox.Text = 'One with r minimum';
            app.OnewithrmaximumCheckBox = uicheckbox(app.AdditionalconstraintsPanel);
            app.OnewithrmaximumCheckBox.ValueChangedFcn = createCallbackFcn(app, @OnewithrmaximumCheckBoxValueChanged, true);
            app.OnewithrmaximumCheckBox.Text = 'One with r maximum';
            app.GeNEsIScreateLabel = uilabel(app.UIFigure);
            app.GeNEsIScreateLabel.Text = 'GeNEsIS - create';
            app.OpenGeNEsIS_saveButton = uibutton(app.UIFigure, 'push');
            app.OpenGeNEsIS_saveButton.ButtonPushedFcn = createCallbackFcn(app, @OpenGeNEsIS_saveButtonPushed, true);
            app.OpenGeNEsIS_saveButton.Enable = 'off';
            app.OpenGeNEsIS_saveButton.Text = 'Open GeNEsIS_save   >>';
            app.iButton = uibutton(app.UIFigure, 'push');
            app.iButton.ButtonPushedFcn = createCallbackFcn(app, @iButtonPushed, true);
            app.iButton.Text = 'i';
            app.iButton_2 = uibutton(app.UIFigure, 'push');
            app.iButton_2.ButtonPushedFcn = createCallbackFcn(app, @iButton_2Pushed, true);
            app.iButton_2.Text = 'i';
            app.LoadshapeButton = uibutton(app.UIFigure, 'push');
            app.LoadshapeButton.ButtonPushedFcn = createCallbackFcn(app, @LoadshapeButtonPushed, true);
            app.LoadshapeButton.Text = 'Load shape';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = shapesGenesis(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end